classdef PhaseWare_V01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhaseWare                       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportDataTab                   matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        MainDataTab                     matlab.ui.container.Tab
        BrowseButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ySliderLabel                    matlab.ui.control.Label
        ySlider                         matlab.ui.control.Slider
        VerticalUIAxes                  matlab.ui.control.UIAxes
        HorizontalUIAxes                matlab.ui.control.UIAxes
        HistogramUi                     matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        FrameSpinner_12Label            matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        FrameSpinner_13Label            matlab.ui.control.Label
        FrameSpinner_2                  matlab.ui.control.Spinner
        DropDown_2                      matlab.ui.control.DropDown
        Spinner                         matlab.ui.control.Spinner
        Spinner_2                       matlab.ui.control.Spinner
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        FrameSpinner_14Label            matlab.ui.control.Label
        FrameSpinner_3                  matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SingleframeButton               matlab.ui.control.ToggleButton
        Combinationof2framesButton      matlab.ui.control.ToggleButton
        PropertiesTextAreaLabel         matlab.ui.control.Label
        PropertiesTextArea              matlab.ui.control.TextArea
        xSlider_3Label                  matlab.ui.control.Label
        xSlider                         matlab.ui.control.Slider
        ReferenceDataTab                matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        PropertiesTextArea_2            matlab.ui.control.TextArea
        BrowseButton_2                  matlab.ui.control.Button
        PropertiesTextAreaLabel_2       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ImplementReferenceDatainthereconstructionSwitchLabel  matlab.ui.control.Label
        ImplementReferenceDatainthereconstructionSwitch  matlab.ui.control.Switch
        ySliderLabel_2                  matlab.ui.control.Label
        ySlider_3                       matlab.ui.control.Slider
        VerticalUIAxes_3                matlab.ui.control.UIAxes
        HorizontalUIAxes_3              matlab.ui.control.UIAxes
        HistogramUi_3                   matlab.ui.control.UIAxes
        xSlider_3Label_2                matlab.ui.control.Label
        xSlider_3                       matlab.ui.control.Slider
        PreProcessingTab                matlab.ui.container.Tab
        CropSizeButtonGroup             matlab.ui.container.ButtonGroup
        NoCropButton                    matlab.ui.control.RadioButton
        ManualSquareButton              matlab.ui.control.RadioButton
        SquareButton                    matlab.ui.control.RadioButton
        CircleButton                    matlab.ui.control.RadioButton
        DimensionSpinnerLabel           matlab.ui.control.Label
        DimensionSpinner                matlab.ui.control.Spinner
        DiameterSpinnerLabel            matlab.ui.control.Label
        DiameterSpinner                 matlab.ui.control.Spinner
        Panel_3                         matlab.ui.container.Panel
        NumberofframesSpinnerLabel      matlab.ui.control.Label
        NumberOfFramesEditField         matlab.ui.control.Spinner
        FirstframeSpinnerLabel          matlab.ui.control.Label
        FirstframeEditField             matlab.ui.control.Spinner
        IntervalSpinnerLabel            matlab.ui.control.Label
        IntervalEditField               matlab.ui.control.Spinner
        UIAxes2_2                       matlab.ui.control.UIAxes
        CropButton                      matlab.ui.control.Button
        FrameSpinner_4Label             matlab.ui.control.Label
        FrameSpinner_4                  matlab.ui.control.Spinner
        Switch                          matlab.ui.control.ToggleSwitch
        PropertiesTextArea_3            matlab.ui.control.TextArea
        DCOffsetRemovalPanel            matlab.ui.container.Panel
        filtersizeSpinner_3Label        matlab.ui.control.Label
        filtersizeSpinner               matlab.ui.control.Spinner
        alphaSpinner_3Label             matlab.ui.control.Label
        alphaSpinner                    matlab.ui.control.Spinner
        standarddeviationSpinner_3Label  matlab.ui.control.Label
        standarddeviationSpinner        matlab.ui.control.Spinner
        cutofffrequencySpinner_3Label   matlab.ui.control.Label
        cutofffrequencySpinner          matlab.ui.control.Spinner
        upcuttofffreqSpinnerLabel       matlab.ui.control.Label
        upcuttofffreqSpinner            matlab.ui.control.Spinner
        downcuttofffreqSpinnerLabel     matlab.ui.control.Label
        downcuttofffreqSpinner          matlab.ui.control.Spinner
        RemoveDCButton_3                matlab.ui.control.Button
        UIAxes2_3                       matlab.ui.control.UIAxes
        FrameSpinner_5Label             matlab.ui.control.Label
        FrameSpinner_5                  matlab.ui.control.Spinner
        Switch_2                        matlab.ui.control.ToggleSwitch
        DropDown                        matlab.ui.control.DropDown
        DataFilteringPanel              matlab.ui.container.Panel
        FilterButton_2                  matlab.ui.control.Button
        UIAxes2_8                       matlab.ui.control.UIAxes
        DenoisingPanel                  matlab.ui.container.Panel
        AveragefilterCheckBox_2         matlab.ui.control.CheckBox
        MedianfilterCheckBox_3          matlab.ui.control.CheckBox
        AdaptiveLowpassWienerfilterCheckBox  matlab.ui.control.CheckBox
        DeepneuralnetworkCheckBox       matlab.ui.control.CheckBox
        filtersizemnLabel               matlab.ui.control.Label
        filtersizemnSpinner             matlab.ui.control.Spinner
        filtersizemnSpinner_2           matlab.ui.control.Spinner
        filtersizemnSpinner_4           matlab.ui.control.Spinner
        filtersizemnSpinner_4Label      matlab.ui.control.Label
        filtersizemnSpinner_3           matlab.ui.control.Spinner
        filtersizemnSpinner_6           matlab.ui.control.Spinner
        filtersizemnSpinner_6Label      matlab.ui.control.Label
        filtersizemnSpinner_5           matlab.ui.control.Spinner
        CustomizedMATLABfunctionCheckBox  matlab.ui.control.CheckBox
        ContrastEnhancementButtonGroup  matlab.ui.container.ButtonGroup
        OriginalButton                  matlab.ui.control.RadioButton
        WindowNormalizationButton       matlab.ui.control.RadioButton
        windowsizemnSpinner_2           matlab.ui.control.Spinner
        windowsizemnSpinnerLabel        matlab.ui.control.Label
        windowsizemnSpinner             matlab.ui.control.Spinner
        CustomizedMATLABfunctionButton  matlab.ui.control.RadioButton
        FrameSpinner_6Label             matlab.ui.control.Label
        FrameSpinner_6                  matlab.ui.control.Spinner
        Switch_3                        matlab.ui.control.ToggleSwitch
        PhaseExtractionTab              matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        OffaxisDigitalholographicInterferometryDHITab  matlab.ui.container.Tab
        ReconstructionMethodButtonGroup  matlab.ui.container.ButtonGroup
        DiscreteFresnelTransformationButton_2  matlab.ui.control.ToggleButton
        AngularSpectrumButton_2         matlab.ui.control.ToggleButton
        ConvolutionButton_2             matlab.ui.control.ToggleButton
        CustomizedMATLABFunctionButton_2  matlab.ui.control.ToggleButton
        Panel_DHI                       matlab.ui.container.Panel
        ObjectPlaneImagingPlaneDistancecmLabel_5  matlab.ui.control.Label
        CameraPixelSizeumSpinnerLabel   matlab.ui.control.Label
        CameraPixelSizeDHI              matlab.ui.control.Spinner
        LaserWavelengthnmSpinnerLabel   matlab.ui.control.Label
        LaserWavelengthnmDHI            matlab.ui.control.Spinner
        ReconstructionZeroPaddingSizepixelLabel  matlab.ui.control.Label
        ZeroPaddedImageSizepixelDHI     matlab.ui.control.Spinner
        fromSpinner_2Label_2            matlab.ui.control.Label
        fromDHI                         matlab.ui.control.Spinner
        intervalSpinner_2Label_2        matlab.ui.control.Label
        intervalDHI                     matlab.ui.control.Spinner
        toSpinner_2Label_2              matlab.ui.control.Label
        toDHI                           matlab.ui.control.Spinner
        MagnificationLabel              matlab.ui.control.Label
        MagnificationDHI                matlab.ui.control.Spinner
        RUNButton                       matlab.ui.control.Button
        PhaseWrappingAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        FourierTransformFTButton_2      matlab.ui.control.ToggleButton
        WaveletTransformWTButton_2      matlab.ui.control.ToggleButton
        HilbertTransformHTButton_2      matlab.ui.control.ToggleButton
        ComplexDataButton               matlab.ui.control.ToggleButton
        CustomizedMATLABFunctionButton_3  matlab.ui.control.ToggleButton
        RemovecarrierfrequencybyFouriermethodCheckBox  matlab.ui.control.CheckBox
        Panel_DHI_Wrapping              matlab.ui.container.Panel
        WindowSizepixelLabel            matlab.ui.control.Label
        FilterRadiusDHI                 matlab.ui.control.Spinner
        WrappingZeroPaddingSizepixelLabel_5  matlab.ui.control.Label
        ZeroPaddedImageSizepixel_DHI    matlab.ui.control.Spinner
        FrequencyFilterSizepixelLabel   matlab.ui.control.Label
        SmoothingFilterSize_DHI         matlab.ui.control.Spinner
        FrequencyFilterTypeDropDownLabel  matlab.ui.control.Label
        FrequencyFilterTypeDropDownDHI  matlab.ui.control.DropDown
        FringeProjectionProfilometryFPPTab  matlab.ui.container.Tab
        AlgorithmButtonGroup            matlab.ui.container.ButtonGroup
        FourierTransformFTButton        matlab.ui.control.ToggleButton
        WaveletTransformWTButton        matlab.ui.control.ToggleButton
        HilbertTransformHTButton        matlab.ui.control.ToggleButton
        CustomizedMATLABFunctionButton  matlab.ui.control.ToggleButton
        RUNButton_2                     matlab.ui.control.Button
        Panel_FPP                       matlab.ui.container.Panel
        FrequencyFilterSizepixelLabel_2  matlab.ui.control.Label
        SmoothingFilterSizeFPP          matlab.ui.control.Spinner
        WindowSizepixelLabel_2          matlab.ui.control.Label
        FilterSizeProfilometryFPP       matlab.ui.control.Spinner
        FrequencyFilterTypeDropDown_3Label  matlab.ui.control.Label
        FrequencyFilterTypeDropDown_FPP  matlab.ui.control.DropDown
        WrappingZeroPaddingSizepixelLabel_4  matlab.ui.control.Label
        ZeroPaddedImageSizepixelProfilometry_wrapping_FPP  matlab.ui.control.Spinner
        RemovecarrierfrequencybyFouriermethodCheckBox_2  matlab.ui.control.CheckBox
        PostProcessingTab               matlab.ui.container.Tab
        DenoisingPanel_3                matlab.ui.container.Panel
        ProcessButton                   matlab.ui.control.Button
        FilteringPanel_2                matlab.ui.container.Panel
        GaussianlowpassfilterCheckBox   matlab.ui.control.CheckBox
        MedianfilterCheckBox_2          matlab.ui.control.CheckBox
        AdaptivelowpassWienerfilterCheckBox  matlab.ui.control.CheckBox
        DeepneuralnetworkCheckBox_4     matlab.ui.control.CheckBox
        sizemnSpinnerLabel              matlab.ui.control.Label
        sizemnSpinner                   matlab.ui.control.Spinner
        filtersizemnSpinner_8           matlab.ui.control.Spinner
        filtersizemnSpinner_9           matlab.ui.control.Spinner
        sizemnSpinner_2Label            matlab.ui.control.Label
        sizemnSpinner_2                 matlab.ui.control.Spinner
        filtersizemnSpinner_11          matlab.ui.control.Spinner
        sizemnLabel                     matlab.ui.control.Label
        sizemnSpinner_3                 matlab.ui.control.Spinner
        sigmaSpinnerLabel               matlab.ui.control.Label
        sigmaSpinner                    matlab.ui.control.Spinner
        CustomizedMATLABfunctionCheckBox_2  matlab.ui.control.CheckBox
        FrameSpinner_8Label             matlab.ui.control.Label
        FrameSpinner_8                  matlab.ui.control.Spinner
        UIAxes2_9                       matlab.ui.control.UIAxes
        Switch_5                        matlab.ui.control.ToggleSwitch
        PhaseUnwrappingPanel            matlab.ui.container.Panel
        ProcessButton_2                 matlab.ui.control.Button
        UnwrappingAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        ConstantinButton                matlab.ui.control.ToggleButton
        GhigliaandPrittButton           matlab.ui.control.ToggleButton
        CustomizedMATLABFunctionButton_5  matlab.ui.control.ToggleButton
        UseMasksCheckBox                matlab.ui.control.CheckBox
        GhigliaandRomeroButton          matlab.ui.control.ToggleButton
        GoldsteinButton                 matlab.ui.control.ToggleButton
        VolkovButton                    matlab.ui.control.ToggleButton
        FrameSpinner_9Label             matlab.ui.control.Label
        FrameSpinner_9                  matlab.ui.control.Spinner
        UIAxes2_11                      matlab.ui.control.UIAxes
        Switch_6                        matlab.ui.control.ToggleSwitch
        PhaseMapLabel                   matlab.ui.control.Label
        DataOptionsDropDown             matlab.ui.control.DropDown
        MaskingPanel                    matlab.ui.container.Panel
        ProcessButton_3                 matlab.ui.control.Button
        UIAxes2_10                      matlab.ui.control.UIAxes
        FrameSpinner_7Label             matlab.ui.control.Label
        FrameSpinner_7                  matlab.ui.control.Spinner
        Switch_4                        matlab.ui.control.ToggleSwitch
        MethodButtonGroup_2             matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.ToggleButton
        CustomizedMATLABFunctionButton_4  matlab.ui.control.ToggleButton
        Switch_7                        matlab.ui.control.ToggleSwitch
        BackgroundRemovalPanel          matlab.ui.container.Panel
        ProcessButton_4                 matlab.ui.control.Button
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        ManualBackgroungEstimationButton  matlab.ui.control.ToggleButton
        AutomaticBackgroundEstimationButton  matlab.ui.control.ToggleButton
        UsingReferenceButton            matlab.ui.control.ToggleButton
        CustomizedMATLABFunctionButton_6  matlab.ui.control.ToggleButton
        FrameSpinner_10Label            matlab.ui.control.Label
        FrameSpinner_10                 matlab.ui.control.Spinner
        UIAxes2_12                      matlab.ui.control.UIAxes
        ImageEnhancementPanel           matlab.ui.container.Panel
        ProcessButton_5                 matlab.ui.control.Button
        FilteringPanel                  matlab.ui.container.Panel
        AveragefilterCheckBox           matlab.ui.control.CheckBox
        MedianfilterCheckBox            matlab.ui.control.CheckBox
        AdaptiveLowpassWienerFilterCheckBox_4  matlab.ui.control.CheckBox
        DeepneuralnetworkCheckBox_3     matlab.ui.control.CheckBox
        sizemnSpinner_4Label            matlab.ui.control.Label
        sizemnSpinner_4                 matlab.ui.control.Spinner
        filtersizemnSpinner_12          matlab.ui.control.Spinner
        filtersizemnSpinner_13          matlab.ui.control.Spinner
        sizemnSpinner_5Label            matlab.ui.control.Label
        sizemnSpinner_5                 matlab.ui.control.Spinner
        filtersizemnSpinner_14          matlab.ui.control.Spinner
        sizemnSpinner_6Label            matlab.ui.control.Label
        sizemnSpinner_6                 matlab.ui.control.Spinner
        CustomizedMATLABfunctionCheckBox_3  matlab.ui.control.CheckBox
        FrameSpinner_11Label            matlab.ui.control.Label
        FrameSpinner_11                 matlab.ui.control.Spinner
        UIAxes2_13                      matlab.ui.control.UIAxes
        DisplaySaveTab                  matlab.ui.container.Tab
        LineProfile                     matlab.ui.control.Button
        Surfplot                        matlab.ui.control.Button
        ImageDropDown_2Label            matlab.ui.control.Label
        ImageOptionsDropDown            matlab.ui.control.DropDown
        Histogram                       matlab.ui.control.Button
        FrameNumberSpinner_2Label       matlab.ui.control.Label
        FrameNumberselection            matlab.ui.control.Spinner
        Play                            matlab.ui.control.Button
        lowerlimitEditFieldLabel        matlab.ui.control.Label
        lowerlimitEditField             matlab.ui.control.NumericEditField
        upperlimitEditFieldLabel        matlab.ui.control.Label
        upperlimitEditField             matlab.ui.control.NumericEditField
        timeplot                        matlab.ui.control.Button
        ysizeLabel                      matlab.ui.control.Label
        ysizeEditField                  matlab.ui.control.NumericEditField
        xsizeEditFieldLabel             matlab.ui.control.Label
        xsizeEditField                  matlab.ui.control.NumericEditField
        ColormapSelection               matlab.ui.control.DropDown
        ColormapDropDown_2Label         matlab.ui.control.Label
        UIAxes4_2                       matlab.ui.control.UIAxes
        xSlider_2Label                  matlab.ui.control.Label
        xSlider_2                       matlab.ui.control.Slider
        ySlider_2                       matlab.ui.control.Slider
        VerticalUIAxes_2                matlab.ui.control.UIAxes
        HorizontalUIAxes_2              matlab.ui.control.UIAxes
        HistogramUi_2                   matlab.ui.control.UIAxes
        ImageDisplay                    matlab.ui.control.Button
        ContrastSpinnerLabel            matlab.ui.control.Label
        ContrastSpinner                 matlab.ui.control.Spinner
        UpdateButton                    matlab.ui.control.Button
        yLabel                          matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        BrowseFolderDirectoryButton     matlab.ui.control.Button
        PropertiesTextArea_4            matlab.ui.control.TextArea
        ReportTab                       matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        SampleofthetableofcontentsforthegeneratedreportLabel  matlab.ui.control.Label
        GenerateReportButton            matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        AcknowledgmentsLabel            matlab.ui.control.Label
        AngularPhaseAmpReconstructionLabel  matlab.ui.control.Label
        FunctionLabel                   matlab.ui.control.Label
        AuthorLabel                     matlab.ui.control.Label
        GeorgesTNehmetallahLabel        matlab.ui.control.Label
        BackgroundRemovalLabel          matlab.ui.control.Label
        ParsaOmidiLabel                 matlab.ui.control.Label
        branch_cutsLabel                matlab.ui.control.Label
        BruceSpottiswoodeLabel_2        matlab.ui.control.Label
        CarrierRemovalLabel             matlab.ui.control.Label
        ParsaOmidiLabel_3               matlab.ui.control.Label
        ConvolutionPhaseAmpReconstructionLabel  matlab.ui.control.Label
        GeorgesTNehmetallahmodifiedbyParsaOmidiLabel  matlab.ui.control.Label
        CROIEditorLabel                 matlab.ui.control.Label
        JonasReberLabel                 matlab.ui.control.Label
        FFTWrappingLabel                matlab.ui.control.Label
        ParsaOmidiLabel_4               matlab.ui.control.Label
        FloodFillLabel                  matlab.ui.control.Label
        BruceSpottiswoodeLabel_3        matlab.ui.control.Label
        FramePreProcessingLabel         matlab.ui.control.Label
        ParsaOmidiLabel_5               matlab.ui.control.Label
        FreqFilterDFTLabel              matlab.ui.control.Label
        unknownLabel                    matlab.ui.control.Label
        FresnelPhaseAmpReconstructionLabel  matlab.ui.control.Label
        GeorgesTNehmetallahmodifiedbyParsaOmidiLabel_2  matlab.ui.control.Label
        FT2DcIFT2DcLabel                matlab.ui.control.Label
        TatianaLatychevskaiaLabel_2     matlab.ui.control.Label
        GoldsteinUnwrap2DLabel          matlab.ui.control.Label
        BruceSpottiswoodeLabel          matlab.ui.control.Label
        HTWrappingLabel                 matlab.ui.control.Label
        ParsaOmidiLabel_6               matlab.ui.control.Label
        polyfitnpolyvalnLabel           matlab.ui.control.Label
        JohnDErricoLabel                matlab.ui.control.Label
        UnwrappingConstantinLabel       matlab.ui.control.Label
        BrunoLuongLabel                 matlab.ui.control.Label
        UnwrappingGhigliaPrittLabel     matlab.ui.control.Label
        unknownLabel_3                  matlab.ui.control.Label
        UnwrappingGhigliaRomeroLabel    matlab.ui.control.Label
        MuhammadFKasimLabel             matlab.ui.control.Label
        UnwrappingGoldsteinLabel        matlab.ui.control.Label
        unknownLabel_4                  matlab.ui.control.Label
        UnwrappingVolkovLabel           matlab.ui.control.Label
        unknownLabel_5                  matlab.ui.control.Label
        VirtualReal_ImageRec_selectionLabel  matlab.ui.control.Label
        ParsaOmidiLabel_9               matlab.ui.control.Label
        WindowedNormalizationLabel      matlab.ui.control.Label
        ParsaOmidiLabel_12              matlab.ui.control.Label
        WTWrappingLabel                 matlab.ui.control.Label
        ParsaOmidiLabel_10              matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
    end

    properties (Access = private)
        V_in
        V_crp
        Video1
        rect
        Vec_Circ % Description
        % Description
        PhaseChange % Description
        RecImage % Description
        WrappedPhase % Description
        NoCarrierWrappedPhase % Description
        UnwrappedPhase % Description
        BackGround % Description
        I_fringeSkeleton % Description
        I_fringeEnhancement % Description
        V_filtered % Description
        V_fringeSkeleton % Description
        V_fringeEnhancement % Description
        WrappedPhase_filtered % Description
        UnwrappedPhase_noBG % Description
        UnwrappedPhase_noBG_filtered % Description
        V_ref_in
        UseRef = 0;% Use Reference in the reconstruction process; 0(not using), 1(using)
        V_ref_crp % Croped Reference
        V_ref_filtered % Description
        NoCarrierWrappedPhase_ref % Description
        WrappedPhase_ref
        RecImage_ref % Description
        WrappedPhase_ref_filtered % Description
        UnwrappedPhase_ref % Description
        Mask % Description
        reportTitle
        Directory
        Img_Disp % Description
        Img_Disp_ref
        l1 % Description
        l2 % Description
        l3 % Description
        l4
        l5 
        l6 % Description
        SavePath % Description
        drawMask = 0; % Description
    end
    
    methods (Access = private)
        %% Function to update the loading Reference Image
        function updateimage_reference(app,imagefile)
            I0 = imread (imagefile);
            if size(I0,3)==3
                I0 = double(rgb2gray(I0));
            else
                I0 = double(I0);
            end
            app.Img_Disp_ref = I0;
            % Remove title, axis labels, and tick labels
            title(app.UIAxes_2, []);
            xlabel(app.UIAxes_2, []);            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};  app.UIAxes_2.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(I0, [], 'Parent', app.UIAxes_2, 'XData', [1 app.UIAxes_2.Position(3)],'YData', [1 app.UIAxes_2.Position(4)]);
            % Set limits of axes
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');
            cellArrayText{1} = sprintf('%s\n%s', 'Number of frames: 1', 'Size of the frames: ', num2str(size(I0)));
            cellArrayText{2} = sprintf('%s', 'File full path: ', imagefile);
            app.PropertiesTextArea_2.Value = cellArrayText;
            app.V_ref_in     = I0;
            % Display Histogram
            histogram(app.HistogramUi_3, I0, 'FaceColor','g','EdgeColor', 'none');
            xNum = app.xSlider_3.Value;
            xPixelNum = round(xNum *(size(I0, 2)-1)+1);
            % Display X-Y line-profiles
            plot(app.VerticalUIAxes_3, I0(:, xPixelNum), 'Color','b');
            yNum = app.ySlider_3.Value;
            yPixelNum = round(yNum *(size(I0, 1)-1)+1);
            plot(app.HorizontalUIAxes_3, I0(yPixelNum,:), 'Color','r');

            xPixelNum = round((xNum *(size(I0, 2)-1)+1)*app.UIAxes_2.XLim(2)/size(I0, 2));
            app.l5 = line(app.UIAxes_2, [xPixelNum xPixelNum], [0 app.UIAxes_2.YLim(2)]);
            app.l5.Color = 'b';
            yPixelNum = round((abs(yNum-1)*(size(I0, 1)-1)+1)*app.UIAxes_2.YLim(2)/size(I0, 1));
            app.l6 = line(app.UIAxes_2, [0 app.UIAxes_2.XLim(2)], [yPixelNum yPixelNum]);
            app.l6.Color = 'r';
        end
        
        function data = GetReportData(app)
            data.Title               = 'Processing Report';
            data.SubTitle            = 'By:';
            data.Author              = getenv('username');
            tempdata                 = app.PropertiesTextArea.Value;
            data.directory           = tempdata{3};
            data.properties          = tempdata{2};
            data.selectedFrameNumber = app.FrameSpinner_3.Value;
            data.selectedImage       = app.Img_Disp;
            data.selectedImage_ref   = app.Img_Disp_ref;
            data.croppedImage        = app.V_crp(:,:,app.FrameSpinner_4.Value);
            data.DCremoved           = app.V_filtered(:,:,app.FrameSpinner_5.Value);
            data.methodDCremoval     = app.DropDown.Value;
            data.denoisedImage       = app.V_filtered(:,:,app.FrameSpinner_5.Value);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PhaseWare.Name = 'PhaseWare';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            try
                f = app.PhaseWare;
                d = uiprogressdlg(f,'Title','Loading ...','Indeterminate','on','Cancelable','on');
                app.PhaseWare.Visible = 'Off';
                app.PhaseWare.Visible = 'On';
                [FileName, PathName] = uigetfile ({'*.avi; *.TIF ; *.tif; *.PNG; *.png; *.bmp ; *.jpg' },'MultiSelect', 'on');
                FN         = app.FrameSpinner_3.Value;
                % Make sure user didn't cancel uigetfile dialog
                if (ischar(PathName))
                    % single file
                    if ischar(FileName)
                        [~,~,ext] = fileparts(FileName);
                        FullPathName = strcat (PathName, FileName);
                        if strcmp(ext, '.avi') % only avi files
                            app.NumberOfFramesEditField.Enable = 'On';
                            app.FirstframeEditField.Enable = 'On';
                            app.IntervalEditField.Enable = 'On';
                            app.Panel_5.Visible = 'On';
                            app.ButtonGroup.Visible = 'On';
                            app.Panel_3.Visible = 'On';
                            V0         = VideoReader(FullPathName);
                            NF         = V0.NumberOfFrames;
                            % open a dialog to ask user input the frame numbers to load
                            prompt     = {'Number of frames:','First frame number:','Interval:'};
                            dlgtitle   = 'Select Frame/s To Load';
                            dims       = [1 35];
                            definput   = {num2str(NF),'1','1'};
                            answer     = inputdlg(prompt,dlgtitle,dims,definput);
                            
                            if ~isempty(answer)
                                numFrame   = str2double(answer{1});
                                firstFrame = str2double(answer{2});
                                interval   = str2double(answer{3});
                                x = read(V0, 1);
                                Vtemp = zeros(size(x,1), size(x,2), numFrame);
                                icount = 0;
                                for i = firstFrame:interval:(firstFrame+(numFrame-1)*interval)
                                    if d.CancelRequested == 1
                                        close(d);
                                        return
                                    end
                                    icount = icount+1;
                                    x = read(V0, i);
                                    if size(x,3)==3
                                        Vtemp(:,:,icount) = double(rgb2gray(x));
                                    else
                                        Vtemp(:,:,icount) = double(x);
                                    end
                                end
                                cellArrayText{1} = sprintf('%s', 'Number of selected frames: ', answer{1});
                                cellArrayText{2} = sprintf('%s', 'Size of the input: ', num2str(size(Vtemp)));
                                cellArrayText{3} = sprintf('%s', 'File full path: ', FullPathName);
                                app.PropertiesTextArea.Value = cellArrayText;
                                app.V_in = Vtemp;
                                ButtonGroupSelectionChanged(app, event)
                                app.PhaseWare.Visible = 'Off';
                                app.PhaseWare.Visible = 'On';
                            else
                                close(d);
                                app.PhaseWare.Visible = 'Off';
                                app.PhaseWare.Visible = 'On';
                                return
                            end
                            app.NumberOfFramesEditField.Value = numFrame;
                        else % only images
                            app.NumberOfFramesEditField.Enable = 'Off';
                            app.FirstframeEditField.Enable = 'Off';
                            app.IntervalEditField.Enable = 'Off';
                            app.Panel_5.Visible = 'Off';
                            app.ButtonGroup.Visible = 'Off';
                            app.Panel_3.Visible = 'Off';
                            app.Panel_4.Visible = 'Off';
                            I0 = imread (FullPathName);
                            if size(I0,3)==3
                                I0 = double(rgb2gray(I0));
                            else
                                I0 = double(I0);
                            end
                            cellArrayText{1} = sprintf('%s', 'Number of frames: 1');
                            cellArrayText{2} = sprintf('%s', 'Size of the input: ', num2str(size(I0)));
                            cellArrayText{3} = sprintf('%s', 'File full path: ', FullPathName);
                            app.PropertiesTextArea.Value = cellArrayText;
                            app.V_in        = I0;
                        end
                    else % multiple files
                        TN = length(FileName);
                        for it = 1:TN
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            fName = FileName(it);
                            [~,~,ext] = fileparts(fName{1,1});
                            if strcmp(ext, '.avi')
                                warndlg('You can only select a single video or single/multiple images','Error');
                                break
                            else
                                app.NumberOfFramesEditField.Enable = 'On';
                                app.FirstframeEditField.Enable = 'On';
                                app.IntervalEditField.Enable = 'On';
                                app.Panel_5.Visible = 'On';
                                app.ButtonGroup.Visible = 'On';
                                app.Panel_3.Visible = 'On';
                                
                                FullPathName = strcat (PathName, fName);
                                if it == 1
                                    I0 = imread (FullPathName{1,1});
                                    Vtemp = zeros(size(I0,1), size(I0,2), TN);
                                    if size(I0,3)==3
                                        I0 = double(rgb2gray(I0));
                                    else
                                        I0 = double(I0);
                                    end
                                    Vtemp(:,:,1) = I0;
                                else
                                    I0 = imread (FullPathName{1,1});
                                    if size(I0,3)==3
                                        I0 = double(rgb2gray(I0));
                                    else
                                        I0 = double(I0);
                                    end
                                    Vtemp(:,:,it) = I0;
                                end
                                app.NumberOfFramesEditField.Value = TN;
                            end
                        end
                        cellArrayText{1} = sprintf('%s', 'Number of frames: ', num2str(TN));
                        cellArrayText{2} = sprintf('%s', 'Size of the input: ', num2str(size(Vtemp)));
                        cellArrayText{3} = sprintf('%s', 'File full path: ', FullPathName{1,1});
                        app.PropertiesTextArea.Value = cellArrayText;
                        app.V_in         = Vtemp;
                    end
                    % Display a frame
                    Vtemp = app.V_in;
                    if FN <= size(Vtemp,3)
                        sl = FN;
                    else
                        sl = 1;
                        warndlg('Number of the loaded frames is less than the selected display frame number. Instead, the first frame has been displayed!','Error');
                        app.FrameSpinner_3.Value =1;
                    end
                    app.Img_Disp = Vtemp(:,:,sl);
                    title(app.UIAxes, []);  xlabel(app.UIAxes, []);   ylabel(app.UIAxes, []);
                    app.UIAxes.XAxis.TickLabels = {};                 app.UIAxes.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imshow(app.Img_Disp, [], 'Parent', app.UIAxes, 'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                    % Set limits of axes
                    app.UIAxes.XLim = [0 I.XData(2)];                 app.UIAxes.YLim = [0 I.YData(2)];
                    pan(app.UIAxes,'on');                             zoom(app.UIAxes,'on');
                    xNum            = app.xSlider.Value;              yNum            = app.ySlider.Value;
                    xPixelNum       = round(xNum *(size(app.Img_Disp, 2)-1)+1); yPixelNum       = round(yNum *(size(app.Img_Disp, 1)-1)+1);
                    % Display histogram
                    histogram(app.HistogramUi, app.Img_Disp, 'FaceColor','g','EdgeColor', 'none');
                    % Display X-Y line-profiles
                    plot(app.VerticalUIAxes, app.Img_Disp(:, xPixelNum), 'Color','b');
                    plot(app.HorizontalUIAxes, app.Img_Disp(yPixelNum,:), 'Color','r');
                    xPixelNum = round((xNum *(size(app.Img_Disp, 2)-1)+1)*app.UIAxes.XLim(2)/size(app.Img_Disp, 2));
                    app.l1 = line(app.UIAxes, [xPixelNum xPixelNum], [0 app.UIAxes.YLim(2)]);
                    app.l1.Color = 'b';
                    yPixelNum = round((abs(yNum-1)*(size(app.Img_Disp, 1)-1)+1)*app.UIAxes.YLim(2)/size(app.Img_Disp, 1));
                    app.l2 = line(app.UIAxes, [0 app.UIAxes.XLim(2)], [yPixelNum yPixelNum]);
                    app.l2.Color = 'r';
                    app.SavePath = PathName(1:end-1);
                    cellArrayText = sprintf('%s', 'Selected Directory: ', PathName);
                    app.PropertiesTextArea_4.Value = cellArrayText;
                end
                app.PhaseWare.Visible = 'Off';
                app.PhaseWare.Visible = 'On';
            catch ME1
                warndlg(ME1.message,'Error');
            end
            % close the dialog box
            close(d);
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            try
                f = app.PhaseWare;
                d = uiprogressdlg(f,'Title','Cropping','Indeterminate','on', 'Cancelable','on');
                if app.UseRef==1
                    app.V_ref_crp = [];
                end
                app.V_crp = [];
                V0 = app.V_in;
                app.Vec_Circ = [];
                firstFrame = app.FirstframeEditField.Value;
                interval = app.IntervalEditField.Value;
                numberOfFrame = app.NumberOfFramesEditField.Value;
                lastFrame = firstFrame + interval*(numberOfFrame-1);
                ImgDisp = V0(:,:,firstFrame);
                val = app.CropSizeButtonGroup.SelectedObject;
                switch val.Text
                    case 'No Crop'
                        if size(V0, 3)==1
                            Vcrp = V0;
                        else
                            iter = 0;
                            Vcrp = zeros(size(ImgDisp,1), size(ImgDisp,2), numberOfFrame);
                            for i=firstFrame:interval:lastFrame
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                iter = iter +1;
                                Vcrp(:,:,iter) = V0(:,:,i);
                            end
                        end
                        if app.UseRef ==1
                            app.V_ref_crp = app.V_ref_in;
                        end
                        app.rect = [ 10 10 size(Vcrp, 1) size(Vcrp, 2)];
                    case 'Manual Square'
                        if size(V0, 3)==1
                            f1 = figure; imshow(V0, [])
                            h_rect = imrect();
                            pos_rect = h_rect.getPosition();
                            r = round(pos_rect);
                            close(f1)
                            Vcrp = V0(round(r(2)):round(r(2)+r(4)),round(r(1)):round(r(1)+r(3)));
                        else
                            iter = 0;
                            for i=firstFrame:interval:lastFrame
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                iter = iter +1;
                                I= V0(:,:,i);
                                if iter == 1
                                    f1 = figure; imshow(I, [])
                                    h_rect = imrect();
                                    pos_rect = h_rect.getPosition();
                                    r = round(pos_rect);
                                    close(f1)
                                end
                                I_selection =I(round(r(2)):round(r(2)+r(4)),round(r(1)):round(r(1)+r(3)));
                                if iter ==1
                                    Vcrp = zeros(size(I_selection,1), size(I_selection,2), numberOfFrame);
                                end
                                Vcrp(:,:,iter) = I_selection;
                            end
                        end
                        if app.UseRef ==1
                            I = app.V_ref_in;
                            app.V_ref_crp =I(round(r(2)):round(r(2)+r(4)),round(r(1)):round(r(1)+r(3)));
                        end
                        app.rect = r;
                    case 'Square'
                        DVal = app.DimensionSpinner.Value;
                        if size(V0, 3)==1
                            f1 = figure; imshow(V0, [])
                            h = imrect(gca,[10 10 DVal DVal]);
                            addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                            r = wait(h);
                            close(f1)
                            Vcrp = V0(round(r(2)):round(r(2)+r(4)-1),round(r(1)):round(r(1)+r(3)-1));
                        else
                            iter = 0;
                            for i = firstFrame:interval:lastFrame
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                iter = iter +1;
                                I= V0(:,:,i);
                                if i == firstFrame
                                    f1 = figure; imshow(I, [])
                                    h = imrect(gca,[10 10 DVal DVal]);
                                    addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                    r = wait(h);
                                    close(f1)
                                end
                                I_selection = I(round(r(2)):round(r(2)+r(4)-1),round(r(1)):round(r(1)+r(3)-1));
                                if i == firstFrame
                                    Vcrp = zeros(size(I_selection,1), size(I_selection,2), numberOfFrame);
                                end
                                Vcrp (:,:,iter) = I_selection;
                            end
                        end
                        if app.UseRef ==1
                            I = app.V_ref_in;
                            app.V_ref_crp =I(round(r(2)):round(r(2)+r(4)-1),round(r(1)):round(r(1)+r(3)-1));
                        end
                        app.rect = r;
                    case 'Circle'
                        ValDiameter = app.DiameterSpinner.Value;
                        if size(V0, 3)==1
                            [m,n] = size(V0);
                            f1 = figure; imshow(V0, [])
                            h = imellipse(gca,[(m/2),(n/2),ValDiameter,ValDiameter]);
                            addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                            vertices = wait(h);
                            close(f1)
                            XH=vertices(:,1);
                            YH=vertices(:,2);
                            bw0 = (poly2mask (XH,YH,m,n));
                            I_selection = bw0.*V0;
                            Vcrp = I_selection(floor(min(YH)):floor(max(YH)-1),floor(min(XH)):floor(max(XH)-1));
                        else
                            iter = 0;
                            for i = firstFrame:interval:lastFrame
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                iter = iter +1;
                                I = V0(:,:,i);
                                [m,n] = size(I);
                                if i == firstFrame
                                    f1 = figure; imshow(I, [])
                                    h = imellipse(gca,[(m/2),(n/2),ValDiameter,ValDiameter]);
                                    addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                    vertices = wait(h);
                                    close(f1)
                                end
                                XH=vertices(:,1);
                                YH=vertices(:,2);
                                bw0 = (poly2mask (XH,YH,m,n));
                                I_selection = bw0.*I;
                                I_selection = I_selection(floor(min(YH)):floor(max(YH)-1),floor(min(XH)):floor(max(XH)-1));
                                if i == firstFrame
                                    Vcrp = zeros(size(I_selection,1), size(I_selection,2), numberOfFrame);
                                end
                                Vcrp(:,:,iter) = I_selection;
                            end
                        end
                        if app.UseRef ==1
                            I = app.V_ref_in;
                            I_selection = bw0.*I;
                            app.V_ref_crp =I_selection(floor(min(YH)):floor(max(YH)-1),floor(min(XH)):floor(max(XH)-1));
                        end
                        app.Vec_Circ = vertices;
                end
                if strcmp(app.Switch.Value, 'Main')
                    I = imshow(Vcrp(:,:,app.FrameSpinner_4.Value), [], 'Parent', app.UIAxes2_2, ...
                        'XData', [1 app.UIAxes2_2.Position(3)], ...
                        'YData', [1 app.UIAxes2_2.Position(4)]);
                    app.UIAxes2_2.XLim = [0 I.XData(2)]; app.UIAxes2_2.YLim = [0 I.YData(2)];
                else
                    I = imshow(app.V_ref_crp, [], 'Parent', app.UIAxes2_2, ...
                        'XData', [1 app.UIAxes2_2.Position(3)], ...
                        'YData', [1 app.UIAxes2_2.Position(4)]);
                    app.UIAxes2_2.XLim = [0 I.XData(2)]; app.UIAxes2_2.YLim = [0 I.YData(2)];
                end
                app.V_crp = Vcrp;
                NS_RecZeroPadding = 2^nextpow2(2*max(size(Vcrp, 1), size(Vcrp,2))+5);
                NS_WrapZeroPadding = 2^nextpow2(2*max(size(Vcrp, 1), size(Vcrp,2))+5);
                app.ZeroPaddedImageSizepixelDHI.Value = NS_RecZeroPadding;
                app.ZeroPaddedImageSizepixel_DHI.Value = NS_WrapZeroPadding;
                app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Value = NS_WrapZeroPadding;
                app.FilterSizeProfilometryFPP.Value = round(NS_WrapZeroPadding/4);
                app.SmoothingFilterSizeFPP.Value = round(NS_WrapZeroPadding/8);
                app.FilterRadiusDHI.Value = round(NS_WrapZeroPadding/4);
                app.SmoothingFilterSize_DHI.Value = round(NS_WrapZeroPadding/8);
                cellArrayText{1} = sprintf('%s', 'Cropped Size: ');
                cellArrayText{2} = sprintf('%s',  num2str(size(Vcrp)));
                app.PropertiesTextArea_3.Value = cellArrayText;
                if size(Vcrp, 3)==1
                    app.FrameSpinner_4.Value = 1;
                    app.FrameSpinner_5.Value = 1;
                    app.FrameSpinner_6.Value = 1;
                    app.FrameSpinner_7.Value = 1;
                    app.FrameSpinner_8.Value = 1;
                    app.FrameSpinner_9.Value = 1;
                    app.FrameSpinner_10.Value = 1;
                    app.FrameSpinner_11.Value = 1;
                    app.FrameNumberselection.Value = 1;
                    app.FrameSpinner_4.Enable = 'Off';
                    app.FrameSpinner_5.Enable = 'Off';
                    app.FrameSpinner_6.Enable = 'Off';
                    app.FrameSpinner_7.Enable = 'Off';
                    app.FrameSpinner_8.Enable = 'Off';
                    app.FrameSpinner_9.Enable = 'Off';
                    app.FrameSpinner_10.Enable = 'Off';
                    app.FrameSpinner_11.Enable = 'Off';
                    app.FrameNumberselection.Enable = 'Off';
                else
                    app.FrameSpinner_4.Enable = 'On';
                    app.FrameSpinner_5.Enable = 'On';
                    app.FrameSpinner_6.Enable = 'On';
                    app.FrameSpinner_7.Enable = 'On';
                    app.FrameSpinner_8.Enable = 'On';
                    app.FrameSpinner_9.Enable = 'On';
                    app.FrameSpinner_10.Enable = 'On';
                    app.FrameSpinner_11.Enable = 'On';
                    app.FrameNumberselection.Enable = 'On';
                end
                app.DCOffsetRemovalPanel.Visible='On';
            catch ME1
                warndlg(ME1.message,'Error');
            end
            close(d);
        end

        % Selection changed function: CropSizeButtonGroup
        function CropSizeButtonGroupSelectionChanged(app, event)
        selectedButton = app.CropSizeButtonGroup.SelectedObject;
        valSelec = selectedButton.Text;
        switch valSelec
            case  'Circle'
                app.DiameterSpinner.Enable = 'On';
                app. DimensionSpinner.Enable = 'Off';
            case  'Square'
                app.DiameterSpinner.Enable = 'Off';
                app. DimensionSpinner.Enable = 'On';
            otherwise
                app.DiameterSpinner.Enable = 'Off';
                app. DimensionSpinner.Enable = 'Off';
        end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            f = app.PhaseWare;
%             try
                dia = uiprogressdlg(f,'Title','Reconstruction',...
                    'Message','Please wait','Cancelable','on');
                selectedButton = app.ReconstructionMethodButtonGroup.SelectedObject;
                selectedButton2 = app.PhaseWrappingAlgorithmButtonGroup.SelectedObject;
                WFT_parameters = [];
                switch selectedButton2.Text
                    case 'Customized MATLAB Function'
                        wrappingAlgorithm = 0;
                    case 'Complex Data'
                        wrappingAlgorithm = 1;
                    case 'Fourier Transform (FT)'
                        wrappingAlgorithm = 2;
                    case 'Wavelet Transform (WT)'
                        wrappingAlgorithm = 3;
                    case 'Hilbert Transform (HT)'
                        wrappingAlgorithm = 4;
                    case 'Windowed Fourier Transform'
                        wrappingAlgorithm = 5;
                        switch app.AlgorithmOption_DHI.SelectedObject.Text
                            case 'WFF'
                                type = 1;
                            case 'WFR'
                                type = 2;
                        end
                        WFT_parameters = {type, app.sigmaxSpinner_DHI.Value, app.wxlSpinner_DHI.Value,app.wxiSpinner_DHI.Value,app.wxhSpinner_DHI.Value,...
                        app.sigmaySpinner_DHI.Value,app.wylSpinner_DHI.Value,app.wyiSpinner_DHI.Value,app.wyhSpinner_DHI.Value, app.thr_DHI.Value};
                end
                RemoveCarrier = app.RemovecarrierfrequencybyFouriermethodCheckBox.Value;
                FrequencyFilterSize = app.SmoothingFilterSize_DHI.Value;
                valFrequency = app.FrequencyFilterTypeDropDownDHI.Value;
                switch valFrequency
                    case 'Gaussian'
                        FrequencyFilterType = 'gaussian';
                    case 'Butterworth'
                        FrequencyFilterType = 'btw';
                    case 'Ideal'
                        FrequencyFilterType = 'ideal';
                end
                NS = app.ZeroPaddedImageSizepixelDHI.Value;
                NS2 = app.ZeroPaddedImageSizepixel_DHI.Value;
                lambda = app.LaserWavelengthnmDHI.Value * 10^-9;
                cameraPixelSize = app.CameraPixelSizeDHI.Value * 10^-3;
                if app.intervalDHI.Value == 0
                    d = [app.fromDHI.Value];
                else
                    d = [app.fromDHI.Value:app.intervalDHI.Value:app.toDHI.Value];
                end
                WindowSize = app.FilterRadiusDHI.Value;
                switch selectedButton.Text
                    case 'Discrete Fresnel Transformation'
                        app.RecImage = [];
                        app.WrappedPhase = [];
                        app.NoCarrierWrappedPhase = [];
                        if app.UseRef==1
                            app.RecImage_ref = [];
                            app.WrappedPhase_ref = [];
                            app.NoCarrierWrappedPhase_ref = [];
                        end
                        vid = app.V_filtered;
                        framsNum = size(vid,3);
                        iter = 0;
                        Msg1 = 'Frame Number: %d ';
                        for fnum = 1:framsNum
                            if dia.CancelRequested == 1
                                close(dia);
                                return
                            end
                            iter = iter+1;
                            dia.Value = (iter)/framsNum;
                            I = vid(:,:,fnum);
                            if NS<max(size(I,1), size(I,2))||NS2<max(size(I,1), size(I,2))
                                ErrorMessage = 'zero padding size must be selected bigger than the input frame';
                            end
                            [I,~] = FramePreProcessing(I, NS);
                            if iter == 1 %only for the first frame the code do the reconstruction for all object-camera distances
                                for ii = 1 : length(d)
                                    if dia.CancelRequested == 1
                                        close(dia);
                                        return
                                    end
                                    D = d(ii);
                                    rec_Image = FresnelPhaseAmpReconstruction(I, D, lambda, cameraPixelSize);
                                    ImageDisplay (abs(rec_Image).^2, 2,512,[], sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                end
                                if length(d)>1
                                    prompt     = {'Enter the object plane-imaging plane distance (cm):'};
                                    dlgtitle   = 'Select the proper distance';
                                    dims       = [1 55];
                                    definput   = {num2str(D)};
                                    answer     = inputdlg(prompt,dlgtitle,dims,definput);
                                    try
                                        D   = str2double(answer{1});
                                        rec_Image = FresnelPhaseAmpReconstruction(I, D, lambda, cameraPixelSize);
                                        ImageDisplay (abs(rec_Image).^2, 2, 512,[], sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                    catch ME1
                                        warndlg(ME1.message,'Error');
                                    end
                                end
                                val = app.CropSizeButtonGroup.SelectedObject;
                                switch val.Text
                                    case 'No Crop'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Manual Square'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Square'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Circle'
                                        [m,n] = size(rec_Image);
                                        ValDiameter = app.DiameterSpinner.Value;
                                        h = imellipse(gca,[(m/2),(n/2),ValDiameter,ValDiameter]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        vertices = wait(h);
                                        XH=vertices(:,1);
                                        YH=vertices(:,2);
                                        a = floor(min(YH)); b = floor(min(XH)); c = floor(max(YH) - min(YH)); d = floor(max(XH) - min(XH));
                                        r = [b a d c];
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection(rec_Image,  r, 2);
                                end
                                Msg2 = [Msg1 newline ' Object Plane-Imaging Plane Distance (cm): %d '];
                                dia.Message = sprintf(Msg2,iter, D);
                                app.RecImage(:,:,fnum) = RecImage_selection;
                                [phase2, phase1, dim] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                                app.WrappedPhase(:,:,fnum) = phase1;
                                app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                                continue
                            end
                            rec_Image = FresnelPhaseAmpReconstruction(I, D, lambda, cameraPixelSize);
                            RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                            Msg2 = [Msg1 newline ' Object Plane-Imaging Plane Distance (cm): %d '];
                            dia.Message = sprintf(Msg2,iter, D);
                            app.RecImage(:,:,fnum) = RecImage_selection;
                            [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize,dim);
                            app.WrappedPhase(:,:,fnum) = phase1;
                            app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                        end
                        if app.UseRef ==1
                            I = app.V_ref_filtered;
                            [I,~] = FramePreProcessing(I, NS);
                            for ii = 1 : length(d)
                                if dia.CancelRequested == 1
                                    close(dia);
                                    return
                                end
                                D = d(ii);
                                rec_Image = FresnelPhaseAmpReconstruction(I, D, lambda, cameraPixelSize);
                                ImageDisplay (abs(rec_Image).^2, 2, 512, [],sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                            end
                            if length(d)>1
                                prompt     = {'Enter the object plane-imaging plane distance (cm):'};
                                dlgtitle   = 'Select the proper distance';
                                dims       = [1 55];
                                definput   = {num2str(D)};
                                answer     = inputdlg(prompt,dlgtitle,dims,definput);
                                try
                                    D   = str2double(answer{1});
                                    rec_Image = FresnelPhaseAmpReconstruction(I, D, lambda, cameraPixelSize);
                                    ImageDisplay (abs(rec_Image).^2, 2, 512,[], sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                catch ME1
                                    warndlg(ME1.message,'Error');
                                end
                            end
                            RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                            Msg2 = [Msg1 newline ' Reference Reconstuction: Object Plane-Imaging Plane Distance (cm): %d '];
                            dia.Message = sprintf(Msg2,iter, D);
                            app.RecImage_ref = RecImage_selection;
                            [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                            app.WrappedPhase_ref = phase1;
                            app.NoCarrierWrappedPhase_ref = phase2;
                        end
                    case 'Angular Spectrum'
                        lambda = lambda *1000;
                        app.RecImage = [];
                        app.WrappedPhase = [];
                        app.NoCarrierWrappedPhase = [];
                        if app.UseRef==1
                            app.RecImage_ref = [];
                            app.WrappedPhase_ref = [];
                            app.NoCarrierWrappedPhase_ref = [];
                        end
                        vid = app.V_filtered;
                        framsNum = size(vid,3);
                        
                        iter = 0;
                        Msg1 = 'Frame Number: %d ';
                        for fnum = 1:framsNum
                            if dia.CancelRequested == 1
                                close(dia);
                                return
                            end
                            iter = iter+1;
                            dia.Value = (iter)/framsNum;
                            I = vid(:,:,fnum);
                            if NS<max(size(I,1), size(I,2))||NS2<max(size(I,1), size(I,2))
                                ErrorMessage = 'zero padding size must be selected bigger than the input frame';
                            end
                            [I,~] = FramePreProcessing(I, NS);
                            if iter == 1 %only for the first frame the code do the reconstruction for all object-camera distances
                                for ii = 1 : length(d)
                                    if dia.CancelRequested == 1
                                        close(dia);
                                        return
                                    end
                                    D = d(ii);
                                    rec_Image = AngularPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, 1,app.MagnificationDHI.Value);
                                    ImageDisplay (abs(rec_Image).^2, 2, 512,[], sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                end
                                if length(d)>1
                                    prompt     = {'Enter the object plane-imaging plane distance (cm):'};
                                    dlgtitle   = 'Select the proper distance';
                                    dims       = [1 55];
                                    definput   = {num2str(D)};
                                    answer     = inputdlg(prompt,dlgtitle,dims,definput);
                                    try
                                        D   = str2double(answer{1});
                                        rec_Image = AngularPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, app.MagnificationDHI.Value);
                                        ImageDisplay (abs(rec_Image).^2, 2, 512, [],sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                    catch ME1
                                        warndlg(ME1.message,'Error');
                                    end
                                end
                                val = app.CropSizeButtonGroup.SelectedObject;
                                switch val.Text
                                    case 'No Crop'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Manual Square'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Square'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Circle'
                                        [m,n] = size(rec_Image);
                                        ValDiameter = app.DiameterSpinner.Value;
                                        h = imellipse(gca,[(m/2),(n/2),ValDiameter,ValDiameter]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        vertices = wait(h);
                                        XH=vertices(:,1);
                                        YH=vertices(:,2);
                                        a = floor(min(YH)); b = floor(min(XH)); c = floor(max(YH) - min(YH)); d = floor(max(XH) - min(XH));
                                        r = [b a d c];
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                end
                                Msg2 = [Msg1 newline ' Object Plane-Imaging Plane Distance (cm): %d '];
                                dia.Message = sprintf(Msg2,iter, D);
                                app.RecImage(:,:,fnum) = RecImage_selection;
                                [phase2, phase1, dim] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                                app.WrappedPhase(:,:,fnum) = phase1;
                                app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                                continue
                            end
                            rec_Image = AngularPhaseAmpReconstruction(I, D, lambda, cameraPixelSize);
                            RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                            Msg2 = [Msg1 newline ' Object Plane-Imaging Plane Distance (cm): %d '];
                            dia.Message = sprintf(Msg2,iter, D);
                            app.RecImage(:,:,fnum) = RecImage_selection;
                            [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize,dim);
                            app.WrappedPhase(:,:,fnum) = phase1;
                            app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                        end
                        if app.UseRef ==1
                            I = app.V_ref_filtered;
                            [I,~] = FramePreProcessing(I, NS);
                            for ii = 1 : length(d)
                                if dia.CancelRequested == 1
                                    close(dia);
                                    return
                                end
                                D = d(ii);
                                rec_Image = AngularPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, 1,app.MagnificationDHI.Value);
                                ImageDisplay (abs(rec_Image).^2, 2, 512,[], sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                            end
                            if length(d)>1
                                prompt     = {'Enter the object plane-imaging plane distance (cm):'};
                                dlgtitle   = 'Select the proper distance';
                                dims       = [1 55];
                                definput   = {num2str(D)};
                                answer     = inputdlg(prompt,dlgtitle,dims,definput);
                                try
                                    D   = str2double(answer{1});
                                    rec_Image = AngularPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, 1,app.MagnificationDHI.Value);
                                    ImageDisplay (abs(rec_Image).^2, 2, 512,[], sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                catch ME1
                                    warndlg(ME1.message,'Error');
                                end
                            end
                            RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                            Msg2 = [Msg1 newline ' Reference Reconstuction: Object Plane-Imaging Plane Distance (cm): %d '];
                            dia.Message = sprintf(Msg2,iter, D);
                            app.RecImage_ref = RecImage_selection;
                            [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                            app.WrappedPhase_ref = phase1;
                            app.NoCarrierWrappedPhase_ref = phase2;
                        end
                    case 'Convolution'
                        lambda = lambda *1000;
                        app.RecImage = [];
                        app.WrappedPhase = [];
                        app.NoCarrierWrappedPhase = [];
                        if app.UseRef==1
                            app.RecImage_ref = [];
                            app.WrappedPhase_ref = [];
                            app.NoCarrierWrappedPhase_ref = [];
                        end
                        vid = app.V_filtered;
                        framsNum = size(vid,3);
                        
                        iter = 0;
                        Msg1 = 'Frame Number: %d ';
                        for fnum = 1:framsNum
                            if dia.CancelRequested == 1
                                close(dia);
                                return
                            end
                            iter = iter+1;
                            dia.Value = (iter)/framsNum;
                            I = vid(:,:,fnum);
                            if NS<max(size(I,1), size(I,2))||NS2<max(size(I,1), size(I,2))
                                ErrorMessage = 'zero padding size must be selected bigger than the input frame';
                            end
                            [I,~] = FramePreProcessing(I, NS);
                            if iter == 1 %only for the first frame the code do the reconstruction for all object-camera distances
                                for ii = 1 : length(d)
                                    if dia.CancelRequested == 1
                                        close(dia);
                                        return
                                    end
                                    D = d(ii);
                                    rec_Image = ConvolutionPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, app.MagnificationDHI.Value);
                                    ImageDisplay (abs(rec_Image).^2, 2, 512,[], sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                end
                                if length(d)>1
                                    prompt     = {'Enter the object plane-imaging plane distance (cm):'};
                                    dlgtitle   = 'Select the proper distance';
                                    dims       = [1 55];
                                    definput   = {num2str(D)};
                                    answer     = inputdlg(prompt,dlgtitle,dims,definput);
                                    try
                                        D   = str2double(answer{1});
                                        rec_Image = ConvolutionPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, app.MagnificationDHI.Value);
                                        ImageDisplay (abs(rec_Image).^2, 2, 512, [],sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                    catch ME1
                                        warndlg(ME1.message,'Error');
                                    end
                                end
                                val = app.CropSizeButtonGroup.SelectedObject;
                                switch val.Text
                                    case 'No Crop'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Manual Square'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Square'
                                        r = app.rect;
                                        h = imrect(gca,[10 10 r(3) r(4)]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        r = wait(h);
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                    case 'Circle'
                                        [m,n] = size(rec_Image);
                                        ValDiameter = app.DiameterSpinner.Value;
                                        h = imellipse(gca,[(m/2),(n/2),ValDiameter,ValDiameter]);
                                        addNewPositionCallback(h,@(p) title(mat2str(p,3)));
                                        vertices = wait(h);
                                        XH=vertices(:,1);
                                        YH=vertices(:,2);
                                        a = floor(min(YH)); b = floor(min(XH)); c = floor(max(YH) - min(YH)); d = floor(max(XH) - min(XH));
                                        r = [b a d c];
                                        if r(1)<1||r(2)<1||r(1)+r(3)>size(rec_Image,1)||r(2)+r(4)>size(rec_Image,2)
                                            ErrorMessage = 'Filter must be selected inside the frame';
                                        end
                                        RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                                end
                                Msg2 = [Msg1 newline ' Object Plane-Imaging Plane Distance (cm): %d '];
                                dia.Message = sprintf(Msg2,iter, D);
                                app.RecImage(:,:,fnum) = RecImage_selection;
                                [phase2, phase1, dim] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                                app.WrappedPhase(:,:,fnum) = phase1;
                                app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                                continue
                            end
                            rec_Image = ConvolutionPhaseAmpReconstruction(I, D, lambda, cameraPixelSize);
                            RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                            Msg2 = [Msg1 newline ' Object Plane-Imaging Plane Distance (cm): %d '];
                            dia.Message = sprintf(Msg2,iter, D);
                            app.RecImage(:,:,fnum) = RecImage_selection;
                            [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize,dim);
                            app.WrappedPhase(:,:,fnum) = phase1;
                            app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                        end
                        if app.UseRef ==1
                            I = app.V_ref_filtered;
                            [I,~] = FramePreProcessing(I, NS);
                            for ii = 1 : length(d)
                                if dia.CancelRequested == 1
                                    close(dia);
                                    return
                                end
                                D = d(ii);
                                rec_Image = ConvolutionPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, app.MagnificationDHI.Value);
                                ImageDisplay (abs(rec_Image).^2, 2, 512, [],sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                            end
                            if length(d)>1
                                prompt     = {'Enter the object plane-imaging plane distance (cm):'};
                                dlgtitle   = 'Select the proper distance';
                                dims       = [1 55];
                                definput   = {num2str(D)};
                                answer     = inputdlg(prompt,dlgtitle,dims,definput);
                                try
                                    D   = str2double(answer{1});
                                    rec_Image = ConvolutionPhaseAmpReconstruction(I, D, lambda, cameraPixelSize, app.MagnificationDHI.Value);
                                    ImageDisplay (abs(rec_Image).^2, 2, 512, [],sprintf('Magnitude for the object plane-imaging plane distance (cm): %d', D))
                                catch ME1
                                    warndlg(ME1.message,'Error');
                                end
                            end
                            RecImage_selection = VirtualReal_ImageRec_selection (rec_Image,  r, 2);
                            Msg2 = [Msg1 newline ' Reference Reconstuction: Object Plane-Imaging Plane Distance (cm): %d '];
                            dia.Message = sprintf(Msg2,iter, D);
                            app.RecImage_ref = RecImage_selection;
                            [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                            app.WrappedPhase_ref = phase1;
                            app.NoCarrierWrappedPhase_ref = phase2;
                        end
                    case 'Customized MATLAB Function'
                        [file,path] = uigetfile('*.m');
                        if (ischar(path))
                            selectedfile = fullfile(path,file);
                            addpath(path);
                            [~,name,~] = fileparts(selectedfile);
                            fh = str2func(name);
                            app.RecImage = [];
                            app.WrappedPhase = [];
                            app.NoCarrierWrappedPhase = [];
                            V0 = app.V_filtered;
                            framsNum = size(vid,3);
                            for fnum = 1:framsNum
                                if dia.CancelRequested == 1
                                    close(dia);
                                    return
                                end
                                dia.Value = fnum/framsNum;
                                I0 = V0(:,:,fnum);
                                RecImage_selection = fh(I0);
                                app.RecImage(:,:,fnum) = RecImage_selection;
                                if fnum ==1
                                    [phase2, phase1, dim] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                                    app.WrappedPhase(:,:,fnum) = phase1;
                                    app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                                    continue
                                end
                                [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize,dim);
                                app.WrappedPhase(:,:,fnum) = phase1;
                                app.NoCarrierWrappedPhase(:,:,fnum) = phase2;
                            end
                        end
                        if app.UseRef ==1
                            app.RecImage_ref = [];
                            app.WrappedPhase_ref = [];
                            app.NoCarrierWrappedPhase_ref = [];
                            I = app.V_ref_filtered;
                            app.RecImage_ref = fh(I);
                            [phase2, phase1, ~] = PhaseWrapping(RecImage_selection, wrappingAlgorithm, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                            app.WrappedPhase_ref = phase1;
                            app.NoCarrierWrappedPhase_ref = phase2;
                        end
                end
                app.reportTitle = 'Off-axis Interferometry';
%             catch
%                 warndlg(ErrorMessage,'Error');
%             end
            close(dia);
        end

        % Callback function
        function PlayButtonPushed(app, event)
        I = app.PhaseChange;
        Dlim = app.lowerlimitEditField.Value;
        Ulim = app.upperlimitEditField.Value;
        [ysize,xsize,zsize] = size(I);
        numberOfFrames = zsize;
        % x1d = linspace(-3, 3, numberOfFrames);
        % y1d = x1d;
        % t = linspace(0, 5, numberOfFrames);
        hFigure = figure;
        % Set up the movie structure.
        % Preallocate movie, which will be an array of structures.
        % First get a cell array with all the frames.
        allTheFrames = cell(numberOfFrames,1);
        vidHeight = ysize;
        vidWidth = xsize;
        allTheFrames(:) = {zeros(vidHeight, vidWidth, 3, 'uint8')};
        % Next get a cell array with all the colormaps.
        allTheColorMaps = cell(numberOfFrames,1);
        allTheColorMaps(:) = {zeros(256, 3)};
        % Now combine these to make the array of structures.
        myMovie = struct('cdata', allTheFrames, 'colormap', allTheColorMaps);
        % Create a VideoWriter object to write the video out to a new, different file.
        % writerObj = VideoWriter('problem_3.avi');
        % open(writerObj);
        % Need to change from the default renderer to zbuffer to get it to work right.
        % openGL doesn't work and Painters is way too slow.
        % set(gcf, 'renderer', 'zbuffer');
        % Enlarge figure to full screen.
        set(gcf, 'Units', 'Normalized', 'OuterPosition', [0 0 1 1]);
        %==============================================================================================
        % Create the movie.
        % Get a list of x and y coordinates for every pixel in the x-y plane.
        % [x, y] = meshgrid(x1d, y1d);
        % After this loop starts, BE SURE NOT TO RESIZE THE WINDOW AS IT'S SHOWING THE FRAMES, or else you won't be able to save it.
        for frameIndex = 1 : numberOfFrames
           	z = I(:,:,frameIndex);
           	cla reset;
           	% Enlarge figure to full screen.
            % 	set(gcf, 'Units', 'Normalized', 'Outerposition', [0, 0, 1, 1]);
           	
            surf(z);shading interp;
            colorbar
            caxis([Dlim Ulim])
           	axis('tight')
           	zlim([Dlim, Ulim]);
            % 	caption = sprintf('Frame #%d of %d, t = %.1f', frameIndex, numberOfFrames, t(frameIndex));
            % 	title(caption, 'FontSize', 15);
            % 	drawnow;
           	thisFrame = getframe(gcf);
           	% Write this frame out to a new video file.
            %  	writeVideo(writerObj, thisFrame);
           	myMovie(frameIndex) = thisFrame;
        end
        % close(writerObj);
        
        %==============================================================================================
        % See if they want to replay the movie.
        message = sprintf('Done creating movie\nDo you want to play it?');
        button = questdlg(message, 'Continue?', 'Yes', 'No', 'Yes');
        drawnow;	% Refresh screen to get rid of dialog box remnants.
        close(hFigure);
        if strcmpi(button, 'Yes')
           	hFigure = figure;
           	% Enlarge figure to full screen.
           	% set(gcf, 'Units', 'Normalized', 'Outerposition', [0, 0, 1, 1]);
           	title('Playing the movie we created', 'FontSize', 15);
           	% Get rid of extra set of axes that it makes for some reason.
           	axis off;
           	% Play the movie.
           	movie(myMovie);
           	close(hFigure);
        end
        
        %==============================================================================================
        % See if they want to save the movie to an avi file on disk.
        promptMessage = sprintf('Do you want to save this movie to disk?');
        titleBarCaption = 'Continue?';
        button = questdlg(promptMessage, titleBarCaption, 'Yes', 'No', 'Yes');
        if strcmpi(button, 'yes')
           	% Get the name of the file that the user wants to save.
           	% Note, if you're saving an image you can use imsave() instead of uiputfile().
           	startingFolder = pwd;
           	defaultFileName = {'*.avi';'*.mp4';'*.mj2'}; %fullfile(startingFolder, '*.avi');
           	[baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
           	if baseFileName == 0
              		% User clicked the Cancel button.
              		return;
           	end
           	fullFileName = fullfile(folder, baseFileName);
           	% Create a video writer object with that file name.
           	% The VideoWriter object must have a profile input argument, otherwise you get jpg.
           	% Determine the format the user specified:
           	[folder, baseFileName, ext] = fileparts(fullFileName);
           	switch lower(ext)
              		case '.jp2'
                 			profile = 'Archival';
              		case '.mp4'
                 			profile = 'MPEG-4';
              		otherwise
                 			% Either avi or some other invalid extension.
                 			profile = 'Uncompressed AVI';
           	end
           	writerObj = VideoWriter(fullFileName, profile);
           	open(writerObj);
           	% Write out all the frames.
           	numberOfFrames = length(myMovie);
           	for frameNumber = 1 : numberOfFrames
            	   writeVideo(writerObj, myMovie(frameNumber));
           	end
           	close(writerObj);
           	% Display the current folder panel so they can see their newly created file.
           	cd(folder);
           	filebrowser;
           	message = sprintf('Finished creating movie file\n      %s.\n\nDone with demo!', fullFileName);
           	uiwait(helpdlg(message));
        else
           	uiwait(helpdlg('Done with demo!'));
        end

        end

        % Callback function
        function DropDownValueChanged(app, event)
        value = app.DropDown.Value;
        switch value
            case 'No DC Removal'
                app.filtersizeSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'DFT'
                app.filtersizeSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'On';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Gaussian'
                app.filtersizeSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Average'
                app.filtersizeSpinner.Enable = 'On';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Median'
                app.filtersizeSpinner.Enable = 'On';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Laplacian'
                app.filtersizeSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'On';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Log'
                app.filtersizeSpinner.Enable = 'On';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'On';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Prewitt'
                app.filtersizeSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Sobel'
                app.filtersizeSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Gaussian Spatial'
                app.filtersizeSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'Off';
                app.downcuttofffreqSpinner.Enable = 'Off';
            case 'Ideal BPF'
                app.filtersizeSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'On';
                app.downcuttofffreqSpinner.Enable = 'On';
            case 'Gaussian BPF'
                app.filtersizeSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'On';
                app.downcuttofffreqSpinner.Enable = 'On';
            case 'Butterworth BPF'
                app.filtersizeSpinner.Enable = 'Off';
                app.standarddeviationSpinner.Enable = 'Off';
                app.alphaSpinner.Enable = 'Off';
                app.cutofffrequencySpinner.Enable = 'Off';
                app.upcuttofffreqSpinner.Enable = 'On';
                app.downcuttofffreqSpinner.Enable = 'On';
        end
        end

        % Callback function
        function InterferometryProfilometryButtonGroupSelectionChanged(app, event)
        selectedButton = app.InterferometryProfilometryButtonGroup.SelectedObject;
        switch selectedButton.Text
            case 'Discrete Fresnel Transformation'
                app.Panel.Visible = 'On';
                app.Panel_2.Visible = 'Off';
                
            case 'Convolution'
                app.Panel.Visible = 'Off';
                app.Panel_2.Visible = 'On';
                
            case 'Angular Spectrum '
                app.Panel.Visible = 'Off';
                app.Panel_2.Visible = 'Off';
                
            case 'Fringe Projection'
                app.Panel.Visible = 'Off';
                app.Panel_2.Visible = 'Off';
        end
        end

        % Button pushed function: RemoveDCButton_3
        function RemoveDCButtonPushed(app, event)
            f = app.PhaseWare;
            try
                d = uiprogressdlg(f,'Title','DC Removal','Indeterminate','on', 'Cancelable','on');
                DropDownValueChanged(app, event)
                V0 = app.V_crp;
                if app.UseRef ==1
                    Vr = app.V_ref_crp;
                end
                NoF = size(V0,3);
                Vfiltered = zeros(size(V0));
                valDC = app.DropDown.Value;
                switch valDC
                    case 'No DC Removal'
                        for i=1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            Vfiltered(:,:,i) = I0;
                        end
                        if app.UseRef ==1
                            VRefFiltered = Vr;
                        end
                    case 'DFT'
                        Lev = app.cutofffrequencySpinner.Value;
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            Vfiltered(:,:,i) = abs(FreqFilterDFT(I0, Lev));
                        end
                        if app.UseRef ==1
                            VRefFiltered =abs(FreqFilterDFT(Vr, Lev));
                        end
                    case 'Average'
                        hsize = app.filtersizeSpinner.Value;
                        h = fspecial('average',hsize);
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            IDC = filter2 (h, I0, 'same');
                            Vfiltered(:,:,i) =  I0 - IDC;
                        end
                        if app.UseRef ==1
                            IDC = filter2 (h, Vr, 'same');
                            VRefFiltered = Vr - IDC;
                        end
                    case 'Median'
                        m = app.filtersizeSpinner.Value;
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            Vfiltered(:,:,i) =  I0 - medfilt2(I0,[m m]);
                        end
                        if app.UseRef ==1
                            VRefFiltered = Vr - medfilt2(Vr,[m m]);
                        end
                    case 'Laplacian'
                        alpha = app.alphaSpinner.Value;
                        h = fspecial('laplacian',alpha);
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            IDC = filter2 (h, I0, 'same');
                            Vfiltered(:,:,i) =  I0 - IDC;
                        end
                        if app.UseRef ==1
                            IDC = filter2 (h, Vr, 'same');
                            VRefFiltered = Vr - IDC;
                        end
                    case 'Log'
                        hsize = app.filtersizeSpinner.Value;
                        sigma = app.standarddeviationSpinner.Value;
                        h = fspecial('log',hsize,sigma);
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            IDC = filter2 (h, I0, 'same');
                            Vfiltered(:,:,i) =  I0 - IDC;
                        end
                        if app.UseRef ==1
                            IDC = filter2 (h, Vr, 'same');
                            VRefFiltered = Vr - IDC;
                        end
                    case 'Prewitt'
                        m = fspecial('prewitt');
                        h = m+m.';
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            Vfiltered(:,:,i) =  filter2 (h, I0, 'same');
                        end
                        if app.UseRef ==1
                            VRefFiltered = filter2 (h, Vr, 'same');
                        end
                    case 'Sobel'
                        m = fspecial('sobel');
                        h = m+m.';
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            Vfiltered(:,:,i) =  filter2 (h, I0, 'same');
                        end
                        if app.UseRef ==1
                            VRefFiltered = filter2 (h, Vr, 'same');
                        end
                    case 'Gaussian Spatial'
                        m = fspecial ('gaussian' , 3, 0.99);
                        for i=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            PQ = paddedsize(size(I0)); %Computes padded sizes
                            If = fft2((I0), PQ(1), PQ(2));
                            H = fft2(double(m), PQ(1), PQ(2));
                            If = H.*If;
                            I = ifft2(If);
                            IDC = abs(I(2:size(I0,1)+1, 2:size(I0,2)+1));
                            Vfiltered(:,:,i) =  I0 - IDC;
                        end
                        if app.UseRef ==1
                            PQ = paddedsize(size(Vr)); %Computes padded sizes
                            If = fft2((Vr), PQ(1), PQ(2));
                            H = fft2(double(m), PQ(1), PQ(2));
                            If = H.*If;
                            I = ifft2(If);
                            IDC = abs(I(2:size(Vr,1)+1, 2:size(Vr,2)+1));
                            VRefFiltered =  Vr - IDC;
                        end
                        
                    case 'Gaussian BPF'
                        for ii=1: NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,ii);
                            [nx, ny] = size(I0);
                            If = fft2(I0,2*nx-1,2*ny-1);
                            If = fftshift(If);
                            f1 = ones(2*nx-1,2*ny-1);
                            f2 = ones(2*nx-1,2*ny-1);
                            f3 = ones(2*nx-1,2*ny-1);
                            Ulim = app.upcuttofffreqSpinner.Value;
                            Dlim = app.downcuttofffreqSpinner.Value;
                            for i = 1:2*nx-1
                                for j =1:2*ny-1
                                    dist = ((i-(nx+1))^2 + (j-(ny+1))^2)^.5;
                                    f1(i,j) = exp(-dist^2/(2*Ulim^2));
                                    f2(i,j) = exp(-dist^2/(2*Dlim^2));
                                    f3(i,j) = 1.0 - f2(i,j);
                                    f3(i,j) = f1(i,j).*f3(i,j);
                                end
                            end
                            If =  f3.*If;
                            If = ifftshift(If);
                            I = ifft2(If,2*nx-1,2*ny-1);
                            Vfiltered(:,:,ii) =  real(I(1:nx,1:ny));
                        end
                        if app.UseRef ==1
                            I0 = Vr;
                            [nx, ny] = size(I0);
                            If = fft2(I0,2*nx-1,2*ny-1);
                            If = fftshift(If);
                            f1 = ones(2*nx-1,2*ny-1);
                            f2 = ones(2*nx-1,2*ny-1);
                            f3 = ones(2*nx-1,2*ny-1);
                            Ulim = app.upcuttofffreqSpinner.Value;
                            Dlim = app.downcuttofffreqSpinner.Value;
                            for i = 1:2*nx-1
                                for j =1:2*ny-1
                                    dist = ((i-(nx+1))^2 + (j-(ny+1))^2)^.5;
                                    f1(i,j) = exp(-dist^2/(2*Ulim^2));
                                    f2(i,j) = exp(-dist^2/(2*Dlim^2));
                                    f3(i,j) = 1.0 - f2(i,j);
                                    f3(i,j) = f1(i,j).*f3(i,j);
                                end
                            end
                            If =  f3.*If;
                            If = ifftshift(If);
                            I = ifft2(If,2*nx-1,2*ny-1);
                            VRefFiltered =  real(I(1:nx,1:ny));
                        end
                    case 'Butterworth BPF'
                        Ulim = app.upcuttofffreqSpinner.Value;
                        Dlim = app.downcuttofffreqSpinner.Value;
                        for ii=1: NoF
                            I0 = V0(:,:,ii);
                            [nx, ny] = size(I0);
                            If = fft2(I0,2*nx-1,2*ny-1);
                            If = fftshift(If);
                            f1 = ones(2*nx-1,2*ny-1);
                            f2 = ones(2*nx-1,2*ny-1);
                            f3 = ones(2*nx-1,2*ny-1);
                            n = 4;
                            for i = 1:2*nx-1
                                for j =1:2*ny-1
                                    if d.CancelRequested == 1
                                        close(d);
                                        return
                                    end
                                    dist = ((i-(nx+1))^2 + (j-(ny+1))^2)^.5;
                                    % Use Butterworth filter.
                                    f1(i,j)= 1/(1 + (dist/Ulim)^(2*n));
                                    f2(i,j) = 1/(1 + (dist/Dlim)^(2*n));
                                    f3(i,j)= 1.0 - f2(i,j);
                                    f3(i,j) = f1(i,j).*f3(i,j);
                                end
                            end
                            % Update image with passed frequencies.
                            If =  f3.* If;
                            If = ifftshift(If);
                            I = ifft2(If,2*nx-1,2*ny-1);
                            Vfiltered(:,:,ii) =  real(I(1:nx,1:ny));
                        end
                        if app.UseRef ==1
                            I0 = Vr;
                            [nx, ny] = size(I0);
                            If = fft2(I0,2*nx-1,2*ny-1);
                            If = fftshift(If);
                            f1 = ones(2*nx-1,2*ny-1);
                            f2 = ones(2*nx-1,2*ny-1);
                            f3 = ones(2*nx-1,2*ny-1);
                            n = 4;
                            for i = 1:2*nx-1
                                for j =1:2*ny-1
                                    if d.CancelRequested == 1
                                        close(d);
                                        return
                                    end
                                    dist = ((i-(nx+1))^2 + (j-(ny+1))^2)^.5;
                                    % Use Butterworth filter.
                                    f1(i,j)= 1/(1 + (dist/Ulim)^(2*n));
                                    f2(i,j) = 1/(1 + (dist/Dlim)^(2*n));
                                    f3(i,j)= 1.0 - f2(i,j);
                                    f3(i,j) = f1(i,j).*f3(i,j);
                                end
                            end
                            % Update image with passed frequencies.
                            If =  f3.*If;
                            If = ifftshift(If);
                            I = ifft2(If,2*nx-1,2*ny-1);
                            VRefFiltered =  real(I(1:nx,1:ny));
                        end
                    case 'Customized MATLAB Function'
                        [file,path] = uigetfile('*.m');
                        if (ischar(path))
                            selectedfile = fullfile(path,file);
                            addpath(path);
                            [~,name,~] = fileparts(selectedfile);
                            fh = str2func(name);
                            for ii = 1:NoF
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                I0 = V0(:,:,ii);
                                Vfiltered(:,:,ii) = double(fh(I0));
                            end
                        end
                        if app.UseRef ==1
                            I0 = Vr;
                            VRefFiltered = double(fh(I0));
                        end
                end
                app.V_filtered = Vfiltered;
                if app.UseRef==1
                    app.V_ref_filtered = VRefFiltered;
                end
                if strcmp(app.Switch_2.Value, 'Main')
                    I = imshow(Vfiltered(:,:,app.FrameSpinner_5.Value), [], 'Parent', app.UIAxes2_3, ...
                        'XData', [1 app.UIAxes2_3.Position(3)], ...
                        'YData', [1 app.UIAxes2_3.Position(4)]);
                    app.UIAxes2_3.XLim = [0 I.XData(2)]; app.UIAxes2_3.YLim = [0 I.YData(2)];
                    figure;
                    ax1 = subplot(2,2,1); imshow(V0(:,:,app.FrameSpinner_5.Value), []); title("Original");
                    ax2 = subplot(2,2,2); imshow(log(abs(fftshift(fft2(V0(:,:,app.FrameSpinner_5.Value))))), []); title("Fourier Domain of the Original");
                    ax3 = subplot(2,2,3); imshow(Vfiltered(:,:,app.FrameSpinner_5.Value), []); title("Filtered");
                    ax4 = subplot(2,2,4); imshow(log(abs(fftshift(fft2(Vfiltered(:,:,app.FrameSpinner_5.Value))))), []); title("Fourier Domain of the Filtered");
                    linkaxes([ax1,ax3], 'xy');
                    linkaxes([ax2,ax4], 'xy');
                else
                    I = imshow(VRefFiltered, [], 'Parent', app.UIAxes2_3, ...
                        'XData', [1 app.UIAxes2_3.Position(3)], ...
                        'YData', [1 app.UIAxes2_3.Position(4)]);
                    app.UIAxes2_3.XLim = [0 I.XData(2)]; app.UIAxes2_3.YLim = [0 I.YData(2)];
                    
                    figure;
                    ax1 = subplot(2,2,1); imshow(Vr, []); title("Original");
                    ax2 = subplot(2,2,2); imshow(log(abs(fftshift(fft2(Vr)))), []); title("Fourier Domain of the Original");
                    ax3 = subplot(2,2,3); imshow(VRefFiltered, []); title("Filtered");
                    ax4 = subplot(2,2,4); imshow(log(abs(fftshift(fft2(VRefFiltered)))), []); title("Fourier Domain of the Filtered");
                    linkaxes([ax1,ax3], 'xy');
                    linkaxes([ax2,ax4], 'xy');
                end
            catch ME1
                warndlg(ME1.message,'Error');
            end
            close(d)
        end

        % Button pushed function: Play
        function PlayButtonPushed2(app, event)
        val = app.ImageOptionsDropDown.Value;
        switch val
            case 'Unwrapped Phase'
                I0 = app.UnwrappedPhase;
            case 'Background'
                I0 = app.BackGround;
            case 'Background Removed Unwrapped Phase'
                I0 = app.UnwrappedPhase_noBG;
            case 'Background Removed Unwrapped Phase (Filtered)'
                I0 = app.UnwrappedPhase_noBG_filtered;
        end
        lambda = app.LaserWavelengthnmDHI.Value *10^-9;
        I = 10^6*I0/(4*pi)*lambda/10^-3;
        Dlim = app.lowerlimitEditField.Value;
        Ulim = app.upperlimitEditField.Value;
        [ysize,xsize,zsize] = size(I);
        numberOfFrames = zsize;
        % x1d = linspace(-3, 3, numberOfFrames);
        % y1d = x1d;
        % t = linspace(0, 5, numberOfFrames);
        hFigure = figure;
        % Set up the movie structure.
        % Preallocate movie, which will be an array of structures.
        % First get a cell array with all the frames.
        allTheFrames = cell(numberOfFrames,1);
        vidHeight = ysize;
        vidWidth = xsize;
        allTheFrames(:) = {zeros(vidHeight, vidWidth, 3, 'uint8')};
        % Next get a cell array with all the colormaps.
        allTheColorMaps = cell(numberOfFrames,1);
        allTheColorMaps(:) = {zeros(256, 3)};
        % Now combine these to make the array of structures.
        myMovie = struct('cdata', allTheFrames, 'colormap', allTheColorMaps);
        % Create a VideoWriter object to write the video out to a new, different file.
        % writerObj = VideoWriter('problem_3.avi');
        % open(writerObj);
        % Need to change from the default renderer to zbuffer to get it to work right.
        % openGL doesn't work and Painters is way too slow.
        % set(gcf, 'renderer', 'zbuffer');
        % Enlarge figure to full screen.
        set(gcf, 'Units', 'Normalized', 'OuterPosition', [0 0 1 1]);
        %==============================================================================================
        % Create the movie.
        % Get a list of x and y coordinates for every pixel in the x-y plane.
        % [x, y] = meshgrid(x1d, y1d);
        % After this loop starts, BE SURE NOT TO RESIZE THE WINDOW AS IT'S SHOWING THE FRAMES, or else you won't be able to save it.
        for frameIndex = 1 : numberOfFrames
           	z = I(:,:,frameIndex);
           	cla reset;
           	% Enlarge figure to full screen.
            % 	set(gcf, 'Units', 'Normalized', 'Outerposition', [0, 0, 1, 1]);
           	
            surf(z);shading interp;
            colorbar
            caxis([Dlim Ulim])
           	axis('tight')
           	zlim([Dlim, Ulim]);
            % 	caption = sprintf('Frame #%d of %d, t = %.1f', frameIndex, numberOfFrames, t(frameIndex));
            % 	title(caption, 'FontSize', 15);
            % 	drawnow;
           	thisFrame = getframe(gcf);
           	% Write this frame out to a new video file.
            %  	writeVideo(writerObj, thisFrame);
           	myMovie(frameIndex) = thisFrame;
        end
        % close(writerObj);
        
        %==============================================================================================
        % See if they want to replay the movie.
        message = sprintf('Done creating movie\nDo you want to play it?');
        button = questdlg(message, 'Continue?', 'Yes', 'No', 'Yes');
        drawnow;	% Refresh screen to get rid of dialog box remnants.
        close(hFigure);
        if strcmpi(button, 'Yes')
           	hFigure = figure;
           	% Enlarge figure to full screen.
           	% set(gcf, 'Units', 'Normalized', 'Outerposition', [0, 0, 1, 1]);
           	title('Playing the movie we created', 'FontSize', 15);
           	% Get rid of extra set of axes that it makes for some reason.
           	axis off;
           	% Play the movie.
           	movie(myMovie);
           	close(hFigure);
        end
        
        %==============================================================================================
        % See if they want to save the movie to an avi file on disk.
        promptMessage = sprintf('Do you want to save this movie to disk?');
        titleBarCaption = 'Continue?';
        button = questdlg(promptMessage, titleBarCaption, 'Yes', 'No', 'Yes');
        if strcmpi(button, 'yes')
           	% Get the name of the file that the user wants to save.
           	% Note, if you're saving an image you can use imsave() instead of uiputfile().
           	startingFolder = pwd;
           	defaultFileName = {'*.avi';'*.mp4';'*.mj2'}; %fullfile(startingFolder, '*.avi');
           	[baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
           	if baseFileName == 0
              		% User clicked the Cancel button.
              		return;
           	end
           	fullFileName = fullfile(folder, baseFileName);
           	% Create a video writer object with that file name.
           	% The VideoWriter object must have a profile input argument, otherwise you get jpg.
           	% Determine the format the user specified:
           	[folder, baseFileName, ext] = fileparts(fullFileName);
           	switch lower(ext)
              		case '.jp2'
                 			profile = 'Archival';
              		case '.mp4'
                 			profile = 'MPEG-4';
              		otherwise
                 			% Either avi or some other invalid extension.
                 			profile = 'Uncompressed AVI';
           	end
           	writerObj = VideoWriter(fullFileName, profile);
           	open(writerObj);
           	% Write out all the frames.
           	numberOfFrames = length(myMovie);
           	for frameNumber = 1 : numberOfFrames
            	   writeVideo(writerObj, myMovie(frameNumber));
           	end
           	close(writerObj);
           	% Display the current folder panel so they can see their newly created file.
           	cd(folder);
           	filebrowser;
           	message = sprintf('Finished creating movie file\n      %s.\n\nDone with demo!', fullFileName);
           	uiwait(helpdlg(message));
        else
           	uiwait(helpdlg('Done with demo!'));
        end
        end

        % Button pushed function: timeplot
        function timeplotButtonPushed(app, event)
        v1 = app.ysizeEditField.Value;
        v2 = app.xsizeEditField.Value;
        val = app.ImageOptionsDropDown.Value;
        FN = app.FrameNumberselection.Value;
        switch val
            case 'Original Frame'
                I0 = app.V_in(:,:,FN);
            case 'Reconstructed (Amplitude)'
                I = app.RecImage(:,:,FN);
                I0 = abs(I).^2;
            case 'Reconstructed (Phase)'
                I = app.RecImage(:,:,FN);
                I0 = imag(I);
            case 'Wrapped Phase'
                I0 = app.WrappedPhase(:,:,FN);
            case 'Carrier Removed Wrapped Phase'
                I0 = app.NoCarrierWrappedPhase(:,:,FN);
            case 'Unwrapped Phase'
                I0 = app.UnwrappedPhase(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
            case 'Background'
                I0 = app.BackGround;
            case 'Background Removed Unwrapped Phase'
                I0 = app.UnwrappedPhase_noBG(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
            case 'Background Removed Unwrapped Phase (Filtered)'
                I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
        end
        grayContrast = app.ContrastSpinner.Value;
        Colmap = app.ColormapSelection.Value;
        ImageDisplay (I0(:,:,1), 2,grayContrast,Colmap, val)
        [xi,yi] = getpts;
        xi = round (xi);
        yi = round (yi);
        for i =1:size(I0, 3)
            I(i) = mean2(I0(yi-v1/2:yi+v1/2, xi-v2/2:xi+v2/2, i));
        end
        figure;
        plot((10^6*I/(4*pi)*lambda/10^-3))
        title('Line Profile');
        zlabel('Depth in NanoMeter')
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            I0 = app.V_in;
            if size(I0, 3)>1
                selectedButton = app.ButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'Single frame'
                        app.Panel_5.Visible = 'On';
                        app.Panel_4.Visible = 'Off';
                        FrameSpinner_3ValueChanged(app, event)
                    case 'Combination of 2 frames'
                        app.Panel_5.Visible = 'Off';
                        app.Panel_4.Visible = 'On';
                        FrameSpinnerValueChanged(app, event)
                end
            elseif size(I0, 3)==1
                xNum = app.xSlider.Value;
                xPixelNum = round(xNum *(size(I0, 2)-1)+1);
                plot(app.VerticalUIAxes, I0(:, xPixelNum), 'Color','b');
                delete(app.l1)
                xPixelNum = round((xNum *(size(I0, 2)-1)+1)*app.UIAxes.XLim(2)/size(I0, 2));
                app.l1 = line(app.UIAxes, [xPixelNum xPixelNum], [0 app.UIAxes.YLim(2)]);
                app.l1.Color = 'b';
            end
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            I0 = app.V_in;
            if size(I0, 3)>1
                selectedButton = app.ButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'Single frame'
                        app.Panel_5.Visible = 'On';
                        app.Panel_4.Visible = 'Off';
                        FrameSpinner_3ValueChanged(app, event)
                    case 'Combination of 2 frames'
                        app.Panel_5.Visible = 'Off';
                        app.Panel_4.Visible = 'On';
                        FrameSpinnerValueChanged(app, event)
                end
            elseif size(I0, 3)==1
                yNum = app.ySlider.Value;
                yPixelNum = round(abs(yNum-1)*(size(I0, 1)-1)+1);
                plot(app.HorizontalUIAxes, I0(yPixelNum,:), 'Color','r');
                delete(app.l2)
                yPixelNum = round((abs(yNum-1)*(size(I0, 1)-1)+1)*app.UIAxes.YLim(2)/size(I0, 1));
                app.l2 = line(app.UIAxes, [0 app.UIAxes.XLim(2)], [yPixelNum yPixelNum]);
                app.l2.Color = 'r';
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
        selectedButton = app.ButtonGroup.SelectedObject;
        switch selectedButton.Text
            case 'Single frame'
                app.Panel_5.Visible = 'On';
                app.Panel_4.Visible = 'Off';
                FrameSpinner_3ValueChanged(app, event)
            case 'Combination of 2 frames'
                app.Panel_5.Visible = 'Off';
                app.Panel_4.Visible = 'On';
                FrameSpinnerValueChanged(app, event)
        end
        end

        % Value changed function: FrameSpinner_3
        function FrameSpinner_3ValueChanged(app, event)
            FN = app.FrameSpinner_3.Value;
            try
                I0 = app.V_in(:,:,FN);
                I0 = I0(:,:,1);
                % Display the frame
                I = imshow(I0, [], 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)]; app.UIAxes.YLim = [0 I.YData(2)];
                pan(app.UIAxes,'on');
                zoom(app.UIAxes,'on');
                % Display Histogram
                histogram(app.HistogramUi, I0, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                % Vertical Line-profile
                xNum = app.xSlider.Value;
                xPixelNum = round(xNum *(size(I0, 2)-1)+1);
                plot(app.VerticalUIAxes, I0(:, xPixelNum), 'Color',[1 0 0]);
                % Horizontal Line-profile
                yNum = app.ySlider.Value;
                yPixelNum = round(yNum *(size(I0, 1)-1)+1);
                plot(app.HorizontalUIAxes, I0(yPixelNum,:), 'Color',[1 0 0]);
                xPixelNum = round((xNum *(size(I0, 2)-1)+1)*app.UIAxes.XLim(2)/size(I0, 2));
                app.l1 = line(app.UIAxes, [xPixelNum xPixelNum], [0 app.UIAxes.YLim(2)]);
                app.l1.Color = 'b';
                yPixelNum = round((abs(yNum-1)*(size(I0, 1)-1)+1)*app.UIAxes.YLim(2)/size(I0, 1));
                app.l2 = line(app.UIAxes, [0 app.UIAxes.XLim(2)], [yPixelNum yPixelNum]);
                app.l2.Color = 'r';
            catch ME1
                warndlg(ME1.message,'Error');
            end
        end

        % Value changed function: DropDown_2, FrameSpinner, 
        % FrameSpinner_2, Spinner, Spinner_2
        function FrameSpinnerValueChanged(app, event)
            try
                FN1 = app.FrameSpinner.Value;
            FN2 = app.FrameSpinner_2.Value;
                vWeight = app.DropDown_2.Value;
                switch vWeight
                    case '-'
                        I0 = (app.Spinner.Value)*app.V_in(:,:,FN1)-(app.Spinner_2.Value)*app.V_in(:,:,FN2);
                    case '+'
                        I0 = (app.Spinner.Value)*app.V_in(:,:,FN1)+(app.Spinner_2.Value)*app.V_in(:,:,FN2);
                    case '*'
                        I0 = (app.Spinner.Value*app.V_in(:,:,FN1)).*(app.Spinner_2.Value*app.V_in(:,:,FN2));
                    case '/'
                        I0 = (app.Spinner.Value*app.V_in(:,:,FN1))./(app.Spinner_2.Value*app.V_in(:,:,FN2));
                end
                % Display the frame
                I = imshow(I0, [], 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                pan(app.UIAxes,'on');
                zoom(app.UIAxes,'on');
                % Display Histogram
                histogram(app.HistogramUi, I0, 'FaceColor','g','EdgeColor', 'none');
                % Vertical Line-profile
                xNum = app.xSlider.Value;
                xPixelNum = round(xNum *(size(I0, 2)-1)+1);
                plot(app.VerticalUIAxes, I0(:, xPixelNum), 'Color','b');
                % Horizontal Line-profile
                yNum = app.ySlider.Value;
                yPixelNum = round(yNum *(size(I0, 1)-1)+1);
                plot(app.HorizontalUIAxes, I0(yPixelNum,:), 'Color','r');
                xPixelNum = round((xNum *(size(I0, 2)-1)+1)*app.UIAxes.XLim(2)/size(I0, 2));
                app.l1 = line(app.UIAxes, [xPixelNum xPixelNum], [0 app.UIAxes.YLim(2)]);
                app.l1.Color = 'b';
                yPixelNum = round((abs(yNum-1)*(size(I0, 1)-1)+1)*app.UIAxes.YLim(2)/size(I0, 1));
                app.l2 = line(app.UIAxes, [0 app.UIAxes.XLim(2)], [yPixelNum yPixelNum]);
                app.l2.Color = 'r';
            catch ME1
                warndlg(ME1.message,'Error');
            end
        end

        % Selection changed function: 
        % ReconstructionMethodButtonGroup
        function ReconstructionMethodButtonGroupSelectionChanged(app, event)
        selectedButton = app.ReconstructionMethodButtonGroup.SelectedObject;
        switch selectedButton.Text
            case {'Angular Spectrum','Convolution'}
                app.Panel_DHI.Visible = 'On';
                app.MagnificationDHI.Enable = 'On';
            case 'Discrete Fresnel Transformation'
                app.Panel_DHI.Visible = 'On';
                app.MagnificationDHI.Enable = 'Off';
            case 'Customized MATLAB Function'
                app.Panel_DHI.Visible = 'Off';
        end
        end

        % Button pushed function: LineProfile
        function LineProfileButtonPushed(app, event)
        try
        val = app.ImageOptionsDropDown.Value;
        FN = app.FrameNumberselection.Value;
        switch val
            case 'Original Frame'
                I0 = app.V_in(:,:,FN);
            case 'Reconstructed (Amplitude)'
                I = app.RecImage(:,:,FN);
                I0 = abs(I).^2;
            case 'Reconstructed (Phase)'
                I = app.RecImage(:,:,FN);
                I0 = imag(I);
            case 'Wrapped Phase'
                I0 = app.WrappedPhase(:,:,FN);
            case 'Carrier Removed Wrapped Phase'
                I0 = app.NoCarrierWrappedPhase(:,:,FN);
            case 'Unwrapped Phase'
                I0 = app.UnwrappedPhase(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
            case 'Background'
                I0 = app.BackGround;
            case 'Background Removed Unwrapped Phase'
                I0 = app.UnwrappedPhase_noBG(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
            case 'Background Removed Unwrapped Phase (Filtered)'
                I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
        end
        grayContrast = app.ContrastSpinner.Value;
        Colmap = app.ColormapSelection.Value;
        ImageDisplay (I0, 2,grayContrast,Colmap, val)
        [xi,yi] = getpts;
        xi = round (xi);        yi = round (yi);
        figure; subplot(211);plot(I0(yi,:));  title(strcat('Horizontal: ',val));zlabel('Depth in NanoMeter')
        subplot(212);plot(I0(:,xi));  title(strcat('Vertical: ',val));zlabel('Depth in NanoMeter')
        catch ME1
             warndlg(ME1.message,'Error');
        end
        end

        % Button pushed function: ImageDisplay
        function ImageDisplayButtonPushed(app, event)
        try
        val = app.ImageOptionsDropDown.Value;
        FN = app.FrameNumberselection.Value;
%         contrastVal = app.ContrastSpinner.Value;
        switch val
            case 'Original Frame'
                I0 = app.V_in(:,:,FN);
            case 'Reconstructed (Amplitude)'
                I = app.RecImage(:,:,FN);
                I0 = abs(I).^2;
            case 'Reconstructed (Phase)'
                I = app.RecImage(:,:,FN);
                I0 = imag(I);
            case 'Wrapped Phase'
                I0 = app.WrappedPhase(:,:,FN);
            case 'Carrier Removed Wrapped Phase'
                I0 = app.NoCarrierWrappedPhase(:,:,FN);
            case 'Unwrapped Phase'
                I0 = app.UnwrappedPhase(:,:,FN);
            case 'Background'
                I0 = app.BackGround;
            case 'Background Removed Unwrapped Phase'
                I0 = app.UnwrappedPhase_noBG(:,:,FN);
            case 'Background Removed Unwrapped Phase (Filtered)'
                I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
        end
        grayContrast = app.ContrastSpinner.Value;
        Colmap = app.ColormapSelection.Value;
        ImageDisplay (I0, 2,grayContrast,Colmap, val)
        catch ME1
             warndlg(ME1.message,'Error');
        end
        end

        % Button pushed function: Surfplot
        function SurfplotButtonPushed(app, event)
        try
        val = app.ImageOptionsDropDown.Value;
        FN = app.FrameNumberselection.Value;
        %             contrastVal = app.ContrastSpinner.Value;
        switch val
            case 'Original Frame'
                I0 = app.V_in(:,:,FN);
            case 'Reconstructed (Amplitude)'
                I = app.RecImage(:,:,FN);
                I0 = abs(I).^2;
            case 'Reconstructed (Phase)'
                I = app.RecImage(:,:,FN);
                I0 = imag(I);
            case 'Wrapped Phase'
                I0 = app.WrappedPhase(:,:,FN);
            case 'Carrier Removed Wrapped Phase'
                I0 = app.NoCarrierWrappedPhase(:,:,FN);
            case 'Unwrapped Phase'
                I0 = app.UnwrappedPhase(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
            case 'Background'
                I0 = app.BackGround;
            case 'Background Removed Unwrapped Phase'
                I0 = app.UnwrappedPhase_noBG(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
            case 'Background Removed Unwrapped Phase (Filtered)'
                I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
                lambda = app.LaserWavelengthnmDHI.Value *10^-9;
                I0 = 10^6*I0/(4*pi)*lambda/10^-3;
        end
        Colmap = app.ColormapSelection.Value;
        figure; surfl(I0(end:-1:1,:));shading interp; colormap(Colmap);  title(val);
        catch ME1
             warndlg(ME1.message,'Error');
        end
        end

        % Value changed function: ImageOptionsDropDown
        function ImageOptionsDropDownValueChanged(app, event)
            try
                value = app.ImageOptionsDropDown.Value;
                FN = app.FrameNumberselection.Value;
                app.Play.Enable = 'Off';
                app.timeplot.Enable = 'Off';
                app.upperlimitEditField.Enable = 'Off';
                app.lowerlimitEditField.Enable = 'Off';
                app.ysizeEditField.Enable = 'Off';
                app.xsizeEditField.Enable ='Off';
                switch value
                    case 'Original Frame'
                        I0 = app.V_in(:,:,FN);
                    case 'Reconstructed (Amplitude)'
                        I = app.RecImage(:,:,FN);
                        I0 = abs(I).^2;
                    case 'Reconstructed (Phase)'
                        I = app.RecImage(:,:,FN);
                        I0 = imag(I);
                    case 'Wrapped Phase'
                        I0 = app.WrappedPhase(:,:,FN);
                    case 'Carrier Removed Wrapped Phase'
                        I0 = app.NoCarrierWrappedPhase(:,:,FN);
                        if size(app.UnwrappedPhase,3)>1
                            app.Play.Enable = 'On';
                            app.timeplot.Enable = 'On';
                            app.upperlimitEditField.Enable = 'On';
                            app.lowerlimitEditField.Enable = 'On';
                            app.ysizeEditField.Enable = 'On';
                            app.xsizeEditField.Enable ='On';
                        end
                    case 'Unwrapped Phase'
                        I0 = app.UnwrappedPhase(:,:,FN);
                        if size(app.UnwrappedPhase,3)>1
                            app.Play.Enable = 'On';
                            app.timeplot.Enable = 'On';
                            app.upperlimitEditField.Enable = 'On';
                            app.lowerlimitEditField.Enable = 'On';
                            app.ysizeEditField.Enable = 'On';
                            app.xsizeEditField.Enable ='On';
                        end
                    case 'Background'
                        I0 = app.BackGround;
                        if size(app.UnwrappedPhase,3)>1
                            app.Play.Enable = 'On';
                            app.timeplot.Enable = 'On';
                            app.upperlimitEditField.Enable = 'On';
                            app.lowerlimitEditField.Enable = 'On';
                            app.ysizeEditField.Enable = 'On';
                            app.xsizeEditField.Enable ='On';
                        end
                    case 'Background Removed Unwrapped Phase'
                        I0 = app.UnwrappedPhase_noBG(:,:,FN);
                        if size(app.UnwrappedPhase,3)>1
                            app.Play.Enable = 'On';
                            app.timeplot.Enable = 'On';
                            app.upperlimitEditField.Enable = 'On';
                            app.lowerlimitEditField.Enable = 'On';
                            app.ysizeEditField.Enable = 'On';
                            app.xsizeEditField.Enable ='On';
                        end
                    case 'Background Removed Unwrapped Phase (Filtered)'
                        I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
                        if size(app.UnwrappedPhase,3)>1
                            app.Play.Enable = 'On';
                            app.timeplot.Enable = 'On';
                            app.upperlimitEditField.Enable = 'On';
                            app.lowerlimitEditField.Enable = 'On';
                            app.ysizeEditField.Enable = 'On';
                            app.xsizeEditField.Enable ='On';
                        end
                end
                %             imshow(I0, [], 'Parent', app.UIAxes4_2);
                I = imshow(I0, [], 'Parent', app.UIAxes4_2, 'XData', [1 app.UIAxes4_2.Position(3)],'YData', [1 app.UIAxes4_2.Position(4)]);
                % Set limits of axes
                app.UIAxes4_2.XLim = [0 I.XData(2)];
                app.UIAxes4_2.YLim = [0 I.YData(2)];
                histogram(app.HistogramUi_2, I0, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                xNum = app.xSlider_2.Value;
                xPixelNum = round(xNum *(size(I0, 2)-1)+1);
                plot(app.VerticalUIAxes_2, I0(:, xPixelNum), 'Color','b');
                yNum = app.ySlider_2.Value;
                yPixelNum = round(yNum *(size(I0, 1)-1)+1);
                plot(app.HorizontalUIAxes_2, I0(yPixelNum,:), 'Color','r');
                xPixelNum = round((xNum *(size(I0, 2)-1)+1)*app.UIAxes4_2.XLim(2)/size(I0, 2));
                app.l3 = line(app.UIAxes4_2, [xPixelNum xPixelNum], [0 app.UIAxes4_2.YLim(2)]);
                app.l3.Color = 'b';
                yPixelNum = round((abs(yNum-1)*(size(I0, 1)-1)+1)*app.UIAxes4_2.YLim(2)/size(I0, 1));
                app.l4 = line(app.UIAxes4_2, [0 app.UIAxes4_2.XLim(2)], [yPixelNum yPixelNum]);
                app.l4.Color = 'r';
            catch ME1
                warndlg(ME1.message,'Error');
            end
        end

        % Value changed function: xSlider_2
        function xSlider_2ValueChanged(app, event)
        try    
        val = app.ImageOptionsDropDown.Value;
            FN = app.FrameNumberselection.Value;
            switch val
                case 'Original Frame'
                    I0 = app.V_in(:,:,FN);
                case 'Reconstructed (Amplitude)'
                    I = app.RecImage(:,:,FN);
                    I0 = abs(I).^2;
                case 'Reconstructed (Phase)'
                    I = app.RecImage(:,:,FN);
                    I0 = imag(I);
                case 'Wrapped Phase'
                    I0 = app.WrappedPhase(:,:,FN);
                case 'Carrier Removed Wrapped Phase'
                    I0 = app.NoCarrierWrappedPhase(:,:,FN);
                case 'Unwrapped Phase'
                    I0 = app.UnwrappedPhase(:,:,FN);
                case 'Background'
                    I0 = app.BackGround;
                case 'Background Removed Unwrapped Phase'
                    I0 = app.UnwrappedPhase_noBG(:,:,FN);
                case 'Background Removed Unwrapped Phase (Filtered)'
                    I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
            end
            xNum = app.xSlider_2.Value;
            xPixelNum = round(xNum *(size(I0, 2)-1)+1);
            plot(app.VerticalUIAxes_2, I0(:, xPixelNum), 'Color','b');
            delete(app.l3)
            xPixelNum = round((xNum *(size(I0, 2)-1)+1)*app.UIAxes4_2.XLim(2)/size(I0, 2));
            app.l3 = line(app.UIAxes4_2, [xPixelNum xPixelNum], [0 app.UIAxes4_2.YLim(2)]);
            app.l3.Color = 'b';
        catch ME1
             warndlg(ME1.message,'Error');
        end
        end

        % Value changed function: ySlider_2
        function ySlider_2ValueChanged(app, event)
        try    
        val = app.ImageOptionsDropDown.Value;
            FN = app.FrameNumberselection.Value;
            switch val
                case 'Original Frame'
                    I0 = app.V_in(:,:,FN);
                case 'Reconstructed (Amplitude)'
                    I = app.RecImage(:,:,FN);
                    I0 = abs(I).^2;
                case 'Reconstructed (Phase)'
                    I = app.RecImage(:,:,FN);
                    I0 = imag(I);
                case 'Wrapped Phase'
                    I0 = app.WrappedPhase(:,:,FN);
                case 'Carrier Removed Wrapped Phase'
                    I0 = app.NoCarrierWrappedPhase(:,:,FN);
                case 'Unwrapped Phase'
                    I0 = app.UnwrappedPhase(:,:,FN);
                case 'Background'
                    I0 = app.BackGround;
                case 'Background Removed Unwrapped Phase'
                    I0 = app.UnwrappedPhase_noBG(:,:,FN);
                case 'Background Removed Unwrapped Phase (Filtered)'
                    I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
            end
            yNum = app.ySlider_2.Value;
            yPixelNum = round(abs(yNum-1)*(size(I0, 1)-1)+1);
            plot(app.HorizontalUIAxes_2, I0(yPixelNum,:), 'Color','r');
            delete(app.l4)
            yPixelNum = round((abs(yNum-1)*(size(I0, 1)-1)+1)*app.UIAxes4_2.YLim(2)/size(I0, 1));
            app.l4 = line(app.UIAxes4_2, [0 app.UIAxes4_2.XLim(2)], [yPixelNum yPixelNum]);
            app.l4.Color = 'r';
        catch ME1
             warndlg(ME1.message,'Error');
        end
        end

        % Button pushed function: FilterButton_2
        function FilterButton_2Pushed(app, event)
            f = app.PhaseWare;
            try
                d = uiprogressdlg(f,'Title','Data Filtering','Indeterminate','on', 'Cancelable','on');
                val1 = app.AveragefilterCheckBox_2.Value;
                val2 = app.MedianfilterCheckBox_3.Value;
                val3 = app.AdaptiveLowpassWienerfilterCheckBox.Value;
                val4 = app.DeepneuralnetworkCheckBox.Value;
                val5 = app.CustomizedMATLABfunctionCheckBox.Value;
                val = app.ContrastEnhancementButtonGroup.SelectedObject;
                V0 = app.V_filtered;
                if app.UseRef==1
                    Vr = app.V_ref_filtered;
                end
                NoF = size(V0,3);
                if val1 ==1 % Average Filter
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = filter2(fspecial('average',[app.filtersizemnSpinner.Value,app.filtersizemnSpinner_2.Value]),I0);
                    end
                    if app.UseRef==1
                        Vr = filter2(fspecial('average',[app.filtersizemnSpinner.Value,app.filtersizemnSpinner_2.Value]),Vr);
                    end
                end
                if val2 ==1 % Median Filter
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = medfilt2(I0, [app.filtersizemnSpinner_3.Value,app.filtersizemnSpinner_4.Value]);
                    end
                    if app.UseRef==1
                        Vr = medfilt2(Vr, [app.filtersizemnSpinner_3.Value,app.filtersizemnSpinner_4.Value]);
                    end
                end
                if val3 ==1 % Adaptive Lowpass Wiener Filter
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = wiener2(I0,[app.filtersizemnSpinner_5.Value,app.filtersizemnSpinner_6.Value]);
                    end
                    if app.UseRef==1
                        Vr = wiener2(Vr,[app.filtersizemnSpinner_5.Value,app.filtersizemnSpinner_6.Value]);
                    end
                end
                if val4 ==1 % Deep neural network
                    net = denoisingNetwork('DnCNN');
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = denoiseImage(I0,net);
                    end
                    if app.UseRef==1
                        Vr = denoiseImage(Vr,net);
                    end
                end
                if val5 ==1
                    [file,path] = uigetfile('*.m');
                        if (ischar(path))
                            selectedfile = fullfile(path,file);
                            addpath(path);
                            [~,name,~] = fileparts(selectedfile);
                            fh = str2func(name);
                            for i = 1:NoF
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                I0 = V0(:,:,i);
                                V0(:,:,i) = double(fh(I0));
                            end
                        end
                        if app.UseRef ==1
                            Vr = double(fh(Vr));
                        end
                end
                switch val.Text
                    case 'Original'
                        % do nothing
                    case 'Window Normalization'
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            V0(:,:,i) = WindowedNormalization(I0, app.windowsizemnSpinner.Value, app.windowsizemnSpinner_2.Value);
                        end
                        if app.UseRef==1
                            Vr = WindowedNormalization(Vr, app.windowsizemnSpinner.Value, app.windowsizemnSpinner_2.Value);
                        end
                    case 'Customized MATLAB function'
                        [file,path] = uigetfile('*.m');
                        if (ischar(path))
                            selectedfile = fullfile(path,file);
                            addpath(path);
                            [~,name,~] = fileparts(selectedfile);
                            fh = str2func(name);
                            for i = 1:NoF
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                I0 = V0(:,:,i);
                                V0(:,:,i) = double(fh(I0));
                            end
                        end
                        if app.UseRef ==1
                            Vr = double(fh(Vr));
                        end
                end
                if strcmp(app.Switch_3.Value, 'Main')
                    I = imshow(V0(:,:,app.FrameSpinner_6.Value), [], 'Parent', app.UIAxes2_8, ...
                        'XData', [1 app.UIAxes2_8.Position(3)], 'YData', [1 app.UIAxes2_8.Position(4)]);
                    % Set limits of axes
                    app.UIAxes2_8.XLim = [0 I.XData(2)]; app.UIAxes2_8.YLim = [0 I.YData(2)];
                else
                    I = imshow(Vr, [], 'Parent', app.UIAxes2_8, ...
                        'XData', [1 app.UIAxes2_8.Position(3)], 'YData', [1 app.UIAxes2_8.Position(4)]);
                    % Set limits of axes
                    app.UIAxes2_8.XLim = [0 I.XData(2)]; app.UIAxes2_8.YLim = [0 I.YData(2)];
                end
                app.V_filtered = [];
                app.V_filtered = V0;
                if app.UseRef==1
                    app.V_ref_filtered = [];
                    app.V_ref_filtered = Vr;
                end
            catch ME1
                warndlg(ME1.message,'Error');
            end
            close(d)
        end

        % Value changed function: AveragefilterCheckBox_2
        function AveragefilterCheckBox_2ValueChanged(app, event)
        value = app.AveragefilterCheckBox_2.Value;
        if value ==1
            app.filtersizemnSpinner.Enable = 'On';
            app.filtersizemnSpinner_2.Enable = 'On';
        else
            app.filtersizemnSpinner.Enable = 'Off';
            app.filtersizemnSpinner_2.Enable = 'Off';
        end
        end

        % Value changed function: MedianfilterCheckBox_3
        function MedianfilterCheckBox_3ValueChanged(app, event)
        value = app.MedianfilterCheckBox_3.Value;
        if value ==1
            app.filtersizemnSpinner_3.Enable = 'On';
            app.filtersizemnSpinner_4.Enable = 'On';
        else
            app.filtersizemnSpinner_3.Enable = 'Off';
            app.filtersizemnSpinner_4.Enable = 'Off';
        end
        end

        % Value changed function: 
        % AdaptiveLowpassWienerfilterCheckBox
        function AdaptiveLowpassWienerfilterCheckBoxValueChanged(app, event)
        value = app.AdaptiveLowpassWienerfilterCheckBox.Value;
        if value ==1
            app.filtersizemnSpinner_5.Enable = 'On';
            app.filtersizemnSpinner_6.Enable = 'On';
        else
            app.filtersizemnSpinner_5.Enable = 'Off';
            app.filtersizemnSpinner_6.Enable = 'Off';
        end
        end

        % Selection changed function: ContrastEnhancementButtonGroup
        function ContrastEnhancementButtonGroupSelectionChanged(app, event)
        selectedButton = app.ContrastEnhancementButtonGroup.SelectedObject;
        switch selectedButton.Text
            case 'Original'
                app.windowsizemnSpinner.Enable = 'Off';
                app.windowsizemnSpinner_2.Enable = 'Off';
            case 'Window Normalization'
                app.windowsizemnSpinner.Enable = 'On';
                app.windowsizemnSpinner_2.Enable = 'On';
        end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            try
                f = app.PhaseWare;
            d = uiprogressdlg(f,'Title','Data Filtering','Indeterminate','on', 'Cancelable','on');
            app.WrappedPhase_filtered = [];
            if app.UseRef==1
                app.WrappedPhase_ref_filtered = [];
            end
            val1 = app.GaussianlowpassfilterCheckBox.Value;
            val2 = app.MedianfilterCheckBox_2.Value;
            val3 = app.AdaptivelowpassWienerfilterCheckBox.Value;
            val4 = app.DeepneuralnetworkCheckBox_4.Value;
            val5 = app.CustomizedMATLABfunctionCheckBox_2.Value;
            val = app.DataOptionsDropDown.Value;
            switch val
                case 'Wrapped Phase'
                    V0 = app.WrappedPhase;
                    if app.UseRef == 1
                        Vr = app.WrappedPhase_ref;
                    end
                case 'Carrier Removed Wrapped Phase'
                    V0 = app.NoCarrierWrappedPhase;
                    if app.UseRef == 1
                        Vr = app.NoCarrierWrappedPhase_ref;
                    end
            end
            NoF = size(V0,3);
            if val1 ==1 % Average Filter
                for i = 1:NoF
                    if d.CancelRequested == 1
                        close(d);
                        return
                    end
                    I0 = V0(:,:,i);
                    H = fspecial('gaussian',[app.sizemnSpinner.Value,app.filtersizemnSpinner_8.Value], app.sigmaSpinner.Value);
                    V0(:,:,i) = imfilter(I0, H);
                end
                if app.UseRef==1
                    H = fspecial('gaussian',[app.sizemnSpinner.Value,app.filtersizemnSpinner_8.Value], app.sigmaSpinner.Value);
                    Vr = imfilter(Vr, H);
                end
            end
            if val2 ==1 % Median Filter
                for i = 1:NoF
                    if d.CancelRequested == 1
                        close(d);
                        return
                    end
                    I0 = V0(:,:,i);
                    V0(:,:,i) = medfilt2(I0, [app.sizemnSpinner_2.Value,app.filtersizemnSpinner_9.Value]);
                end
                if app.UseRef==1
                    Vr = medfilt2(Vr, [app.sizemnSpinner_2.Value,app.filtersizemnSpinner_9.Value]);
                end
            end
            if val3 ==1 % Adaptive Lowpass Wiener Filter
                for i = 1:NoF
                    if d.CancelRequested == 1
                        close(d);
                        return
                    end
                    I0 = V0(:,:,i);
                    V0(:,:,i) = wiener2(I0,[app.sizemnSpinner_3.Value,app.filtersizemnSpinner_11.Value]);
                end
                if app.UseRef==1
                    Vr = wiener2(Vr,[app.sizemnSpinner_3.Value,app.filtersizemnSpinner_11.Value]);
                end
            end
            if val4 ==1 % Deep neural network
                net = denoisingNetwork('DnCNN');
                for i = 1:NoF
                    if d.CancelRequested == 1
                        close(d);
                        return
                    end
                    I0 = V0(:,:,i);
                    V0(:,:,i) = denoiseImage(I0,net);
                end
                if app.UseRef==1
                    Vr = denoiseImage(Vr,net);
                end
            end
            if val5 ==1
                [file,path] = uigetfile('*.m');
                if (ischar(path))
                    selectedfile = fullfile(path,file);
                    addpath(path);
                    [~,name,~] = fileparts(selectedfile);
                    fh = str2func(name);
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = double(fh(I0));
                    end
                end
                if app.UseRef ==1
                    Vr = double(fh(Vr));
                end
            end
            if strcmp(app.Switch_5.Value, 'Main')
                I = imshow(V0(:,:,app.FrameSpinner_8.Value), [], 'Parent', app.UIAxes2_9, ...
                    'XData', [1 app.UIAxes2_9.Position(3)], 'YData', [1 app.UIAxes2_9.Position(4)]);
                app.UIAxes2_9.XLim = [0 I.XData(2)]; app.UIAxes2_9.YLim = [0 I.YData(2)];
            else
                I = imshow(Vr, [], 'Parent', app.UIAxes2_9, ...
                    'XData', [1 app.UIAxes2_9.Position(3)], 'YData', [1 app.UIAxes2_9.Position(4)]);
                app.UIAxes2_9.XLim = [0 I.XData(2)]; app.UIAxes2_9.YLim = [0 I.YData(2)];
            end
            app.WrappedPhase_filtered = V0;
            if app.UseRef==1
                app.WrappedPhase_ref_filtered = Vr;
            end
            catch ME1
                warndlg(ME1.message,'Error');
            end
            close(d)
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            f = app.PhaseWare;
            d = uiprogressdlg(f,'Title','Phase Unwrapping','Indeterminate','on', 'Cancelable','on');
            %             try
            app.UnwrappedPhase =[];
            selectedButton = app.UnwrappingAlgorithmButtonGroup.SelectedObject;
            V0 =  app.WrappedPhase_filtered;
            if app.UseRef==1
                app.UnwrappedPhase_ref =[];
                Vr =  app.WrappedPhase_ref_filtered;
            end
            NoF = size(V0,3);
            switch selectedButton.Text
                case 'Constantin'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = UnwrappingConstantin(I0);
                        V0(:,:,i) = double(V0(:,:,i));
                    end
                    if app.UseRef==1
                        Vr = UnwrappingConstantin(Vr);
                        Vr = double(Vr);
                    end
                    if app.UseMasksCheckBox.Value
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            Itemp = V0(:,:,i);
                            if strcmp(app.Switch_7.Value, 'A Mask per frame')
                                Itemp(~app.Mask(:,:,i)) = nan;
                            else
                                Itemp(~app.Mask) = nan;
                            end
                            V0(:,:,i)= Itemp;
                        end
                    end
                case'Ghiglia and Pritt'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = single(V0(:,:,i));
                        V0(:,:,i) = UnwrappingGhigliaPritt(I0);
                        V0(:,:,i) = double(V0(:,:,i));
                    end
                    if app.UseRef==1
                        Vr = UnwrappingGhigliaPritt(single(Vr));
                        Vr = double(Vr);
                    end
                    if app.UseMasksCheckBox.Value
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            Itemp = V0(:,:,i);
                            if strcmp(app.Switch_7.Value, 'A Mask per frame')
                                Itemp(~app.Mask(:,:,i)) = nan;
                            else
                                Itemp(~app.Mask) = nan;
                            end
                            V0(:,:,i)= Itemp;
                        end
                    end
                case 'Ghiglia and Romero'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = UnwrappingGhigliaRomero(I0);
                        V0(:,:,i) = double(V0(:,:,i));
                    end
                    if app.UseRef==1
                        Vr = UnwrappingGhigliaRomero(Vr);
                        Vr = double(Vr);
                    end
                    if app.UseMasksCheckBox.Value
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            Itemp = V0(:,:,i);
                            if strcmp(app.Switch_7.Value, 'A Mask per frame')
                                Itemp(~app.Mask(:,:,i)) = nan;
                            else
                                Itemp(~app.Mask) = nan;
                            end
                            V0(:,:,i)= Itemp;
                        end
                    end
                case 'Goldstein'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = UnwrappingGoldstein(I0);
                        V0(:,:,i) = double(V0(:,:,i));
                    end
                    if app.UseRef==1
                        Vr = UnwrappingGoldstein(Vr);
                        Vr = double(Vr);
                    end
                    if app.UseMasksCheckBox.Value
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            Itemp = V0(:,:,i);
                            if strcmp(app.Switch_7.Value, 'A Mask per frame')
                                Itemp(~app.Mask(:,:,i)) = nan;
                            else
                                Itemp(~app.Mask) = nan;
                            end
                            V0(:,:,i)= Itemp;
                        end
                    end
                case 'Volkov'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        V0(:,:,i) = UnwrappingVolkov(I0);
                        V0(:,:,i) = double(V0(:,:,i));
                    end
                    if app.UseRef==1
                        Vr = UnwrappingVolkov(Vr);
                        Vr = double(Vr);
                    end
                    if app.UseMasksCheckBox.Value
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            Itemp = V0(:,:,i);
                            if strcmp(app.Switch_7.Value, 'A Mask per frame')
                                Itemp(~app.Mask(:,:,i)) = nan;
                            else
                                Itemp(~app.Mask) = nan;
                            end
                            V0(:,:,i)= Itemp;
                        end
                    end
                case 'Customized MATLAB Function'
                    [file,path] = uigetfile('*.m');
                    if (ischar(path))
                        selectedfile = fullfile(path,file);
                        addpath(path);
                        [~,name,~] = fileparts(selectedfile);
                        fh = str2func(name);
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = single(V0(:,:,i));
                            V0(:,:,i) = double(fh(I0));
                        end
                        if app.UseMasksCheckBox.Value
                            for i = 1:NoF
                                if d.CancelRequested == 1
                                    close(d);
                                    return
                                end
                                Itemp = V0(:,:,i);
                                if strcmp(app.Switch_7.Value, 'A Mask per frame')
                                    Itemp(~app.Mask(:,:,i)) = nan;
                                else
                                    Itemp(~app.Mask) = nan;
                                end
                                V0(:,:,i)= Itemp;
                            end
                        end
                    end
            end
            if strcmp(app.Switch_6.Value, 'Main')
                I = imshow(V0(:,:,app.FrameSpinner_9.Value), [], 'Parent', app.UIAxes2_11, ...
                    'XData', [1 app.UIAxes2_11.Position(3)], 'YData', [1 app.UIAxes2_11.Position(4)]);
                app.UIAxes2_11.XLim = [0 I.XData(2)]; app.UIAxes2_11.YLim = [0 I.YData(2)];
            else
                I = imshow(Vr, [], 'Parent', app.UIAxes2_11, ...
                    'XData', [1 app.UIAxes2_11.Position(3)], 'YData', [1 app.UIAxes2_11.Position(4)]);
                app.UIAxes2_11.XLim = [0 I.XData(2)]; app.UIAxes2_11.YLim = [0 I.YData(2)];
            end
            app.UnwrappedPhase = V0;
            if app.UseRef==1
                app.UnwrappedPhase_ref = Vr;
            end
            %         catch ME1
            %             warndlg(ME1.message,'Error');
            %         end
            close(d)
        end

        % Button pushed function: ProcessButton_4
        function ProcessButton_4Pushed(app, event)
            f = app.PhaseWare;
            d = uiprogressdlg(f,'Title','Background Removal','Indeterminate','on', 'Cancelable','on');
            try
            app.BackGround =[];
            app.UnwrappedPhase_noBG = [];
            selectedButton = app.MethodButtonGroup.SelectedObject;
            V0 =  app.UnwrappedPhase;
            if app.UseRef==1
                Vr =  app.UnwrappedPhase_ref;
            end
            NoF = size(V0,3);
            switch selectedButton.Text
                case 'Manual Backgroung Estimation'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        figure; imshow(I0,[]); title('Select points using left mouse button. Press ENTER key when done')
                        [x, y, I] = impixel;
                        I = I(:,3);  % Keep only one column since for graylevel images, all columns are same.
                        % Plot points
                        hold on; plot(x, y, 'y+'); hold off
                        % Fit data at selected points to background function
                        %  Solve least-squares problem: [C]{a} = {k} using the
                        %    \ operator, i.e., {a} = [C]\{k}
                        %  First, compute elements of the matrix [C]
                        N = length(x);
                        Sx = sum(x);
                        Sy = sum(y);
                        Sxx = sum(x.*x);
                        Syy = sum(y.*y);
                        Sxy = sum(x.*y);
                        Sxxx = sum(x.^3);
                        Sxxy = sum(x.*x.*y);
                        Sxyy = sum(x.*y.*y);
                        Syyy = sum(y.^3);
                        Sxxxx = sum(x.^4);
                        Sxxxy = sum(y.*x.^3);
                        Sxxyy = sum(x.*x.*y.*y);
                        Sxyyy = sum(x.*y.^3);
                        Syyyy = sum(y.^4);
                        C = [N    Sx  Sy   Sxx   Syy   Sxy;
                            Sx   Sxx Sxy  Sxxx  Sxyy  Sxxy;
                            Sy   Sxy Syy  Sxxy  Syyy  Sxyy;
                            Sxx Sxxx Sxxy Sxxxx Sxxyy Sxxxy;
                            Syy Sxyy Syyy Sxxyy Syyyy Sxyyy;
                            Sxy Sxxy Sxyy Sxxxy Sxyyy Sxxyy];
                        % Construct {k}
                        SI = sum(I);
                        SxI = sum(x.*I);
                        SyI = sum(y.*I);
                        SxxI = sum(x.*x.*I);
                        SyyI = sum(y.*y.*I);
                        SxyI = sum(x.*y.*I);
                        k = [SI SxI SyI SxxI SyyI SxyI]';
                        % Solve
                        a = C\k;
                        % Remove background
                        % First compute background image
                        [rows, cols] = size(I0);
                        [x, y] = meshgrid( 1:cols, 1:rows );
                        back = a(1) + a(2)*x + a(3)*y + a(4)*x.*x + a(5)*y.*y +a(6)*x.*y;
                        I2 = double(I0) - back; %
                        app.BackGround(:,:,i) = back;
                        app.UnwrappedPhase_noBG(:,:,i) = I2;
                    end
                case'Automatic Background Estimation'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        [UnwrappedPhaseWithoutBG, BG] = BackgroundRemoval(I0);
                        app.BackGround(:,:,i) = BG;
                        app.UnwrappedPhase_noBG(:,:,i) = UnwrappedPhaseWithoutBG;
                    end
                case 'Using Reference'
                    for i = 1:NoF
                        if d.CancelRequested == 1
                            close(d);
                            return
                        end
                        I0 = V0(:,:,i);
                        UnwrappedPhaseWithoutBG = I0 - Vr;
                        app.BackGround(:,:,i) = Vr;
                        app.UnwrappedPhase_noBG(:,:,i) = UnwrappedPhaseWithoutBG;
                    end
                case 'Customized MATLAB Function'
                    [file,path] = uigetfile('*.m');
                    if (ischar(path))
                        selectedfile = fullfile(path,file);
                        addpath(path);
                        [~,name,~] = fileparts(selectedfile);
                        fh = str2func(name);
                        for i = 1:NoF
                            if d.CancelRequested == 1
                                close(d);
                                return
                            end
                            I0 = V0(:,:,i);
                            [app.UnwrappedPhase_noBG(:,:,i),app.BackGround(:,:,i)] = double(fh(I0));
                        end
                    end
            end
            I = imshow(app.UnwrappedPhase_noBG(:,:,app.FrameSpinner_10.Value), [], 'Parent', app.UIAxes2_12, ...
                'XData', [1 app.UIAxes2_12.Position(3)], 'YData', [1 app.UIAxes2_12.Position(4)]);
            % Set limits of axes
            app.UIAxes2_12.XLim = [0 I.XData(2)]; app.UIAxes2_12.YLim = [0 I.YData(2)];
            catch ME1
                warndlg(ME1.message,'Error');
            end
            close(d)
        end

        % Value changed function: GaussianlowpassfilterCheckBox
        function GaussianlowpassfilterCheckBoxValueChanged(app, event)
        value = app.GaussianlowpassfilterCheckBox.Value;
        if value ==1
            app.sizemnSpinner.Enable = 'On';
            app.filtersizemnSpinner_8.Enable = 'On';
            app.sigmaSpinner.Enable = 'On';
        else
            app.sizemnSpinner.Enable = 'Off';
            app.filtersizemnSpinner_8.Enable = 'Off';
            app.sigmaSpinner.Enable = 'Off';
        end
        end

        % Value changed function: MedianfilterCheckBox_2
        function MedianfilterCheckBox_2ValueChanged(app, event)
        value = app.MedianfilterCheckBox_2.Value;
        if value ==1
            app.sizemnSpinner_2.Enable = 'On';
            app.filtersizemnSpinner_9.Enable = 'On';
        else
            app.sizemnSpinner_2.Enable = 'Off';
            app.filtersizemnSpinner_9.Enable = 'Off';
            
        end
        end

        % Value changed function: 
        % AdaptivelowpassWienerfilterCheckBox
        function AdaptivelowpassWienerfilterCheckBoxValueChanged(app, event)
        value = app.AdaptivelowpassWienerfilterCheckBox.Value;
        if value ==1
            app.sizemnSpinner_3.Enable = 'On';
            app.filtersizemnSpinner_11.Enable = 'On';
        else
            app.sizemnSpinner_3.Enable = 'Off';
            app.filtersizemnSpinner_11.Enable = 'Off';
        end
        end

        % Button pushed function: Histogram
        function HistogramButtonPushed(app, event)
        try
            val = app.ImageOptionsDropDown.Value;
        FN = app.FrameNumberselection.Value;
        switch val
            case 'Original Frame'
                I0 = app.V_in(:,:,FN);
            case 'Reconstructed (Amplitude)'
                I = app.RecImage(:,:,FN);
                I0 = abs(I).^2;
            case 'Reconstructed (Phase)'
                I = app.RecImage(:,:,FN);
                I0 = imag(I);
            case 'Wrapped Phase'
                I0 = app.WrappedPhase(:,:,FN);
            case 'Carrier Removed Wrapped Phase'
                I0 = app.NoCarrierWrappedPhase(:,:,FN);
            case 'Unwrapped Phase'
                I0 = app.UnwrappedPhase(:,:,FN);
            case 'Background'
                I0 = app.BackGround;
            case 'Background Removed Unwrapped Phase'
                I0 = app.UnwrappedPhase_noBG(:,:,FN);
            case 'Background Removed Unwrapped Phase (Filtered)'
                I0 = app.UnwrappedPhase_noBG_filtered(:,:,FN);
        end
        figure; histogram(I0); title(val)
        catch ME1
            warndlg(ME1.message,'Error');
        end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
        ImageOptionsDropDownValueChanged(app, event)
        end

        % Button pushed function: ProcessButton_5
        function ProcessButton_5Pushed(app, event)
        f = app.PhaseWare;
        d = uiprogressdlg(f,'Title','Data Filtering','Indeterminate','on', 'Cancelable','on');
        try
        app.UnwrappedPhase_noBG_filtered = [];
        val1 = app.AveragefilterCheckBox.Value;
        val2 = app.MedianfilterCheckBox.Value;
        val3 = app.AdaptiveLowpassWienerFilterCheckBox_4.Value;
        val4 = app.DeepneuralnetworkCheckBox_3.Value;
        val5 = app.CustomizedMATLABfunctionCheckBox_3.Value;
        V0 = app.UnwrappedPhase_noBG;
        NoF = size(V0,3);
        if val1 ==1 % Average Filter
            for i = 1:NoF
                if d.CancelRequested == 1
                    close(d);
                    return
                end
                I0 = V0(:,:,i);
                V0(:,:,i) = filter2(fspecial('average',[app.sizemnSpinner_4.Value,app.filtersizemnSpinner_12.Value]),I0);
            end
        end
        if val2 ==1 % Median Filter
            for i = 1:NoF
                if d.CancelRequested == 1
                    close(d);
                    return
                end
                I0 = V0(:,:,i);
                V0(:,:,i) = medfilt2(I0, [app.sizemnSpinner_5.Value,app.filtersizemnSpinner_13.Value]);
            end
        end
        if val3 ==1 % Adaptive Lowpass Wiener Filter
            for i = 1:NoF
                if d.CancelRequested == 1
                    close(d);
                    return
                end
                I0 = V0(:,:,i);
                V0(:,:,i) = wiener2(I0,[app.sizemnSpinner_6.Value,app.filtersizemnSpinner_14.Value]);
            end
        end
        if val4 ==1 % Deep neural network
            net = denoisingNetwork('DnCNN');
            for i = 1:NoF
                if d.CancelRequested == 1
                    close(d);
                    return
                end
                I0 = V0(:,:,i);
                V0(:,:,i) = denoiseImage(I0,net);
            end
        end
        if val5 ==1
            [file,path] = uigetfile('*.m');
            if (ischar(path))
                selectedfile = fullfile(path,file);
                addpath(path);
                [~,name,~] = fileparts(selectedfile);
                fh = str2func(name);
                for i = 1:NoF
                    if d.CancelRequested == 1
                        close(d);
                        return
                    end
                    I0 = V0(:,:,i);
                    V0(:,:,i) = double(fh(I0));
                end
            end
        end
        I = imshow(V0(:,:,app.FrameSpinner_11.Value), [], 'Parent', app.UIAxes2_13, ...
            'XData', [1 app.UIAxes2_13.Position(3)], 'YData', [1 app.UIAxes2_13.Position(4)]);
        % Set limits of axes
        app.UIAxes2_13.XLim = [0 I.XData(2)]; app.UIAxes2_13.YLim = [0 I.YData(2)];
        app.UnwrappedPhase_noBG_filtered = V0;
        catch ME1
            warndlg(ME1.message,'Error');
        end
        close(d)
        end

        % Value changed function: AveragefilterCheckBox
        function AveragefilterCheckBoxValueChanged(app, event)
        value = app.AveragefilterCheckBox.Value;
        if value ==1
            app.sizemnSpinner_4.Enable = 'On';
            app.filtersizemnSpinner_12.Enable = 'On';
        else
            app.sizemnSpinner_4.Enable = 'Off';
            app.filtersizemnSpinner_12.Enable = 'Off';
        end
        end

        % Value changed function: MedianfilterCheckBox
        function MedianfilterCheckBoxValueChanged(app, event)
        value = app.MedianfilterCheckBox.Value;
        if value ==1
            app.sizemnSpinner_5.Enable = 'On';
            app.filtersizemnSpinner_13.Enable = 'On';
        else
            app.sizemnSpinner_5.Enable = 'Off';
            app.filtersizemnSpinner_13.Enable = 'Off';
        end
        end

        % Value changed function: 
        % AdaptiveLowpassWienerFilterCheckBox_4
        function AdaptiveLowpassWienerFilterCheckBox_4ValueChanged(app, event)
        value = app.AdaptiveLowpassWienerFilterCheckBox_4.Value;
        if value ==1
            app.sizemnSpinner_6.Enable = 'On';
            app.filtersizemnSpinner_14.Enable = 'On';
        else
            app.sizemnSpinner_6.Enable = 'Off';
            app.filtersizemnSpinner_14.Enable = 'Off';
        end
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            f = app.PhaseWare;
            dia = uiprogressdlg(f,'Title','Phase Rerieval',...
                'Message','Please wait','Cancelable','on');
            %             try
            RemoveCarrier = app.RemovecarrierfrequencybyFouriermethodCheckBox_2.Value;
            WindowSize = app.FilterSizeProfilometryFPP.Value;
            NS2 = app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Value;
            FrequencyFilterSize = app.SmoothingFilterSizeFPP.Value;
            val2 = app.FrequencyFilterTypeDropDown_FPP.Value;
            switch val2
                case 'Gaussian'
                    FrequencyFilterType = 'gaussian';
                case 'Butterworth'
                    FrequencyFilterType = 'btw';
                case 'Ideal'
                    FrequencyFilterType = 'ideal';
            end
            
            app.WrappedPhase = [];
            app.NoCarrierWrappedPhase = [];
            if app.UseRef==1
                app.WrappedPhase_ref = [];
                app.NoCarrierWrappedPhase_ref = [];
            end
            selectedButton = app.AlgorithmButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Fourier Transform (FT)'
                    V0 = app.V_filtered;
                    for i = 1: size(V0,3)
                        if dia.CancelRequested == 1
                            close(dia);
                            return
                        end
                        I0 = V0(:,:,i);
                        if i ==1
                            [phase2, phase1, dim] = PhaseWrapping(I0, 2,NS2,RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,[],WindowSize);
                            app.WrappedPhase(:,:,i) = phase1;
                            app.NoCarrierWrappedPhase(:,:,i) = phase2;
                            continue
                        end
                        [phase2, phase1, ~] = PhaseWrapping(I0, 2, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,[],WindowSize,dim);
                        app.WrappedPhase(:,:,i) = phase1;
                        app.NoCarrierWrappedPhase(:,:,i) = phase2;
                    end
                    if app.UseRef ==1
                        I0 = app.V_ref_filtered;
                        [phase2, phase1, ~] = PhaseWrapping(I0, 2, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,[],WindowSize);
                        app.WrappedPhase_ref = phase1;
                        app.NoCarrierWrappedPhase_ref = phase2;
                    end
                case 'Wavelet Transform (WT)'
                    V0 = app.V_filtered;
                    for i = 1: size(V0,3)
                        if dia.CancelRequested == 1
                                close(dia);
                                return
                            end
                        I0 = V0(:,:,i);
                        if i ==1
                            [phase2, phase1, dim] = PhaseWrapping(I0, 3, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,[],WindowSize);
                            app.WrappedPhase(:,:,i) = phase1;
                            app.NoCarrierWrappedPhase(:,:,i) = phase2;
                            continue
                        end
                        [phase2, phase1, ~] = PhaseWrapping(I0, 3, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,[],WindowSize,dim);
                        app.WrappedPhase(:,:,i) = phase1;
                        app.NoCarrierWrappedPhase(:,:,i) = phase2;
                    end
                    if app.UseRef ==1
                        I0 = app.V_ref_filtered;
                        [phase2, phase1, ~] = PhaseWrapping(I0, 3, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,[],WindowSize);
                        app.WrappedPhase_ref = phase1;
                        app.NoCarrierWrappedPhase_ref = phase2;
                    end
                case 'Hilbert Transform (HT)'
                    V0 = app.V_filtered;
                    for i = 1: size(V0,3)
                        if dia.CancelRequested == 1
                                close(dia);
                                return
                            end
                        I0 = V0(:,:,i);
                        if i ==1
                            [phase2, phase1, dim] = PhaseWrapping(I0, 4, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,[],WindowSize);
                            app.WrappedPhase(:,:,i) = phase1;
                            app.NoCarrierWrappedPhase(:,:,i) = phase2;
                            continue
                        end
                        [phase2, phase1, ~] = PhaseWrapping(I0, 4, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,[],WindowSize,dim);
                        app.WrappedPhase(:,:,i) = phase1;
                        app.NoCarrierWrappedPhase(:,:,i) = phase2;
                    end
                    if app.UseRef ==1
                        I0 = app.V_ref_filtered;
                        [phase2, phase1, ~] = PhaseWrapping(I0, 4, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,[],WindowSize);
                        app.WrappedPhase_ref = phase1;
                        app.NoCarrierWrappedPhase_ref = phase2;
                    end
                case 'Windowed Fourier Transform'
                    switch app.AlgorithmOption_FPP.SelectedObject.Text
                        case 'WFF'
                            type = 1;
                        case 'WFR'
                            type = 2;
                    end
                    WFT_parameters = [type, app.sigmaxSpinner_FPP.Value, app.wxlSpinner_FPP.Value,app.wxiSpinner_FPP.Value,app.wxhSpinner_FPP.Value,...
                        app.sigmaySpinner_FPP.Value,app.wylSpinner_FPP.Value,app.wyiSpinner_FPP.Value,app.wyhSpinner_FPP.Value, app.thr_FPP.Value];
                    V0 = app.V_filtered;
                    for i = 1: size(V0,3)
                        if dia.CancelRequested == 1
                            close(dia);
                            return
                        end
                        I0 = V0(:,:,i);
                        if i ==1
                            [phase2, phase1, dim] = PhaseWrapping(I0, 5, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                            app.WrappedPhase(:,:,i) = phase1;
                            app.NoCarrierWrappedPhase(:,:,i) = phase2;
                            continue
                        end
                        [phase2, phase1, ~] = PhaseWrapping(I0, 5, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize,dim);
                        app.WrappedPhase(:,:,i) = phase1;
                        app.NoCarrierWrappedPhase(:,:,i) = phase2;
                    end
                    if app.UseRef ==1
                        I0 = app.V_ref_filtered;
                        [phase2, phase1, ~] = PhaseWrapping(I0, 5, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                        app.WrappedPhase_ref = phase1;
                        app.NoCarrierWrappedPhase_ref = phase2;
                    end
                case 'Customized MATLAB Function'
                    V0 = app.V_filtered;
                    for i = 1: size(V0,3)
                        if dia.CancelRequested == 1
                            close(dia);
                            return
                        end
                        I0 = V0(:,:,i);
                        if i ==1
                            [phase2, phase1, dim] = PhaseWrapping(I0, 0, NS2,RemoveCarrier, FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                            app.WrappedPhase(:,:,i) = phase1;
                            app.NoCarrierWrappedPhase(:,:,i) = phase2;
                            continue
                        end
                        [phase2, phase1, ~] = PhaseWrapping(I0, 0, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize,dim);
                        app.WrappedPhase(:,:,i) = phase1;
                        app.NoCarrierWrappedPhase(:,:,i) = phase2;
                    end
                    if app.UseRef ==1
                        I0 = app.V_ref_filtered;
                        [phase2, phase1, ~] = PhaseWrapping(I0, 0, NS2, RemoveCarrier,FrequencyFilterType,FrequencyFilterSize,WFT_parameters,WindowSize);
                        app.WrappedPhase_ref = phase1;
                        app.NoCarrierWrappedPhase_ref = phase2;
                    end
            end
            app.reportTitle = 'Fringe Profilometry';
            %             catch ME1
            %                 warndlg(ME1.message,'Error');
            %             end
            close(dia);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
    f = app.PhaseWare;
    d = uiprogressdlg(f,'Title','Loading Reference Data...','Indeterminate','on');
    try
    [FileName, PathName] = uigetfile ({ '*.TIF ; *.tif; *.PNG; *.png; *.bmp'}, 'File Selector');
    % Make sure user didn't cancel uigetfile dialog
    if (ischar(PathName))
        FullPathName = strcat (PathName, FileName);
        updateimage_reference(app, FullPathName);
    end
    app.PhaseWare.Visible = 'off';
    app.PhaseWare.Visible = 'on';
    catch ME1
        warndlg(ME1.message,'Error');
    end
    % close the dialog box
    close(d);
        end

        % Value changed function: xSlider_3
        function xSlider_3ValueChanged(app, event)
            I0 = app.V_ref_in;
            xNum = app.xSlider_3.Value;
            xPixelNum = round(xNum *(size(I0, 2)-1)+1);
            plot(app.VerticalUIAxes_3, I0(:, xPixelNum), 'Color','b');
            delete(app.l5)
            xPixelNum = round((xNum *(size(I0, 2)-1)+1)*app.UIAxes_2.XLim(2)/size(I0, 2));
            app.l5 = line(app.UIAxes_2, [xPixelNum xPixelNum], [0 app.UIAxes_2.YLim(2)]);
            app.l5.Color = 'b';
        end

        % Value changed function: ySlider_3
        function ySlider_3ValueChanged(app, event)
    I0 = app.V_ref_in;
    yNum = app.ySlider_3.Value;
    yPixelNum = round(abs(yNum-1)*(size(I0, 1)-1)+1);
    plot(app.HorizontalUIAxes_3, I0(yPixelNum,:), 'Color','r');
    delete(app.l6)
    yPixelNum = round((abs(yNum-1)*(size(I0, 1)-1)+1)*app.UIAxes_2.YLim(2)/size(I0, 1));
    app.l6 = line(app.UIAxes_2, [0 app.UIAxes_2.XLim(2)], [yPixelNum yPixelNum]);
    app.l6.Color = 'r';
        end

        % Value changed function: 
        % ImplementReferenceDatainthereconstructionSwitch
        function ImplementReferenceDatainthereconstructionSwitchValueChanged2(app, event)
            if strcmp(app.ImplementReferenceDatainthereconstructionSwitch.Value, 'On')
                app.UseRef = 1 ;
                if ~isempty(app.V_ref_in)
                    app.UseRef = 1 ;
                    app.Lamp.Color = [0.93,0.69,0.13] ;
                    app.Switch.Enable = 'On';
                    app.Switch_2.Enable = 'On';
                    app.Switch_3.Enable = 'On';
                    app.Switch_4.Enable = 'On';
                    app.Switch_5.Enable = 'On';
                    app.Switch_6.Enable = 'On';
                else
                    msg = cell(2,1);
                    msg{1} = sprintf('No data has been imported for the Reference Data!');
                    msg{2} = sprintf('First, use the Browse button to import the data.');
                    warndlg(msg,'Error');
                    app.ImplementReferenceDatainthereconstructionSwitch.Value = 'Off';
                end
            else
                app.UseRef = 0 ;
                app.Lamp.Color = [0.5 0.5 0.5] ;
                app.Switch.Enable = 'Off';
                app.Switch_2.Enable = 'Off';
                app.Switch_3.Enable = 'Off';
                app.Switch_4.Enable = 'Off';
                app.Switch_5.Enable = 'Off';
                app.Switch_6.Enable = 'Off';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp(app.Switch.Value, 'Main')
                V0 = app.V_crp;
                if size(V0,3)==1
                    app.FrameSpinner_4.Enable = 'Off';
                    app.FrameSpinner_4.Value  = 1;
                else
                    app.FrameSpinner_4.Enable = 'On';
                end
                I = imshow(V0(:,:,app.FrameSpinner_4.Value), [], 'Parent', app.UIAxes2_2, ...
                    'XData', [1 app.UIAxes2_2.Position(3)], ...
                    'YData', [1 app.UIAxes2_2.Position(4)]);
                app.UIAxes2_2.XLim = [0 I.XData(2)]; app.UIAxes2_2.YLim = [0 I.YData(2)];
            else
                app.FrameSpinner_4.Enable = 'Off';
                I = imshow(app.V_ref_crp, [], 'Parent', app.UIAxes2_2, ...
                    'XData', [1 app.UIAxes2_2.Position(3)], ...
                    'YData', [1 app.UIAxes2_2.Position(4)]);
                app.UIAxes2_2.XLim = [0 I.XData(2)]; app.UIAxes2_2.YLim = [0 I.YData(2)];
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            if strcmp(app.Switch_2.Value, 'Main')
                V0 = app.V_filtered;
                if size(V0,3)==1
                    app.FrameSpinner_5.Enable = 'Off';
                    app.FrameSpinner_5.Value  = 1;
                else
                    app.FrameSpinner_5.Enable = 'On';
                end
                I = imshow(V0(:,:,app.FrameSpinner_5.Value), [], 'Parent', app.UIAxes2_3, ...
                    'XData', [1 app.UIAxes2_3.Position(3)], ...
                    'YData', [1 app.UIAxes2_3.Position(4)]);
                app.UIAxes2_3.XLim = [0 I.XData(2)]; app.UIAxes2_3.YLim = [0 I.YData(2)];
            else
                app.FrameSpinner_5.Enable = 'Off';
                I = imshow(app.V_ref_filtered, [], 'Parent', app.UIAxes2_3, ...
                    'XData', [1 app.UIAxes2_3.Position(3)], ...
                    'YData', [1 app.UIAxes2_3.Position(4)]);
                app.UIAxes2_3.XLim = [0 I.XData(2)]; app.UIAxes2_3.YLim = [0 I.YData(2)];
            end
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            if strcmp(app.Switch_3.Value, 'Main')
                V0 = app.V_filtered;
                if size(V0,3)==1
                    app.FrameSpinner_6.Enable = 'Off';
                    app.FrameSpinner_6.Value  = 1;
                else
                    app.FrameSpinner_6.Enable = 'On';
                end
                I = imshow(V0(:,:,app.FrameSpinner_6.Value), [], 'Parent', app.UIAxes2_8, ...
                    'XData', [1 app.UIAxes2_8.Position(3)], 'YData', [1 app.UIAxes2_8.Position(4)]);
                app.UIAxes2_8.XLim = [0 I.XData(2)]; app.UIAxes2_8.YLim = [0 I.YData(2)];
            else
                app.FrameSpinner_6.Enable = 'Off';
                I = imshow(app.V_ref_filtered, [], 'Parent', app.UIAxes2_8, ...
                    'XData', [1 app.UIAxes2_8.Position(3)], 'YData', [1 app.UIAxes2_8.Position(4)]);
                app.UIAxes2_8.XLim = [0 I.XData(2)]; app.UIAxes2_8.YLim = [0 I.YData(2)];
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            
        end

        % Button pushed function: ProcessButton_3
        function ProcessButton_3Pushed(app, event)
            try
                app.drawMask = 0;
                app.Mask =[];
                V0 = app.WrappedPhase_filtered;
                selectedButton = app.MethodButtonGroup_2.SelectedObject;
                if strcmp(app.Switch_7.Value, 'A Mask per frame')
                    NoF = size(V0,3);
                else
                    NoF = 1;
                end
                switch selectedButton.Text
                    case 'Manual'
                        for i = 1: NoF
                            roiwindow = CROIEditor(V0(:,:,i));
                            waitfor(roiwindow,'roi');
                            if ~isvalid(roiwindow)
                                disp('you closed the window without applying a ROI, exiting...');
                                return
                            end
                            [M, ~, ~] = roiwindow.getROIData;
                            app.Mask(:,:,i) = M;
                        end
                    case 'Customized MATLAB Function'
                        [file,path] = uigetfile('*.m');
                        if (ischar(path))
                            selectedfile = fullfile(path,file);
                            addpath(path);
                            [~,name,~] = fileparts(selectedfile);
                            fh = str2func(name);
                            for i = 1: NoF
                                app.Mask(:,:,i) = fh(V0(:,:,i));
                            end
                        end
                end
                I = imshow(M(:,:,app.FrameSpinner_7.Value), [], 'Parent', app.UIAxes2_10, ...
                    'XData', [1 app.UIAxes2_10.Position(3)], 'YData', [1 app.UIAxes2_10.Position(4)]);
                app.UIAxes2_10.XLim = [0 I.XData(2)]; app.UIAxes2_10.YLim = [0 I.YData(2)];
                app.drawMask = 1;
            catch ME1
                warndlg(ME1.message,'Error');
                app.drawMask = 0;
            end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
    if strcmp(app.Switch_5.Value, 'Main')
        app.FrameSpinner_8.Enable = 'On';
        I = imshow(app.WrappedPhase_filtered(:,:,app.FrameSpinner_8.Value), [], 'Parent', app.UIAxes2_9, ...
            'XData', [1 app.UIAxes2_9.Position(3)], 'YData', [1 app.UIAxes2_9.Position(4)]);
        app.UIAxes2_9.XLim = [0 I.XData(2)]; app.UIAxes2_9.YLim = [0 I.YData(2)];
    else
        app.FrameSpinner_8.Enable = 'Off';
        I = imshow(app.WrappedPhase_ref_filtered, [], 'Parent', app.UIAxes2_9, ...
            'XData', [1 app.UIAxes2_9.Position(3)], 'YData', [1 app.UIAxes2_9.Position(4)]);
        app.UIAxes2_9.XLim = [0 I.XData(2)]; app.UIAxes2_9.YLim = [0 I.YData(2)];
    end
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
    if strcmp(app.Switch_6.Value, 'Main')
        app.FrameSpinner_9.Enable = 'On';
        I = imshow(app.UnwrappedPhase(:,:,app.FrameSpinner_8.Value), [], 'Parent', app.UIAxes2_11, ...
            'XData', [1 app.UIAxes2_11.Position(3)], 'YData', [1 app.UIAxes2_11.Position(4)]);
        app.UIAxes2_11.XLim = [0 I.XData(2)]; app.UIAxes2_11.YLim = [0 I.YData(2)];
    else
        app.FrameSpinner_9.Enable = 'Off';
        I = imshow(app.UnwrappedPhase_ref, [], 'Parent', app.UIAxes2_11, ...
            'XData', [1 app.UIAxes2_11.Position(3)], 'YData', [1 app.UIAxes2_11.Position(4)]);
        app.UIAxes2_11.XLim = [0 I.XData(2)]; app.UIAxes2_11.YLim = [0 I.YData(2)];
    end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
%% Import Data
            import mlreportgen.dom.*
            %Get the report data from the interface
            reportData = GetReportData(app);
            %Create new object of class Document, based on AdvancedReportTemplate
            doc = Document(['Report_', datestr(now,'mmmm,dd,yyyy-HH.MM.SS AM')], 'docx', 'ReportTemplateFinal');
            %Move to the first hole
            moveToNextHole(doc); rawData = reportData.Title;     processStandardHole(doc, rawData);
            moveToNextHole(doc); rawData = reportData.SubTitle;  processStandardHole(doc, rawData);
            moveToNextHole(doc); rawData = reportData.Author;    processStandardHole(doc, rawData);
            moveToNextHole(doc); rawData = reportData.directory; processStandardHole(doc, rawData);
            moveToNextHole(doc); rawData = reportData.properties;processStandardHole(doc, rawData);
            moveToNextHole(doc); rawData = reportData.selectedFrameNumber;   processStandardHole(doc, rawData);
            currentHoleId = moveToNextHole(doc);
            if app.UseRef ==1
                rawData1 = reportData.selectedImage;
                rawData2 = reportData.selectedImage_ref;
                process2Figures(doc, rawData1,rawData2, currentHoleId, 'Selected frame for the input', 'reference frame', '10cm', '7cm');
            else
                rawData = reportData.selectedImage;
                processFigure(doc, rawData, currentHoleId, 'Selected frame for the input', '10cm', '7cm');
            end
            %% Pre-Processing
            currentHoleId = moveToNextHole(doc);
            f1 = currentHoleId;
            rawData1 = reportData.croppedImage;
            processFigure(doc, rawData1, currentHoleId, 'Cropped image', '10cm', '7cm');
            moveToNextHole(doc); rawData = reportData.methodDCremoval; processStandardHole(doc, rawData);
            currentHoleId = moveToNextHole(doc);
            rawData2 = reportData.DCremoved;
            process4Figures(doc, rawData1,rawData2,abs(fftshift(fft2(rawData1))), abs(fftshift(fft2(rawData2))), currentHoleId, 'Cropped image', 'DC removed image', 'FFT for Cropped image', 'FFT for DC removed image', '10cm', '7cm');
            moveToNextHole(doc);
            val1 = app.AveragefilterCheckBox_2.Value;
            val2 = app.MedianfilterCheckBox_3.Value;
            val3 = app.AdaptiveLowpassWienerfilterCheckBox.Value;
            val4 = app.DeepneuralnetworkCheckBox.Value;
            val5 = app.CustomizedMATLABfunctionCheckBox.Value;
            tx = [];
            if val1 ==1
                tx =  [tx newline  'Average filter(size:',num2str(app.filtersizemnSpinner.Value), ', ', num2str(app.filtersizemnSpinner_2.Value), ')'];
            end
            if val2 ==1
                tx =  [tx newline 'Median filter(size:',num2str(app.filtersizemnSpinner_3.Value), ', ',num2str(app.filtersizemnSpinner_4.Value), ')'];
            end
            if val3 ==1
                tx =  [tx newline 'Adaptive lowpass Wiener filter(size:',num2str(app.filtersizemnSpinner_5.Value), ', ',num2str(app.filtersizemnSpinner_6.Value), ')'];
            end
            if val4 ==1
                tx =  [tx newline 'Deep neural network'];
            end
            if val5 ==1
                tx =  [tx newline 'Customized MATLAB function'];
            end
            if isempty(tx)
                tx = {'No denoising filter is applied'};
            else
                tx = splitlines(tx);
                tx = tx(~cellfun('isempty',tx));
            end
            table1 = Table(tx);
            append(doc,table1);
            moveToNextHole(doc);val = app.ContrastEnhancementButtonGroup.SelectedObject; processStandardHole(doc, val.Text);
            currentHoleId = moveToNextHole(doc);
            rawData1 = reportData.DCremoved;
            rawData2 = reportData.denoisedImage;
            process2Figures(doc, rawData1,rawData2, currentHoleId, 'DC removed frame', 'denoisede frame', '10cm', '7cm');
            %% Phase Extraction
            system = app.reportTitle;
            if isequal(system , 'Off-axis Interferometry')
                moveToNextHole(doc);
                AL = app.ReconstructionMethodButtonGroup.SelectedObject;
                switch AL.Text
                    case 'Discrete Fresnel Transformation'
                        tx = {'System: ', system;...
                    'Algorithm: ', AL.Text;...
                    'Algorithm Parameters:', '';...
                    'Camera pixel size (um) = ', num2str(app.CameraPixelSizeDHI.Value); ...
                    'Laser wavelength (nm) = ', num2str(app.LaserWavelengthnmDHI.Value); ...
                    'Reconstruction Zero Padding Size (pixel) = ', num2str(app.ZeroPaddedImageSizepixelDHI.Value); ...
                    'Filter radious (pixel) = ' num2str(app.FilterRadiusDHI.Value); ...
                    'Object Plane-Imaging Plane Distance (cm) = ',['from ', num2str(app.fromDHI.Value), ' , interval ', num2str(app.intervalDHI.Value), ' ,to', num2str(app.toDHI.Value)];...
                    'Wrapping Zero Padding Size (pixel) = ',num2str(app.ZeroPaddedImageSizepixel_DHI.Value); ...
                    'Window Size (Radius) = ', num2str(app.FilterRadiusDHI.Value); ...
                    'Frequency Filter Type = ', app.FrequencyFilterTypeDropDownDHI.Value; ...
                    'Frequency Filter Size = ', num2str(app.SmoothingFilterSize_DHI.Value); ...
                    };
                
                    case 'Angular Spectrum'
                        tx = {'System: ', system;...
                    'Algorithm: ', AL.Text;...
                    'Algorithm Parameters:', '';...
                    'Camera pixel size (um) = ', num2str(app.CameraPixelSizeumSpinnerAngular.Value); ...
                    'Laser wavelength (nm) = ', num2str(app.LaserWavelengthnmSpinnerAngular.Value); ...
                    'Reconstruction Zero Padding Size (pixel) = ', num2str(app.ZeroPaddingSizepixelSpinnerAngular.Value); ...
%                     'Filter radious (pixel) = ' num2str(app.FilterRadiusDHI.Value); ...
                    'Object Plane-Imaging Plane Distance (cm) = ',['from ', num2str(app.fromAngular.Value), ' , interval ', num2str(app.intervalAngular.Value), ' ,to', num2str(app.toAngular.Value)];...
                    'Wrapping Zero Padding Size (pixel) = ',num2str(app.ZeroPaddingSizepixelSpinnerAngular_Wrapping.Value); ...
                    'Window Size (Radius) = ', num2str(app.FilterRadiusSpinnerAngular.Value); ...
                    'Frequency Filter Type = ', app.FrequencyFilterTypeDropDown_Angular.Value; ...
                    'Frequency Filter Size = ', num2str(app.SmoothingFilterSizeFFT_Angular.Value); ...
                    };
                    case 'Convolution'
                        tx = {'System: ', system;...
                    'Algorithm: ', AL.Text;...
                    'Algorithm Parameters:', '';...
                    'Camera pixel size (um) = ', num2str(app.CameraPixelSizeConvolution.Value); ...
                    'Laser wavelength (nm) = ', num2str(app.LaserWavelengthnmConvolution.Value); ...
                    'Magnification = ', num2str(app.MagnificationDHI.Value); ...
                    'Reconstruction Zero Padding Size (pixel) = ', num2str(app.ZeroPaddedImageSizepixelConvolution.Value); ...
                    'Filter radious (pixel) = ' num2str(app.FilterRadiusConvolution.Value); ...
                    'Object Plane-Imaging Plane Distance (cm) = ',['from ', num2str(app.fromConv.Value), ' , interval ', num2str(app.intervalConv.Value), ' ,to', num2str(app.toConv.Value)];...
                    'Wrapping Zero Padding Size (pixel) = ',num2str(app.ZeroPaddedImageSizepixelConvolution_wrapping.Value); ...
                    'Window Size (Radius) = ', num2str(app.FilterRadiusSpinnerAngular.Value); ...
                    'Frequency Filter Type = ', app.FrequencyFilterTypeDropDown_Convolution.Value; ...
                    'Frequency Filter Size = ', num2str(app.SmoothingFilterSizeFFT_Convolution.Value); ...
                    };
                    case 'Customized MATLAB Function'
                        tx = [];
                        tx = splitlines(tx);
                        tx = tx(~cellfun('isempty',tx));
                end
                
                table1 = Table(tx);
                append(doc,table1);
                if app.UseRef ==1
                    currentHoleId = moveToNextHole(doc);
                    rawData1 = abs(app.RecImage(:,:,1));
                    rawData2 = app.WrappedPhase(:,:,1);
                    rawData3 = app.NoCarrierWrappedPhase(:,:,1);
                    rawData4 = abs(app.RecImage_ref);
                    rawData5 = app.WrappedPhase_ref;
                    rawData6 = app.NoCarrierWrappedPhase_ref;
                    process6Figures(doc, rawData1,rawData2,rawData3,rawData4,rawData5,rawData6, currentHoleId, 'Absolute value of the reconstructed image', 'Wrapped phase map containing carrier','Wrapped phase map without carrier','Absolute value of the reconstructed reference interferogram', 'Wrapped phase map containing carrier for reference interferogram','Wrapped phase map without carrier for reference interferogram', '6cm', '4cm');
                else
                    currentHoleId = moveToNextHole(doc);
                    rawData1 = abs(app.RecImage(:,:,1));
                    rawData2 = app.WrappedPhase(:,:,1);
                    rawData3 = app.NoCarrierWrappedPhase(:,:,1);
                    process3Figures(doc, rawData1,rawData2,rawData3, currentHoleId, 'Absolute value of the reconstructed image', 'Wrapped phase map containing carrier','Wrapped phase map without carrier', '6cm', '4cm');
                end
            elseif isequal(system , 'Fringe Profilometry')
                moveToNextHole(doc);
                AL = app.AlgorithmButtonGroup.SelectedObject;
                tx = {'System: ', system;...
                    'Algorithm: ', AL.Text;...
                    'Algorithm Parameters:', '';...
                    'Wrapping Zero Padding Size (pixel) = ', num2str(app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Value); ...
                    'Window Size (Radius) = ', num2str(app.FilterSizeProfilometryFPP.Value); ...
                    'Frequency Filter Type = ', app.FrequencyFilterTypeDropDown_FPP.Value; ...
                    'Frequency Filter Size = ', num2str(app.SmoothingFilterSizeFPP.Value)};
                table1 = Table(tx);
                append(doc,table1);
                if app.UseRef ==1
                    currentHoleId = moveToNextHole(doc);
                    rawData1 = app.WrappedPhase(:,:,1);
                    rawData2 = app.NoCarrierWrappedPhase(:,:,1);
                    rawData3 = app.WrappedPhase_ref;
                    rawData4 = app.NoCarrierWrappedPhase_ref;
                    process4Figures(doc, rawData1,rawData2,rawData3,rawData4,currentHoleId,  'Wrapped phase map containing carrier','Wrapped phase map without carrier', 'Wrapped phase map containing carrier for reference interferogram','Wrapped phase map without carrier for reference interferogram', '6cm', '4cm');
                else
                    currentHoleId = moveToNextHole(doc);
                    rawData1 = app.WrappedPhase(:,:,1);
                    rawData2 = app.NoCarrierWrappedPhase(:,:,1);
                    process2Figures(doc, rawData1,rawData2, currentHoleId, 'Wrapped phase map containing carrier','Wrapped phase map without carrier', '10cm', '7cm');
                end
            end
            %% Post-Processing
            moveToNextHole(doc);
            tx = {'Selected Phase Map = ', app.DataOptionsDropDown.Value};
            table1 = Table(tx);
            append(doc,table1);
            moveToNextHole(doc);
            val1 = app.GaussianlowpassfilterCheckBox.Value;
            val2 = app.MedianfilterCheckBox_2.Value;
            val3 = app.AdaptivelowpassWienerfilterCheckBox.Value;
            val4 = app.DeepneuralnetworkCheckBox_4.Value;
            val5 = app.CustomizedMATLABfunctionCheckBox_2.Value;
            tx = [];
            if val1 ==1
                tx =  [tx newline  'Average filter (size:',num2str(app.sizemnSpinner.Value), ', ',num2str(app.filtersizemnSpinner_8.Value), ')'];
            end
            if val2 ==1
                tx =  [tx newline 'Median filter (size:',num2str(app.sizemnSpinner_2.Value), ', ',num2str(app.filtersizemnSpinner_9.Value), ')'];
            end
            if val3 ==1
                tx =  [tx newline 'Adaptive Lowpass Wiener filter (size:',num2str(app.sizemnSpinner_3.Value), ', ',num2str(app.filtersizemnSpinner_11.Value), ')'];
            end
            if val4 ==1
                tx =  [tx newline 'Deep neural network'];
            end
            if val5 ==1
                tx =  [tx newline 'Customized MATLAB function'];
            end
            if isempty(tx)
                tx = {'No denoising filter is applied'};
            else
                tx = splitlines(tx);
                tx = tx(~cellfun('isempty',tx));
            end
            table1 = Table(tx);
            append(doc,table1);
            currentHoleId = moveToNextHole(doc);
            if app.UseRef ==1
                rawData1 = app.WrappedPhase_filtered(:,:,app.FrameSpinner_8.Value);
                rawData2 = app.WrappedPhase_ref_filtered;
                process2Figures(doc, rawData1,rawData2, currentHoleId, 'filtered wrapped phase', 'reference frame filtered wrapped phase', '10cm', '7cm');
            else
                rawData = app.WrappedPhase_filtered(:,:,app.FrameSpinner_8.Value);
                processFigure(doc, rawData, currentHoleId, 'filtered wrapped phase', '10cm', '7cm');
            end
            currentHoleId = moveToNextHole(doc);
            if app.drawMask ==1
            MaskMethod = app.MethodButtonGroup_2.SelectedObject;
            tx = {'Method = ', MaskMethod.Text; ...
                'Number of individual Masks = ', app.Switch_7.Value};
            else 
                tx = {'No Mask has been picked'};
            end
            table1 = Table(tx);
            append(doc,table1);
            currentHoleId = moveToNextHole(doc);
            if app.drawMask ==1
            rawData = app.Mask(:,:,1);
                processFigure(doc, rawData, currentHoleId, 'Mask: first frame', '10cm', '7cm');
            else 
                %show empty image
            end
            currentHoleId = moveToNextHole(doc);
            uwAL = app.UnwrappingAlgorithmButtonGroup.SelectedObject;
            if app.UseMasksCheckBox.Value
            tempAns = 'Yes';
            else
                tempAns = 'No';
            end
            tx = {'Unwrapping Algorithm = ', uwAL.Text; ...
                'Using Mask = ', tempAns};
            table1 = Table(tx);
            append(doc,table1);
            currentHoleId = moveToNextHole(doc);
            if app.UseRef ==1
                rawData1 = app.UnwrappedPhase(:,:,app.FrameSpinner_9.Value);
                rawData2 = app.UnwrappedPhase_ref;
                process2Figures(doc, rawData1,rawData2, currentHoleId, ' unwrapped phase', 'reference frame unwrapped phase', '10cm', '7cm');
            else
                rawData = app.UnwrappedPhase(:,:,app.FrameSpinner_9.Value);
                processFigure(doc, rawData, currentHoleId, 'unwrapped phase', '10cm', '7cm');
            end
            moveToNextHole(doc); 
            BGremovalAL = app.MethodButtonGroup.SelectedObject; 
            processStandardHole(doc, BGremovalAL.Text);
            currentHoleId = moveToNextHole(doc);
            rawData1 = app.UnwrappedPhase_noBG(:,:,app.FrameSpinner_10.Value);
            rawData2 = app.BackGround(:,:,1);
            process2Figures(doc, rawData1,rawData2, currentHoleId, ' unwrapped phase without background', 'background', '10cm', '7cm');
            moveToNextHole(doc);
            val1 = app.AveragefilterCheckBox.Value;
            val2 = app.MedianfilterCheckBox.Value;
            val3 = app.AdaptiveLowpassWienerFilterCheckBox_4.Value;
            val4 = app.DeepneuralnetworkCheckBox_3.Value;
            val5 = app.CustomizedMATLABfunctionCheckBox_3.Value;
            tx = [];
            if val1 ==1
                tx =  [tx newline  'Average Filter (size:',num2str(app.sizemnSpinner_4.Value),', ', num2str(app.filtersizemnSpinner_12.Value), ')'];
            end
            if val2 ==1
                tx =  [tx newline 'Median Filter (size:',num2str(app.sizemnSpinner_5.Value), ', ',num2str(app.filtersizemnSpinner_13.Value), ')'];
            end
            if val3 ==1
                tx =  [tx newline 'Adaptive Low-pass Wiener Filter (size:',num2str(app.sizemnSpinner_6.Value), ', ',num2str(app.filtersizemnSpinner_14.Value), ')'];
            end
            if val4 ==1
                tx =  [tx newline 'Deep neural network'];
            end
            if val5 ==1
                tx =  [tx newline 'Customized MATLAB function'];
            end
            if isempty(tx)
                tx = {'No denoising filter is applied'};
            else
                tx = splitlines(tx);
                tx = tx(~cellfun('isempty',tx));
            end
            table1 = Table(tx);
            append(doc,table1);
            currentHoleId = moveToNextHole(doc);
            rawData = app.UnwrappedPhase_noBG_filtered(:,:,1);
            processFigure(doc, rawData, currentHoleId, 'filtered unwrapped phase without background', '10cm', '7cm');
            close(doc);
            close all
            pattern = 'ReportImage';
            theFiles = dir(['*',pattern,'*']);
            for k = 1 : length(theFiles)
                baseFileName = theFiles(k).name;
                fullFileName = fullfile(cd, baseFileName);
                delete(fullFileName);
            end

            %% Process a Figure
            function processFigure(doc, rawData, currentHoleId, tl, w, h)
                %This function adds an image to our report
                import mlreportgen.dom.*
                I0 = rawData;
                clf; % Clear any prexisting figures.
                % Display square as a color-coded figure.
                ImageDisplay(I0)
                title(sprintf(tl))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
                % Convert the figure to a png image for inclusion
                % in the chapter.
                imagePath = sprintf([currentHoleId,'.png']);
                print(gcf, '-dpng', imagePath);
                img = Image(imagePath);
                img.Width  = w;
                img.Height = h;
                append(doc, img);
                delete(gcf);
            end
            %% Process 2 Figures Side-By-Side
            function process2Figures(doc, rawData1,rawData2, currentHoleId, tl1, tl2, w, h)
                %This function adds an image to our report
                import mlreportgen.dom.*
                I1 = rawData1;
                I2 = rawData2;
                clf; % Clear any prexisting figures.
                ImageDisplay(I1)
                title(sprintf(tl1))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
                imagePath = sprintf([currentHoleId,'1','.png']);
                print(gcf, '-dpng', imagePath);
                img1 = Image(imagePath);
                img1.Width  = w;
                img1.Height = h;
                ImageDisplay(I2)
                title(sprintf(tl2))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;

                imagePath = sprintf([currentHoleId,'2','.png']);
                print(gcf, '-dpng', imagePath);
                img2 = Image(imagePath);
                img2.Width  = w;
                img2.Height = h;

                images{1} = img1;
                images{2} = img2;
                t = Table(images);
                t.Border = 'none';
                t.TableEntriesInnerMargin = '5pt';
                append(doc, t);
                delete(gcf);
            end
            function process3Figures(doc, rawData1,rawData2,rawData3, currentHoleId, tl1, tl2,tl3, w, h)
                %This function adds an image to our report
                import mlreportgen.dom.*
                I1 = rawData1;
                I2 = rawData2;
                I3 = rawData3;
                clf; % Clear any prexisting figures.
                ImageDisplay(I1)
                title(sprintf(tl1))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
                imagePath = sprintf([currentHoleId,'1','.png']);
                print(gcf, '-dpng', imagePath);
                img1 = Image(imagePath);
                img1.Width  = w;
                img1.Height = h;
                ImageDisplay(I2)
                title(sprintf(tl2))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
     
                imagePath = sprintf([currentHoleId,'2','.png']);
                print(gcf, '-dpng', imagePath);
                img2 = Image(imagePath);
                img2.Width  = w;
                img2.Height = h;
                ImageDisplay(I3)
                title(sprintf(tl3))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;

                imagePath = sprintf([currentHoleId,'3','.png']);
                print(gcf, '-dpng', imagePath);
                img3 = Image(imagePath);
                img3.Width  = w;
                img3.Height = h;
       
                images{1} = img1;
                images{2} = img2;
                images{3} = img3;
                t = Table(images);
                t.Border = 'none';
                t.TableEntriesInnerMargin = '5pt';
                append(doc, t);
                delete(gcf);
            end
            %% Process 4 Figures
            function process4Figures(doc, rawData1,rawData2,rawData3,rawData4, currentHoleId, tl1, tl2,tl3,tl4, w, h)
                %This function adds an image to our report
                import mlreportgen.dom.*
                I1 = rawData1;
                I2 = rawData2;
                I3 = rawData3;
                I4 = rawData4;
                clf; % Clear any prexisting figures.
                ImageDisplay(I1)
                title(sprintf(tl1))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
                imagePath = sprintf([currentHoleId,'1','.png']);
                print(gcf, '-dpng', imagePath);
                img1 = Image(imagePath);
                img1.Width  = w;
                img1.Height = h;
                ImageDisplay(I2)
                title(sprintf(tl2))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
             
                imagePath = sprintf([currentHoleId,'2','.png']);
                print(gcf, '-dpng', imagePath);
                img2 = Image(imagePath);
                img2.Width  = w;
                img2.Height = h;
                ImageDisplay(I3)
                title(sprintf(tl3))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
   
                imagePath = sprintf([currentHoleId,'3','.png']);
                print(gcf, '-dpng', imagePath);
                img3 = Image(imagePath);
                img3.Width  = w;
                img3.Height = h;
                ImageDisplay(I4)
                title(sprintf(tl4))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;

                imagePath = sprintf([currentHoleId,'4','.png']);
                print(gcf, '-dpng', imagePath);
                img4 = Image(imagePath);
                img4.Width  = w;
                img4.Height = h;
  
                images{1,1} = img1;
                images{1,2} = img2;
                images{2,1} = img3;
                images{2,2} = img4;
                t = Table(images);
                t.Border = 'none';
                t.TableEntriesInnerMargin = '5pt';
                append(doc, t);
                delete(gcf);
            end
            %% Process 6 Figures
            function process6Figures(doc, rawData1,rawData2, rawData3,rawData4, rawData5,rawData6, currentHoleId, tl1, tl2,tl3,tl4, tl5, tl6, w, h)
                %This function adds an image to our report
                import mlreportgen.dom.*
                I1 = rawData1;
                I2 = rawData2;
                I3 = rawData3;
                I4 = rawData4;
                I5 = rawData5;
                I6 = rawData6;
                clf; % Clear any prexisting figures.
                ImageDisplay(I1)
                title(sprintf(tl1))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
                imagePath = sprintf([currentHoleId,'1','.png']);
                print(gcf, '-dpng', imagePath);
                img1 = Image(imagePath);
                img1.Width  = w;
                img1.Height = h;
                ImageDisplay(I2)
                title(sprintf(tl2))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
              
                imagePath = sprintf([currentHoleId,'2','.png']);
                print(gcf, '-dpng', imagePath);
                img2 = Image(imagePath);
                img2.Width  = w;
                img2.Height = h;
                ImageDisplay(I3)
                title(sprintf(tl3))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
  
                imagePath = sprintf([currentHoleId,'3','.png']);
                print(gcf, '-dpng', imagePath);
                img3 = Image(imagePath);
                img3.Width  = w;
                img3.Height = h;
                ImageDisplay(I4)
                title(sprintf(tl4))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;

                imagePath = sprintf([currentHoleId,'4','.png']);
                print(gcf, '-dpng', imagePath);
                img4 = Image(imagePath);
                img4.Width  = w;
                img4.Height = h;
                ImageDisplay(I5)
                title(sprintf(tl5))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
       
                imagePath = sprintf([currentHoleId,'5','.png']);
                print(gcf, '-dpng', imagePath);
                img5 = Image(imagePath);
                img5.Width  = w;
                img5.Height = h;
                ImageDisplay(I6)
                title(sprintf(tl6))
                set(gca,'Ydir','normal');
                axis equal;        axis tight;
       
                imagePath = sprintf([currentHoleId,'6','.png']);
                print(gcf, '-dpng', imagePath);
                img6 = Image(imagePath);
                img6.Width  = w;
                img6.Height = h;
             
                images{1,1} = img1;
                images{1,2} = img2;
                images{1,3} = img3;
                images{2,1} = img4;
                images{2,2} = img5;
                images{2,3} = img6;
                t = Table(images);
                t.Border = 'none';
                t.TableEntriesInnerMargin = '5pt';
                append(doc, t);
                delete(gcf);
            end
            function processSimpleTable(doc, reportData, holeId)
                %This function adds a simple table, which is based on the Word style
                %"_AR_Table" to our report
                import mlreportgen.dom.*
                
                %We add a new Level2 Heading before this table
                p = Paragraph('Simple Table Example', 'AR_Heading2');
                append(doc, p);
                %And some description
                p = Paragraph(['This is an example of a simple table. The table ' ...
                    'is based upon the Word table style "AR_Table".'], 'AR_Normal');
                append(doc, p);
                
                tableData = reportData.(holeId);
                %The Table class constructor accepts a cell array which contains the
                %data we want to display. The complete table is automatically build for
                %us, based on the input data and the table style AR_Table, which is
                %defined in the Word template
                table = Table( tableData, 'AR_Table');
                append(doc, table);
            end
            %% Process Advanced Table
            function processAdvancedTable(doc, reportData, holeId)
                %This function adds a table to our report, which is solely constructed
                %with DOM API commands
                import mlreportgen.dom.*
                %We add a new Level2 Heading before this table
                p = Paragraph('Complex Table Example', 'AR_Heading2');
                append(doc, p);
                %And some description
                p = Paragraph(['This is an example of an advanced table, which is ' ...
                    'completely created with the DOM API.'], 'AR_Normal');
                append(doc, p);
                tableData = reportData.(holeId);
                numCol = size(tableData,2);
                numRow = size(tableData,1);
                %The FormalTable class allows us to build a complete table from scratch.
                %We need to provide the number of columns in the constructor
                table = FormalTable( numCol );
                %The table shall span the page width, so we set the attribute to 100%
                table.Width = '100%';
                %Now we need to create a TableRow object for each row we want to add.
                %This loop is for adding a table header which shall be displayed in bold
                row = TableRow();
                for nc=1:numCol
                    %We create a Text for each colum in the header and make it bold
                    textObj = Text(sprintf('Row %d', nc));
                    textObj.Bold = true;
                    te = TableEntry( textObj );
                    %Then a TableEntry is added to the TableRow for each column
                    append(row, te );
                end
                %This row is appended to the table-header
                append(table.Header, row);
                
                %This loop fills the table body
                for nr=1:numRow
                    row = TableRow();
                    for nc=1:numCol
                        te = TableEntry( tableData{nr,nc} );
                        %The BackgroundColor of each TableEntry shall be filled with a
                        %random color.
                        bgColor = 'White';
                        te.Style  = { BackgroundColor(bgColor) };
                        te.VAlign = 'middle';
                        append(row, te );
                    end
                    append(table.Body, row);
                end
                append(doc, table);
            end
            %% Process Text Hole
            function processStandardHole(doc, rawData)
                import mlreportgen.dom.*
                %This function fills all holes that do not require a special handling.
                %The try/catch block performs an error handling in the case that there
                %is a mismatch between the hole-names in the template and the field-
                %names of the reportData-structure
                try
                    data = rawData;
                catch
                    warning('Undefined Hole-Id: %s', holeId);
                    data = 'undefined';
                end
                %Create a new text object and add it to the report
                t = Text(data);
                append(doc, t);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
    value = app.DropDown.Value;
    switch value
        case 'No DC Removal'
            app.filtersizeSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'DFT'
            app.filtersizeSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'On';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Average'
            app.filtersizeSpinner.Enable = 'On';
            app.alphaSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Median'
            app.filtersizeSpinner.Enable = 'On';
            app.alphaSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Laplacian'
            app.filtersizeSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'On';
            app.standarddeviationSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Log'
            app.filtersizeSpinner.Enable = 'On';
            app.alphaSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'On';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Prewitt'
            app.filtersizeSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Sobel'
            app.filtersizeSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Gaussian Spatial'
            app.filtersizeSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
        case 'Gaussian BPF'
            app.filtersizeSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'On';
            app.downcuttofffreqSpinner.Enable = 'On';
        case 'Butterworth BPF'
            app.filtersizeSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'On';
            app.downcuttofffreqSpinner.Enable = 'On';
        case 'Customized .m file'
            app.filtersizeSpinner.Enable = 'Off';
            app.standarddeviationSpinner.Enable = 'Off';
            app.alphaSpinner.Enable = 'Off';
            app.cutofffrequencySpinner.Enable = 'Off';
            app.upcuttofffreqSpinner.Enable = 'Off';
            app.downcuttofffreqSpinner.Enable = 'Off';
    end
        end

        % Button pushed function: BrowseFolderDirectoryButton
        function BrowseFolderDirectoryButtonPushed(app, event)
            f = app.PhaseWare;
            d = uiprogressdlg(f,'Title','Loading Reference Data...','Indeterminate','on');
            selpath = uigetdir;
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(selpath))
                cellArrayText = sprintf('%s', 'Selected Directory: ', selpath);
                app.PropertiesTextArea_4.Value = cellArrayText;
                app.SavePath = selpath;
            end
            app.PhaseWare.Visible = 'off';
            app.PhaseWare.Visible = 'on';
            % close the dialog box
            close(d);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            try
                value = app.ImageOptionsDropDown.Value;
                switch value
                    case 'Original Frame'
                        I0 = app.V_in;
                    case 'Reconstructed (Amplitude)'
                        I = app.RecImage;
                        I0 = abs(I).^2;
                    case 'Reconstructed (Phase)'
                        I = app.RecImage;
                        I0 = imag(I);
                    case 'Wrapped Phase'
                        I0 = app.WrappedPhase;
                    case 'Carrier Removed Wrapped Phase'
                        I0 = app.NoCarrierWrappedPhase;
                    case 'Unwrapped Phase'
                        I0 = app.UnwrappedPhase;
                    case 'Background'
                        I0 = app.BackGround;
                    case 'Background Removed Unwrapped Phase'
                        I0 = app.UnwrappedPhase_noBG;
                    case 'Background Removed Unwrapped Phase (Filtered)'
                        I0 = app.UnwrappedPhase_noBG_filtered;
                end
                S.(value(~isspace(value))) = I0;
                save (fullfile(app.SavePath, append(value(~isspace(value)), '.mat')), '-struct', 'S', '-v7.3', '-nocompression')
                message = sprintf('The file has been saved successfully.');
                uialert(app.PhaseWare,message,'Save', 'Icon','success');
            catch ME1
                warndlg(ME1.message,'Error');
            end
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            value = app.Switch_7.Value;
            
        end

        % Value changed function: 
        % RemovecarrierfrequencybyFouriermethodCheckBox
        function RemovecarrierfrequencybyFouriermethodCheckBoxValueChanged(app, event)
            value = app.RemovecarrierfrequencybyFouriermethodCheckBox.Value;
            switch value
                case 1
                    app.DataOptionsDropDown.Enable = 'On';
                    app.FilterRadiusDHI.Enable = 'On';
                    app.SmoothingFilterSize_DHI.Enable = 'On';
                    app.FrequencyFilterTypeDropDownDHI.Enable = 'On';
                    app.ZeroPaddedImageSizepixel_DHI.Enable = 'On';
                case 0
                    selectedButton2 = app.PhaseWrappingAlgorithmButtonGroup.SelectedObject;
                    switch selectedButton2.Text
                        case 'Fourier Transform (FT)'
                            app.FilterRadiusDHI.Enable = 'On';
                            app.SmoothingFilterSize_DHI.Enable = 'On';
                            app.FrequencyFilterTypeDropDownDHI.Enable = 'On';
                            app.ZeroPaddedImageSizepixel_DHI.Enable = 'On';
                        case {'Complex Data', 'Windowed Fourier Transform','Wavelet Transform (WT)','Hilbert Transform (HT)'}
                            app.FilterRadiusDHI.Enable = 'Off';
                            app.SmoothingFilterSize_DHI.Enable = 'Off';
                            app.FrequencyFilterTypeDropDownDHI.Enable = 'Off';
                            app.ZeroPaddedImageSizepixel_DHI.Enable = 'Off';
                    end
                    app.DataOptionsDropDown.Enable = 'Off';
                    app.DataOptionsDropDown.Value = 'Wrapped Phase';
            end
        end

        % Value changed function: 
        % RemovecarrierfrequencybyFouriermethodCheckBox_2
        function RemovecarrierfrequencybyFouriermethodCheckBox_2ValueChanged(app, event)
            selectedButton = app.AlgorithmButtonGroup.SelectedObject;
            value = app.RemovecarrierfrequencybyFouriermethodCheckBox_2.Value;
            switch value
                case 1
                    app.DataOptionsDropDown.Enable = 'On';
                    switch selectedButton.Text
                        case {'Fourier Transform (FT)','Windowed Fourier Transform', 'Wavelet Transform (WT)', 'Hilbert Transform (HT)'}
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'On';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'On';
                            app.FilterSizeProfilometryFPP.Enable = 'On';
                            app.SmoothingFilterSizeFPP.Enable = 'On';
                        case 'Customized MATLAB Function'
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'Off';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'Off';
                            app.FilterSizeProfilometryFPP.Enable = 'Off';
                            app.SmoothingFilterSizeFPP.Enable = 'Off';
                    end
                case 0
                    switch selectedButton.Text
                        case 'Fourier Transform (FT)'
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'On';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'On';
                            app.FilterSizeProfilometryFPP.Enable = 'On';
                            app.SmoothingFilterSizeFPP.Enable = 'On';
                        case {'Windowed Fourier Transform', 'Wavelet Transform (WT)', 'Hilbert Transform (HT)'}
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'Off';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'Off';
                            app.FilterSizeProfilometryFPP.Enable = 'Off';
                            app.SmoothingFilterSizeFPP.Enable = 'Off';
                    end
                    app.DataOptionsDropDown.Enable = 'Off';
                            app.DataOptionsDropDown.Value = 'Wrapped Phase';
            end
        end

        % Selection changed function: AlgorithmButtonGroup
        function AlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmButtonGroup.SelectedObject;
            value = app.RemovecarrierfrequencybyFouriermethodCheckBox_2.Value;
            app.DataOptionsDropDown.Enable = 'On';
            switch selectedButton.Text
                case 'Windowed Fourier Transform'
                    app.Panel_FPP.Visible = 'On';
                    switch value
                        case 1
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'On';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'On';
                            app.FilterSizeProfilometryFPP.Enable = 'On';
                            app.SmoothingFilterSizeFPP.Enable = 'On';
                        case 0
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'Off';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'Off';
                            app.FilterSizeProfilometryFPP.Enable = 'Off';
                            app.SmoothingFilterSizeFPP.Enable = 'Off';
                            app.DataOptionsDropDown.Enable = 'Off';
                            app.DataOptionsDropDown.Value = 'Wrapped Phase';
                    end
                    app.sigmaxSpinner_FPP.Enable = 'On';      app.wxlSpinner_FPP.Enable = 'On';
                    app.wxiSpinner_FPP.Enable = 'On';         app.wxhSpinner_FPP.Enable = 'On';
                    app.sigmaySpinner_FPP.Enable = 'On';      app.wylSpinner_FPP.Enable = 'On';
                    app.wyiSpinner_FPP.Enable = 'On';         app.wyhSpinner_FPP.Enable = 'On';
                    app.thr_FPP.Enable = 'On';                app.AlgorithmOption_FPP.Visible = 'On';
                case 'Fourier Transform (FT)'
                    app.Panel_FPP.Visible = 'On';
                    app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'On';
                    app.FrequencyFilterTypeDropDown_FPP.Enable = 'On';
                    app.FilterSizeProfilometryFPP.Enable = 'On';
                    app.SmoothingFilterSizeFPP.Enable = 'On';
%                     app.sigmaxSpinner_FPP.Enable = 'Off';      app.wxlSpinner_FPP.Enable = 'Off';
%                     app.wxiSpinner_FPP.Enable = 'Off';         app.wxhSpinner_FPP.Enable = 'Off';
%                     app.sigmaySpinner_FPP.Enable = 'Off';      app.wylSpinner_FPP.Enable = 'Off';
%                     app.wyiSpinner_FPP.Enable = 'Off';         app.wyhSpinner_FPP.Enable = 'Off';
%                     app.thr_FPP.Enable = 'Off';                app.AlgorithmOption_FPP.Visible = 'Off';
                    switch value
                        case 0
                            app.DataOptionsDropDown.Enable = 'Off';
                            app.DataOptionsDropDown.Value = 'Wrapped Phase';
                    end
                case { 'Wavelet Transform (WT)', 'Hilbert Transform (HT)'}
                    app.Panel_FPP.Visible = 'On';
                    switch value
                        case 1
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'On';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'On';
                            app.FilterSizeProfilometryFPP.Enable = 'On';
                            app.SmoothingFilterSizeFPP.Enable = 'On';
                        case 0
                            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Enable = 'Off';
                            app.FrequencyFilterTypeDropDown_FPP.Enable = 'Off';
                            app.FilterSizeProfilometryFPP.Enable = 'Off';
                            app.SmoothingFilterSizeFPP.Enable = 'Off';
                            app.DataOptionsDropDown.Enable = 'Off';
                            app.DataOptionsDropDown.Value = 'Wrapped Phase';
                    end
%                     app.sigmaxSpinner_FPP.Enable = 'Off';      app.wxlSpinner_FPP.Enable = 'Off';
%                     app.wxiSpinner_FPP.Enable = 'Off';         app.wxhSpinner_FPP.Enable = 'Off';
%                     app.sigmaySpinner_FPP.Enable = 'Off';      app.wylSpinner_FPP.Enable = 'Off';
%                     app.wyiSpinner_FPP.Enable = 'Off';         app.wyhSpinner_FPP.Enable = 'Off';
%                     app.thr_FPP.Enable = 'Off';                app.AlgorithmOption_FPP.Visible = 'Off';
                case 'Customized MATLAB Function'
                    app.Panel_FPP.Visible = 'Off';
                   
            end
        end

        % Selection changed function: 
        % PhaseWrappingAlgorithmButtonGroup
        function PhaseWrappingAlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.PhaseWrappingAlgorithmButtonGroup.SelectedObject;
            value = app.RemovecarrierfrequencybyFouriermethodCheckBox.Value;
            app.DataOptionsDropDown.Enable = 'On';
            switch selectedButton.Text
                case 'Windowed Fourier Transform'
                    app.Panel_DHI_Wrapping.Visible = 'On';
                    switch value
                        case 1
                            app.ZeroPaddedImageSizepixel_DHI.Enable = 'On';
                            app.FrequencyFilterTypeDropDownDHI.Enable = 'On';
                            app.FilterRadiusDHI.Enable = 'On';
                            app.SmoothingFilterSize_DHI.Enable = 'On';
                        case 0
                            app.ZeroPaddedImageSizepixel_DHI.Enable = 'Off';
                            app.FrequencyFilterTypeDropDownDHI.Enable = 'Off';
                            app.FilterRadiusDHI.Enable = 'Off';
                            app.SmoothingFilterSize_DHI.Enable = 'Off';
                            app.DataOptionsDropDown.Enable = 'Off';
                            app.DataOptionsDropDown.Value = 'Wrapped Phase';
                    end
                    app.sigmaxSpinner_DHI.Enable = 'On';      app.wxlSpinner_DHI.Enable = 'On';
                    app.wxiSpinner_DHI.Enable = 'On';         app.wxhSpinner_DHI.Enable = 'On';
                    app.sigmaySpinner_DHI.Enable = 'On';      app.wylSpinner_DHI.Enable = 'On';
                    app.wyiSpinner_DHI.Enable = 'On';         app.wyhSpinner_DHI.Enable = 'On';
                    app.thr_DHI.Enable = 'On';                app.AlgorithmOption_DHI.Visible = 'On';
                case 'Fourier Transform (FT)'
                    app.ZeroPaddedImageSizepixel_DHI.Enable = 'On';
                    app.FrequencyFilterTypeDropDownDHI.Enable = 'On';
                    app.FilterRadiusDHI.Enable = 'On';
                    app.SmoothingFilterSize_DHI.Enable = 'On';
%                     app.sigmaxSpinner_DHI.Enable = 'Off';      app.wxlSpinner_DHI.Enable = 'Off';
%                     app.wxiSpinner_DHI.Enable = 'Off';         app.wxhSpinner_DHI.Enable = 'Off';
%                     app.sigmaySpinner_DHI.Enable = 'Off';      app.wylSpinner_DHI.Enable = 'Off';
%                     app.wyiSpinner_DHI.Enable = 'Off';         app.wyhSpinner_DHI.Enable = 'Off';
%                     app.thr_DHI.Enable = 'Off';                app.AlgorithmOption_DHI.Visible = 'Off';
                    switch value
                        case 0
                            app.DataOptionsDropDown.Enable = 'Off';
                            app.DataOptionsDropDown.Value = 'Wrapped Phase';
                    end
                case { 'Complex Data','Wavelet Transform (WT)', 'Hilbert Transform (HT)'}
                    app.Panel_DHI_Wrapping.Visible = 'On';
                    switch value
                        case 1
                            app.ZeroPaddedImageSizepixel_DHI.Enable = 'On';
                            app.FrequencyFilterTypeDropDownDHI.Enable = 'On';
                            app.FilterRadiusDHI.Enable = 'On';
                            app.SmoothingFilterSize_DHI.Enable = 'On';
                        case 0
                            app.ZeroPaddedImageSizepixel_DHI.Enable = 'Off';
                            app.FrequencyFilterTypeDropDownDHI.Enable = 'Off';
                            app.FilterRadiusDHI.Enable = 'Off';
                            app.SmoothingFilterSize_DHI.Enable = 'Off';
                            app.DataOptionsDropDown.Enable = 'Off';
                            app.DataOptionsDropDown.Value = 'Wrapped Phase';
                    end
%                     app.sigmaxSpinner_DHI.Enable = 'Off';      app.wxlSpinner_DHI.Enable = 'Off';
%                     app.wxiSpinner_DHI.Enable = 'Off';         app.wxhSpinner_DHI.Enable = 'Off';
%                     app.sigmaySpinner_DHI.Enable = 'Off';      app.wylSpinner_DHI.Enable = 'Off';
%                     app.wyiSpinner_DHI.Enable = 'Off';         app.wyhSpinner_DHI.Enable = 'Off';
%                     app.thr_DHI.Enable = 'Off';                app.AlgorithmOption_DHI.Visible = 'Off';
                case 'Customized MATLAB Function'
                    app.Panel_DHI_Wrapping.Visible = 'Off';
                   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhaseWare = uifigure('Visible', 'off');
            app.PhaseWare.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.PhaseWare);
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Title = 'Import Data';
            app.TabGroup3 = uitabgroup(app.ImportDataTab);
            app.MainDataTab = uitab(app.TabGroup3);
            app.MainDataTab.Title = 'Main Data';
            app.BrowseButton = uibutton(app.MainDataTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse ...';
            app.UIAxes = uiaxes(app.MainDataTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ySliderLabel = uilabel(app.MainDataTab);
            app.ySliderLabel.Text = {'y'; ''};
            app.ySlider = uislider(app.MainDataTab);
            app.ySlider.Limits = [0 1];
            app.ySlider.MajorTicks = [];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.MinorTicks = [0 0.025 0.05 0.075 0.1 0.125 0.15 0.175 0.2 0.225 0.25 0.275 0.3 0.325 0.35 0.375 0.4 0.425 0.45 0.475 0.5 0.525 0.55 0.575 0.6 0.625 0.65 0.675 0.7 0.725 0.75 0.775 0.8 0.825 0.85 0.875 0.9 0.925 0.95 0.975 1];
            app.ySlider.Value = 0.5;
            app.VerticalUIAxes = uiaxes(app.MainDataTab);
            title(app.VerticalUIAxes, 'Vertical Line-Profile')
            xlabel(app.VerticalUIAxes, 'Pixels ')
            ylabel(app.VerticalUIAxes, 'Intensity (a.u.)')
            app.HorizontalUIAxes = uiaxes(app.MainDataTab);
            title(app.HorizontalUIAxes, 'Horizontal Line-Profile')
            xlabel(app.HorizontalUIAxes, 'Pixels ')
            ylabel(app.HorizontalUIAxes, 'Intensity (a.u.)')
            app.HistogramUi = uiaxes(app.MainDataTab);
            title(app.HistogramUi, 'Histogram')
            xlabel(app.HistogramUi, 'Intensity Value')
            ylabel(app.HistogramUi, 'Number of Pixels')
            app.Panel_4 = uipanel(app.MainDataTab);
            app.FrameSpinner_12Label = uilabel(app.Panel_4);
            app.FrameSpinner_12Label.Text = 'Frame';
            app.FrameSpinner = uispinner(app.Panel_4);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 2;
            app.FrameSpinner_13Label = uilabel(app.Panel_4);
            app.FrameSpinner_13Label.Text = 'Frame';
            app.FrameSpinner_2 = uispinner(app.Panel_4);
            app.FrameSpinner_2.Limits = [1 Inf];
            app.FrameSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner_2.Value = 1;
            app.DropDown_2 = uidropdown(app.Panel_4);
            app.DropDown_2.Items = {'-', '+', '*', '/'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.DropDown_2.Value = '-';
            app.Spinner = uispinner(app.Panel_4);
            app.Spinner.Step = 0.5;
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Spinner_2 = uispinner(app.Panel_4);
            app.Spinner_2.Step = 0.5;
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.Spinner_2.Value = 1;
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '*';
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '*';
            app.Panel_5 = uipanel(app.MainDataTab);
            app.FrameSpinner_14Label = uilabel(app.Panel_5);
            app.FrameSpinner_14Label.Text = 'Frame';
            app.FrameSpinner_3 = uispinner(app.Panel_5);
            app.FrameSpinner_3.Limits = [1 Inf];
            app.FrameSpinner_3.ValueChangedFcn = createCallbackFcn(app, @FrameSpinner_3ValueChanged, true);
            app.FrameSpinner_3.Value = 1;
            app.ButtonGroup = uibuttongroup(app.MainDataTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SingleframeButton = uitogglebutton(app.ButtonGroup);
            app.SingleframeButton.Text = 'Single frame';
            app.SingleframeButton.Value = true;
            app.Combinationof2framesButton = uitogglebutton(app.ButtonGroup);
            app.Combinationof2framesButton.Text = 'Combination of 2 frames';
            app.PropertiesTextAreaLabel = uilabel(app.MainDataTab);
            app.PropertiesTextAreaLabel.Text = 'Properties:';
            app.PropertiesTextArea = uitextarea(app.MainDataTab);
            app.xSlider_3Label = uilabel(app.MainDataTab);
            app.xSlider_3Label.Text = {'x'; ''};
            app.xSlider = uislider(app.MainDataTab);
            app.xSlider.Limits = [0 1];
            app.xSlider.MajorTicks = [];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.Value = 0.5;
            app.ReferenceDataTab = uitab(app.TabGroup3);
            app.ReferenceDataTab.Title = 'Reference Data';
            app.UIAxes_2 = uiaxes(app.ReferenceDataTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.PropertiesTextArea_2 = uitextarea(app.ReferenceDataTab);
            app.BrowseButton_2 = uibutton(app.ReferenceDataTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse ...';
            app.PropertiesTextAreaLabel_2 = uilabel(app.ReferenceDataTab);
            app.PropertiesTextAreaLabel_2.Text = 'Properties:';
            app.Lamp = uilamp(app.ReferenceDataTab);
            app.ImplementReferenceDatainthereconstructionSwitchLabel = uilabel(app.ReferenceDataTab);
            app.ImplementReferenceDatainthereconstructionSwitchLabel.Text = 'Implement Reference Data in the reconstruction:';
            app.ImplementReferenceDatainthereconstructionSwitch = uiswitch(app.ReferenceDataTab, 'slider');
            app.ImplementReferenceDatainthereconstructionSwitch.ValueChangedFcn = createCallbackFcn(app, @ImplementReferenceDatainthereconstructionSwitchValueChanged2, true);
            app.ySliderLabel_2 = uilabel(app.ReferenceDataTab);
            app.ySliderLabel_2.Text = {'y'; ''};
            app.ySlider_3 = uislider(app.ReferenceDataTab);
            app.ySlider_3.Limits = [0 1];
            app.ySlider_3.MajorTicks = [];
            app.ySlider_3.MajorTickLabels = {''};
            app.ySlider_3.ValueChangedFcn = createCallbackFcn(app, @ySlider_3ValueChanged, true);
            app.ySlider_3.MinorTicks = [0 0.025 0.05 0.075 0.1 0.125 0.15 0.175 0.2 0.225 0.25 0.275 0.3 0.325 0.35 0.375 0.4 0.425 0.45 0.475 0.5 0.525 0.55 0.575 0.6 0.625 0.65 0.675 0.7 0.725 0.75 0.775 0.8 0.825 0.85 0.875 0.9 0.925 0.95 0.975 1];
            app.ySlider_3.Value = 0.5;
            app.VerticalUIAxes_3 = uiaxes(app.ReferenceDataTab);
            title(app.VerticalUIAxes_3, 'Vertical Line-Profile')
            xlabel(app.VerticalUIAxes_3, 'Pixels ')
            ylabel(app.VerticalUIAxes_3, 'Intensity (a.u.)')
            app.HorizontalUIAxes_3 = uiaxes(app.ReferenceDataTab);
            title(app.HorizontalUIAxes_3, 'Horizontal Line-Profile')
            xlabel(app.HorizontalUIAxes_3, 'Pixels ')
            ylabel(app.HorizontalUIAxes_3, 'Intensity (a.u.)')
            app.HistogramUi_3 = uiaxes(app.ReferenceDataTab);
            title(app.HistogramUi_3, 'Histogram')
            xlabel(app.HistogramUi_3, 'Intensity Value')
            ylabel(app.HistogramUi_3, 'Number of Pixels')
            app.xSlider_3Label_2 = uilabel(app.ReferenceDataTab);
            app.xSlider_3Label_2.Text = {'x'; ''};
            app.xSlider_3 = uislider(app.ReferenceDataTab);
            app.xSlider_3.Limits = [0 1];
            app.xSlider_3.MajorTicks = [];
            app.xSlider_3.ValueChangedFcn = createCallbackFcn(app, @xSlider_3ValueChanged, true);
            app.xSlider_3.Value = 0.5;
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.CropSizeButtonGroup = uibuttongroup(app.PreProcessingTab);
            app.CropSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CropSizeButtonGroupSelectionChanged, true);
            app.CropSizeButtonGroup.Title = '1-Cropping Tool';
            app.NoCropButton = uiradiobutton(app.CropSizeButtonGroup);
            app.NoCropButton.Text = 'No Crop';
            app.NoCropButton.Value = true;
            app.ManualSquareButton = uiradiobutton(app.CropSizeButtonGroup);
            app.ManualSquareButton.Text = 'Manual Square';
            app.SquareButton = uiradiobutton(app.CropSizeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.CircleButton = uiradiobutton(app.CropSizeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.DimensionSpinnerLabel = uilabel(app.CropSizeButtonGroup);
            app.DimensionSpinnerLabel.Enable = 'off';
            app.DimensionSpinnerLabel.Text = 'Dimension';
            app.DimensionSpinner = uispinner(app.CropSizeButtonGroup);
            app.DimensionSpinner.Step = 256;
            app.DimensionSpinner.Limits = [1 Inf];
            app.DimensionSpinner.Enable = 'off';
            app.DimensionSpinner.Value = 1024;
            app.DiameterSpinnerLabel = uilabel(app.CropSizeButtonGroup);
            app.DiameterSpinnerLabel.Enable = 'off';
            app.DiameterSpinnerLabel.Text = 'Diameter';
            app.DiameterSpinner = uispinner(app.CropSizeButtonGroup);
            app.DiameterSpinner.Step = 256;
            app.DiameterSpinner.Limits = [1 Inf];
            app.DiameterSpinner.Enable = 'off';
            app.DiameterSpinner.Value = 1024;
            app.Panel_3 = uipanel(app.CropSizeButtonGroup);
            app.NumberofframesSpinnerLabel = uilabel(app.Panel_3);
            app.NumberofframesSpinnerLabel.Text = 'Number of frames:';
            app.NumberOfFramesEditField = uispinner(app.Panel_3);
            app.NumberOfFramesEditField.Limits = [1 Inf];
            app.NumberOfFramesEditField.Value = 1;
            app.FirstframeSpinnerLabel = uilabel(app.Panel_3);
            app.FirstframeSpinnerLabel.Text = 'First frame:';
            app.FirstframeEditField = uispinner(app.Panel_3);
            app.FirstframeEditField.Limits = [1 Inf];
            app.FirstframeEditField.Value = 1;
            app.IntervalSpinnerLabel = uilabel(app.Panel_3);
            app.IntervalSpinnerLabel.Text = 'Interval:';
            app.IntervalEditField = uispinner(app.Panel_3);
            app.IntervalEditField.Limits = [1 Inf];
            app.IntervalEditField.Value = 1;
            app.UIAxes2_2 = uiaxes(app.CropSizeButtonGroup);
            title(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.CropButton = uibutton(app.CropSizeButtonGroup, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.FrameSpinner_4Label = uilabel(app.CropSizeButtonGroup);
            app.FrameSpinner_4Label.Text = 'Frame';
            app.FrameSpinner_4 = uispinner(app.CropSizeButtonGroup);
            app.FrameSpinner_4.Limits = [1 Inf];
            app.FrameSpinner_4.Value = 1;
            app.Switch = uiswitch(app.CropSizeButtonGroup, 'toggle');
            app.Switch.Items = {'Main', 'Reference'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = 'Main';
            app.PropertiesTextArea_3 = uitextarea(app.CropSizeButtonGroup);
            app.DCOffsetRemovalPanel = uipanel(app.PreProcessingTab);
            app.DCOffsetRemovalPanel.Title = '2-DC Offset Removal';
            app.filtersizeSpinner_3Label = uilabel(app.DCOffsetRemovalPanel);
            app.filtersizeSpinner_3Label.Enable = 'off';
            app.filtersizeSpinner_3Label.Text = 'filter size:';
            app.filtersizeSpinner = uispinner(app.DCOffsetRemovalPanel);
            app.filtersizeSpinner.Enable = 'off';
            app.filtersizeSpinner.Value = 3;
            app.alphaSpinner_3Label = uilabel(app.DCOffsetRemovalPanel);
            app.alphaSpinner_3Label.Enable = 'off';
            app.alphaSpinner_3Label.Text = 'alpha:';
            app.alphaSpinner = uispinner(app.DCOffsetRemovalPanel);
            app.alphaSpinner.Step = 0.05;
            app.alphaSpinner.Limits = [0 1];
            app.alphaSpinner.Enable = 'off';
            app.alphaSpinner.Value = 0.2;
            app.standarddeviationSpinner_3Label = uilabel(app.DCOffsetRemovalPanel);
            app.standarddeviationSpinner_3Label.Enable = 'off';
            app.standarddeviationSpinner_3Label.Text = 'standard deviation:';
            app.standarddeviationSpinner = uispinner(app.DCOffsetRemovalPanel);
            app.standarddeviationSpinner.Step = 0.1;
            app.standarddeviationSpinner.Limits = [0 10];
            app.standarddeviationSpinner.Enable = 'off';
            app.standarddeviationSpinner.Value = 0.5;
            app.cutofffrequencySpinner_3Label = uilabel(app.DCOffsetRemovalPanel);
            app.cutofffrequencySpinner_3Label.Enable = 'off';
            app.cutofffrequencySpinner_3Label.Text = 'cut-off frequency:';
            app.cutofffrequencySpinner = uispinner(app.DCOffsetRemovalPanel);
            app.cutofffrequencySpinner.Step = 0.1;
            app.cutofffrequencySpinner.Limits = [0 10];
            app.cutofffrequencySpinner.Enable = 'off';
            app.cutofffrequencySpinner.Value = 0.5;
            app.upcuttofffreqSpinnerLabel = uilabel(app.DCOffsetRemovalPanel);
            app.upcuttofffreqSpinnerLabel.Enable = 'off';
            app.upcuttofffreqSpinnerLabel.Text = 'up cutt-off freq';
            app.upcuttofffreqSpinner = uispinner(app.DCOffsetRemovalPanel);
            app.upcuttofffreqSpinner.Enable = 'off';
            app.upcuttofffreqSpinner.Value = 100;
            app.downcuttofffreqSpinnerLabel = uilabel(app.DCOffsetRemovalPanel);
            app.downcuttofffreqSpinnerLabel.Enable = 'off';
            app.downcuttofffreqSpinnerLabel.Text = 'down cutt-off freq';
            app.downcuttofffreqSpinner = uispinner(app.DCOffsetRemovalPanel);
            app.downcuttofffreqSpinner.Enable = 'off';
            app.downcuttofffreqSpinner.Value = 30;
            app.RemoveDCButton_3 = uibutton(app.DCOffsetRemovalPanel, 'push');
            app.RemoveDCButton_3.ButtonPushedFcn = createCallbackFcn(app, @RemoveDCButtonPushed, true);
            app.RemoveDCButton_3.Text = 'Remove DC';
            app.UIAxes2_3 = uiaxes(app.DCOffsetRemovalPanel);
            title(app.UIAxes2_3, '')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.FrameSpinner_5Label = uilabel(app.DCOffsetRemovalPanel);
            app.FrameSpinner_5Label.Text = 'Frame';
            app.FrameSpinner_5 = uispinner(app.DCOffsetRemovalPanel);
            app.FrameSpinner_5.Limits = [1 Inf];
            app.FrameSpinner_5.Value = 1;
            app.Switch_2 = uiswitch(app.DCOffsetRemovalPanel, 'toggle');
            app.Switch_2.Items = {'Main', 'Reference'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Enable = 'off';
            app.Switch_2.Value = 'Main';
            app.DropDown = uidropdown(app.DCOffsetRemovalPanel);
            app.DropDown.Items = {'No DC Removal', 'DFT', 'Median', 'Average', 'Laplacian', 'Log', 'Prewitt', 'Sobel', 'Gaussian Spatial', 'Gaussian BPF', 'Butterworth BPF', 'Customized MATLAB Function'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Value = 'Gaussian Spatial';
            app.DataFilteringPanel = uipanel(app.PreProcessingTab);
            app.DataFilteringPanel.Title = '3-Data Filtering';
            app.FilterButton_2 = uibutton(app.DataFilteringPanel, 'push');
            app.FilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilterButton_2Pushed, true);
            app.FilterButton_2.Text = 'Filter';
            app.UIAxes2_8 = uiaxes(app.DataFilteringPanel);
            title(app.UIAxes2_8, '')
            app.UIAxes2_8.XTick = [];
            app.UIAxes2_8.YTick = [];
            app.DenoisingPanel = uipanel(app.DataFilteringPanel);
            app.DenoisingPanel.Title = 'Denoising';
            app.AveragefilterCheckBox_2 = uicheckbox(app.DenoisingPanel);
            app.AveragefilterCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AveragefilterCheckBox_2ValueChanged, true);
            app.AveragefilterCheckBox_2.Text = 'Average filter';
            app.MedianfilterCheckBox_3 = uicheckbox(app.DenoisingPanel);
            app.MedianfilterCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @MedianfilterCheckBox_3ValueChanged, true);
            app.MedianfilterCheckBox_3.Text = 'Median filter';
            app.AdaptiveLowpassWienerfilterCheckBox = uicheckbox(app.DenoisingPanel);
            app.AdaptiveLowpassWienerfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdaptiveLowpassWienerfilterCheckBoxValueChanged, true);
            app.AdaptiveLowpassWienerfilterCheckBox.Text = 'Adaptive Lowpass Wiener filter';
            app.DeepneuralnetworkCheckBox = uicheckbox(app.DenoisingPanel);
            app.DeepneuralnetworkCheckBox.Tooltip = {'Function: denoiseImage'; ''; 'This function uses:'; 'Image Processing Toolbox'; 'Deep Learning Toolbox'};
            app.DeepneuralnetworkCheckBox.Text = 'Deep neural network';
            app.filtersizemnLabel = uilabel(app.DenoisingPanel);
            app.filtersizemnLabel.Enable = 'off';
            app.filtersizemnLabel.Text = 'filter size [m n]:';
            app.filtersizemnSpinner = uispinner(app.DenoisingPanel);
            app.filtersizemnSpinner.Enable = 'off';
            app.filtersizemnSpinner.Value = 3;
            app.filtersizemnSpinner_2 = uispinner(app.DenoisingPanel);
            app.filtersizemnSpinner_2.Enable = 'off';
            app.filtersizemnSpinner_2.Value = 3;
            app.filtersizemnSpinner_4 = uispinner(app.DenoisingPanel);
            app.filtersizemnSpinner_4.Enable = 'off';
            app.filtersizemnSpinner_4.Value = 3;
            app.filtersizemnSpinner_4Label = uilabel(app.DenoisingPanel);
            app.filtersizemnSpinner_4Label.Enable = 'off';
            app.filtersizemnSpinner_4Label.Text = 'filter size [m n]:';
            app.filtersizemnSpinner_3 = uispinner(app.DenoisingPanel);
            app.filtersizemnSpinner_3.Enable = 'off';
            app.filtersizemnSpinner_3.Value = 3;
            app.filtersizemnSpinner_6 = uispinner(app.DenoisingPanel);
            app.filtersizemnSpinner_6.Enable = 'off';
            app.filtersizemnSpinner_6.Value = 3;
            app.filtersizemnSpinner_6Label = uilabel(app.DenoisingPanel);
            app.filtersizemnSpinner_6Label.Enable = 'off';
            app.filtersizemnSpinner_6Label.Text = 'filter size [m n]:';
            app.filtersizemnSpinner_5 = uispinner(app.DenoisingPanel);
            app.filtersizemnSpinner_5.Enable = 'off';
            app.filtersizemnSpinner_5.Value = 3;
            app.CustomizedMATLABfunctionCheckBox = uicheckbox(app.DenoisingPanel);
            app.CustomizedMATLABfunctionCheckBox.Tooltip = {'Function: denoiseImage'; ''; 'This function uses:'; 'Image Processing Toolbox'; 'Deep Learning Toolbox'};
            app.CustomizedMATLABfunctionCheckBox.Text = 'Customized MATLAB function';
            app.ContrastEnhancementButtonGroup = uibuttongroup(app.DataFilteringPanel);
            app.ContrastEnhancementButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ContrastEnhancementButtonGroupSelectionChanged, true);
            app.ContrastEnhancementButtonGroup.Title = 'Contrast Enhancement';
            app.OriginalButton = uiradiobutton(app.ContrastEnhancementButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.WindowNormalizationButton = uiradiobutton(app.ContrastEnhancementButtonGroup);
            app.WindowNormalizationButton.Text = 'Window Normalization';
            app.windowsizemnSpinner_2 = uispinner(app.ContrastEnhancementButtonGroup);
            app.windowsizemnSpinner_2.Enable = 'off';
            app.windowsizemnSpinner_2.Value = 3;
            app.windowsizemnSpinnerLabel = uilabel(app.ContrastEnhancementButtonGroup);
            app.windowsizemnSpinnerLabel.Enable = 'off';
            app.windowsizemnSpinnerLabel.Text = 'window size [m n]:';
            app.windowsizemnSpinner = uispinner(app.ContrastEnhancementButtonGroup);
            app.windowsizemnSpinner.Enable = 'off';
            app.windowsizemnSpinner.Value = 3;
            app.CustomizedMATLABfunctionButton = uiradiobutton(app.ContrastEnhancementButtonGroup);
            app.CustomizedMATLABfunctionButton.Text = 'Customized MATLAB function';
            app.FrameSpinner_6Label = uilabel(app.DataFilteringPanel);
            app.FrameSpinner_6Label.Text = 'Frame';
            app.FrameSpinner_6 = uispinner(app.DataFilteringPanel);
            app.FrameSpinner_6.Limits = [1 Inf];
            app.FrameSpinner_6.Value = 1;
            app.Switch_3 = uiswitch(app.DataFilteringPanel, 'toggle');
            app.Switch_3.Items = {'Main', 'Reference'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Enable = 'off';
            app.Switch_3.Value = 'Main';
            app.PhaseExtractionTab = uitab(app.TabGroup);
            app.PhaseExtractionTab.Title = 'Phase Extraction';
            app.TabGroup2 = uitabgroup(app.PhaseExtractionTab);
            app.OffaxisDigitalholographicInterferometryDHITab = uitab(app.TabGroup2);
            app.OffaxisDigitalholographicInterferometryDHITab.Title = 'Off-axis Digital holographic Interferometry (DHI)';
            app.ReconstructionMethodButtonGroup = uibuttongroup(app.OffaxisDigitalholographicInterferometryDHITab);
            app.ReconstructionMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReconstructionMethodButtonGroupSelectionChanged, true);
            app.ReconstructionMethodButtonGroup.Title = 'Reconstruction Method:';
            app.DiscreteFresnelTransformationButton_2 = uitogglebutton(app.ReconstructionMethodButtonGroup);
            app.DiscreteFresnelTransformationButton_2.Text = 'Discrete Fresnel Transformation';
            app.DiscreteFresnelTransformationButton_2.Value = true;
            app.AngularSpectrumButton_2 = uitogglebutton(app.ReconstructionMethodButtonGroup);
            app.AngularSpectrumButton_2.Text = 'Angular Spectrum';
            app.ConvolutionButton_2 = uitogglebutton(app.ReconstructionMethodButtonGroup);
            app.ConvolutionButton_2.Text = 'Convolution';
            app.CustomizedMATLABFunctionButton_2 = uitogglebutton(app.ReconstructionMethodButtonGroup);
            app.CustomizedMATLABFunctionButton_2.Text = 'Customized MATLAB Function';
            app.Panel_DHI = uipanel(app.OffaxisDigitalholographicInterferometryDHITab);
            app.ObjectPlaneImagingPlaneDistancecmLabel_5 = uilabel(app.Panel_DHI);
            app.ObjectPlaneImagingPlaneDistancecmLabel_5.Text = 'Object Plane-Imaging Plane Distance (cm):';
            app.CameraPixelSizeumSpinnerLabel = uilabel(app.Panel_DHI);
            app.CameraPixelSizeumSpinnerLabel.Text = 'Camera Pixel Size (um):';
            app.CameraPixelSizeDHI = uispinner(app.Panel_DHI);
            app.CameraPixelSizeDHI.Step = 0.1;
            app.CameraPixelSizeDHI.Limits = [0 Inf];
            app.CameraPixelSizeDHI.Value = 3.5;
            app.LaserWavelengthnmSpinnerLabel = uilabel(app.Panel_DHI);
            app.LaserWavelengthnmSpinnerLabel.Text = 'Laser Wavelength (nm):';
            app.LaserWavelengthnmDHI = uispinner(app.Panel_DHI);
            app.LaserWavelengthnmDHI.Value = 633;
            app.ReconstructionZeroPaddingSizepixelLabel = uilabel(app.Panel_DHI);
            app.ReconstructionZeroPaddingSizepixelLabel.Text = 'Reconstruction Zero Padding Size (pixel):';
            app.ZeroPaddedImageSizepixelDHI = uispinner(app.Panel_DHI);
            app.ZeroPaddedImageSizepixelDHI.Value = 4096;
            app.fromSpinner_2Label_2 = uilabel(app.Panel_DHI);
            app.fromSpinner_2Label_2.Text = 'from';
            app.fromDHI = uispinner(app.Panel_DHI);
            app.fromDHI.Value = 50;
            app.intervalSpinner_2Label_2 = uilabel(app.Panel_DHI);
            app.intervalSpinner_2Label_2.Text = 'interval';
            app.intervalDHI = uispinner(app.Panel_DHI);
            app.toSpinner_2Label_2 = uilabel(app.Panel_DHI);
            app.toSpinner_2Label_2.Text = 'to';
            app.toDHI = uispinner(app.Panel_DHI);
            app.MagnificationLabel = uilabel(app.Panel_DHI);
            app.MagnificationLabel.Text = 'Magnification:';
            app.MagnificationDHI = uispinner(app.Panel_DHI);
            app.MagnificationDHI.Enable = 'off';
            app.MagnificationDHI.Value = 1;
            app.RUNButton = uibutton(app.OffaxisDigitalholographicInterferometryDHITab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.PhaseWrappingAlgorithmButtonGroup = uibuttongroup(app.OffaxisDigitalholographicInterferometryDHITab);
            app.PhaseWrappingAlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhaseWrappingAlgorithmButtonGroupSelectionChanged, true);
            app.PhaseWrappingAlgorithmButtonGroup.Title = 'Phase Wrapping Algorithm:';
            app.FourierTransformFTButton_2 = uitogglebutton(app.PhaseWrappingAlgorithmButtonGroup);
            app.FourierTransformFTButton_2.Text = 'Fourier Transform (FT)';
            app.WaveletTransformWTButton_2 = uitogglebutton(app.PhaseWrappingAlgorithmButtonGroup);
            app.WaveletTransformWTButton_2.Text = 'Wavelet Transform (WT)';
            app.HilbertTransformHTButton_2 = uitogglebutton(app.PhaseWrappingAlgorithmButtonGroup);
            app.HilbertTransformHTButton_2.Text = 'Hilbert Transform (HT)';
            app.ComplexDataButton = uitogglebutton(app.PhaseWrappingAlgorithmButtonGroup);
            app.ComplexDataButton.Text = 'Complex Data';
            app.ComplexDataButton.Value = true;
            app.CustomizedMATLABFunctionButton_3 = uitogglebutton(app.PhaseWrappingAlgorithmButtonGroup);
            app.CustomizedMATLABFunctionButton_3.Text = 'Customized MATLAB Function';
            app.RemovecarrierfrequencybyFouriermethodCheckBox = uicheckbox(app.OffaxisDigitalholographicInterferometryDHITab);
            app.RemovecarrierfrequencybyFouriermethodCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovecarrierfrequencybyFouriermethodCheckBoxValueChanged, true);
            app.RemovecarrierfrequencybyFouriermethodCheckBox.Text = 'Remove carrier frequency by Fourier method';
            app.RemovecarrierfrequencybyFouriermethodCheckBox.Value = true;
            app.Panel_DHI_Wrapping = uipanel(app.OffaxisDigitalholographicInterferometryDHITab);
            app.WindowSizepixelLabel = uilabel(app.Panel_DHI_Wrapping);
            app.WindowSizepixelLabel.Text = 'Window Size (pixel):';
            app.FilterRadiusDHI = uispinner(app.Panel_DHI_Wrapping);
            app.FilterRadiusDHI.Tooltip = {'radius of a circular window'};
            app.FilterRadiusDHI.Value = 512;
            app.WrappingZeroPaddingSizepixelLabel_5 = uilabel(app.Panel_DHI_Wrapping);
            app.WrappingZeroPaddingSizepixelLabel_5.Text = 'Wrapping Zero Padding Size (pixel):';
            app.ZeroPaddedImageSizepixel_DHI = uispinner(app.Panel_DHI_Wrapping);
            app.ZeroPaddedImageSizepixel_DHI.Value = 4096;
            app.FrequencyFilterSizepixelLabel = uilabel(app.Panel_DHI_Wrapping);
            app.FrequencyFilterSizepixelLabel.Text = 'Frequency Filter Size (pixel):';
            app.SmoothingFilterSize_DHI = uispinner(app.Panel_DHI_Wrapping);
            app.SmoothingFilterSize_DHI.Limits = [1 Inf];
            app.SmoothingFilterSize_DHI.Value = 19;
            app.FrequencyFilterTypeDropDownLabel = uilabel(app.Panel_DHI_Wrapping);
            app.FrequencyFilterTypeDropDownLabel.Text = 'Frequency Filter Type:';
            app.FrequencyFilterTypeDropDownDHI = uidropdown(app.Panel_DHI_Wrapping);
            app.FrequencyFilterTypeDropDownDHI.Items = {'Gaussian', 'Butterworth', 'Ideal'};
            app.FrequencyFilterTypeDropDownDHI.Value = 'Gaussian';
            app.FringeProjectionProfilometryFPPTab = uitab(app.TabGroup2);
            app.FringeProjectionProfilometryFPPTab.Title = 'Fringe Projection Profilometry (FPP)';
            app.AlgorithmButtonGroup = uibuttongroup(app.FringeProjectionProfilometryFPPTab);
            app.AlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmButtonGroupSelectionChanged, true);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.FourierTransformFTButton = uitogglebutton(app.AlgorithmButtonGroup);
            app.FourierTransformFTButton.Text = 'Fourier Transform (FT)';
            app.FourierTransformFTButton.Value = true;
            app.WaveletTransformWTButton = uitogglebutton(app.AlgorithmButtonGroup);
            app.WaveletTransformWTButton.Tooltip = {'Require Wavelet Toolbox'};
            app.WaveletTransformWTButton.Text = 'Wavelet Transform (WT)';
            app.HilbertTransformHTButton = uitogglebutton(app.AlgorithmButtonGroup);
            app.HilbertTransformHTButton.Text = 'Hilbert Transform (HT)';
            app.CustomizedMATLABFunctionButton = uitogglebutton(app.AlgorithmButtonGroup);
            app.CustomizedMATLABFunctionButton.Text = 'Customized MATLAB Function';
            app.RUNButton_2 = uibutton(app.FringeProjectionProfilometryFPPTab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.Panel_FPP = uipanel(app.FringeProjectionProfilometryFPPTab);
            app.FrequencyFilterSizepixelLabel_2 = uilabel(app.Panel_FPP);
            app.FrequencyFilterSizepixelLabel_2.Text = 'Frequency Filter Size (pixel):';
            app.SmoothingFilterSizeFPP = uispinner(app.Panel_FPP);
            app.SmoothingFilterSizeFPP.Limits = [1 Inf];
            app.SmoothingFilterSizeFPP.Value = 19;
            app.WindowSizepixelLabel_2 = uilabel(app.Panel_FPP);
            app.WindowSizepixelLabel_2.Tooltip = {''};
            app.WindowSizepixelLabel_2.Text = 'Window Size (pixel):';
            app.FilterSizeProfilometryFPP = uispinner(app.Panel_FPP);
            app.FilterSizeProfilometryFPP.Step = 10;
            app.FilterSizeProfilometryFPP.Limits = [1 Inf];
            app.FilterSizeProfilometryFPP.Tooltip = {'radius of a circular window'};
            app.FilterSizeProfilometryFPP.Value = 100;
            app.FrequencyFilterTypeDropDown_3Label = uilabel(app.Panel_FPP);
            app.FrequencyFilterTypeDropDown_3Label.Text = 'Frequency Filter Type:';
            app.FrequencyFilterTypeDropDown_FPP = uidropdown(app.Panel_FPP);
            app.FrequencyFilterTypeDropDown_FPP.Items = {'Gaussian', 'Butterworth', 'Ideal'};
            app.FrequencyFilterTypeDropDown_FPP.Value = 'Gaussian';
            app.WrappingZeroPaddingSizepixelLabel_4 = uilabel(app.Panel_FPP);
            app.WrappingZeroPaddingSizepixelLabel_4.Text = 'Wrapping Zero Padding Size (pixel):';
            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP = uispinner(app.Panel_FPP);
            app.ZeroPaddedImageSizepixelProfilometry_wrapping_FPP.Value = 4096;
            app.RemovecarrierfrequencybyFouriermethodCheckBox_2 = uicheckbox(app.FringeProjectionProfilometryFPPTab);
            app.RemovecarrierfrequencybyFouriermethodCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @RemovecarrierfrequencybyFouriermethodCheckBox_2ValueChanged, true);
            app.RemovecarrierfrequencybyFouriermethodCheckBox_2.Text = 'Remove carrier frequency by Fourier method';
            app.RemovecarrierfrequencybyFouriermethodCheckBox_2.Value = true;
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.DenoisingPanel_3 = uipanel(app.PostProcessingTab);
            app.DenoisingPanel_3.Title = '1-Denoising';
            app.ProcessButton = uibutton(app.DenoisingPanel_3, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.FilteringPanel_2 = uipanel(app.DenoisingPanel_3);
            app.FilteringPanel_2.Title = 'Filtering';
            app.GaussianlowpassfilterCheckBox = uicheckbox(app.FilteringPanel_2);
            app.GaussianlowpassfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianlowpassfilterCheckBoxValueChanged, true);
            app.GaussianlowpassfilterCheckBox.Text = 'Gaussian lowpass filter';
            app.MedianfilterCheckBox_2 = uicheckbox(app.FilteringPanel_2);
            app.MedianfilterCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MedianfilterCheckBox_2ValueChanged, true);
            app.MedianfilterCheckBox_2.Text = 'Median filter';
            app.AdaptivelowpassWienerfilterCheckBox = uicheckbox(app.FilteringPanel_2);
            app.AdaptivelowpassWienerfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdaptivelowpassWienerfilterCheckBoxValueChanged, true);
            app.AdaptivelowpassWienerfilterCheckBox.Text = {'Adaptive lowpass Wiener '; 'filter'};
            app.DeepneuralnetworkCheckBox_4 = uicheckbox(app.FilteringPanel_2);
            app.DeepneuralnetworkCheckBox_4.Tooltip = {'Function: denoiseImage'; ''; 'This function uses:'; 'Image Processing Toolbox'; 'Deep Learning Toolbox'};
            app.DeepneuralnetworkCheckBox_4.Text = 'Deep neural network';
            app.sizemnSpinnerLabel = uilabel(app.FilteringPanel_2);
            app.sizemnSpinnerLabel.Enable = 'off';
            app.sizemnSpinnerLabel.Text = 'size [m n]:';
            app.sizemnSpinner = uispinner(app.FilteringPanel_2);
            app.sizemnSpinner.Enable = 'off';
            app.sizemnSpinner.Value = 3;
            app.filtersizemnSpinner_8 = uispinner(app.FilteringPanel_2);
            app.filtersizemnSpinner_8.Enable = 'off';
            app.filtersizemnSpinner_8.Value = 3;
            app.filtersizemnSpinner_9 = uispinner(app.FilteringPanel_2);
            app.filtersizemnSpinner_9.Enable = 'off';
            app.filtersizemnSpinner_9.Value = 3;
            app.sizemnSpinner_2Label = uilabel(app.FilteringPanel_2);
            app.sizemnSpinner_2Label.Enable = 'off';
            app.sizemnSpinner_2Label.Text = 'size [m n]:';
            app.sizemnSpinner_2 = uispinner(app.FilteringPanel_2);
            app.sizemnSpinner_2.Enable = 'off';
            app.sizemnSpinner_2.Value = 3;
            app.filtersizemnSpinner_11 = uispinner(app.FilteringPanel_2);
            app.filtersizemnSpinner_11.Enable = 'off';
            app.filtersizemnSpinner_11.Value = 3;
            app.sizemnLabel = uilabel(app.FilteringPanel_2);
            app.sizemnLabel.Enable = 'off';
            app.sizemnLabel.Text = 'size [m n]:';
            app.sizemnSpinner_3 = uispinner(app.FilteringPanel_2);
            app.sizemnSpinner_3.Enable = 'off';
            app.sizemnSpinner_3.Value = 3;
            app.sigmaSpinnerLabel = uilabel(app.FilteringPanel_2);
            app.sigmaSpinnerLabel.Enable = 'off';
            app.sigmaSpinnerLabel.Text = 'sigma:';
            app.sigmaSpinner = uispinner(app.FilteringPanel_2);
            app.sigmaSpinner.Step = 0.1;
            app.sigmaSpinner.Limits = [0 1];
            app.sigmaSpinner.Enable = 'off';
            app.sigmaSpinner.Value = 0.5;
            app.CustomizedMATLABfunctionCheckBox_2 = uicheckbox(app.FilteringPanel_2);
            app.CustomizedMATLABfunctionCheckBox_2.Tooltip = {'Function: denoiseImage'; ''; 'This function uses:'; 'Image Processing Toolbox'; 'Deep Learning Toolbox'};
            app.CustomizedMATLABfunctionCheckBox_2.Text = {'Customized MATLAB '; 'function'};
            app.FrameSpinner_8Label = uilabel(app.DenoisingPanel_3);
            app.FrameSpinner_8Label.Text = 'Frame';
            app.FrameSpinner_8 = uispinner(app.DenoisingPanel_3);
            app.FrameSpinner_8.Limits = [1 Inf];
            app.FrameSpinner_8.Value = 1;
            app.UIAxes2_9 = uiaxes(app.DenoisingPanel_3);
            title(app.UIAxes2_9, '')
            app.UIAxes2_9.XTick = [];
            app.UIAxes2_9.YTick = [];
            app.Switch_5 = uiswitch(app.DenoisingPanel_3, 'toggle');
            app.Switch_5.Items = {'Main', 'Reference'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Enable = 'off';
            app.Switch_5.Value = 'Main';
            app.PhaseUnwrappingPanel = uipanel(app.PostProcessingTab);
            app.PhaseUnwrappingPanel.Title = '3-Phase Unwrapping ';
            app.ProcessButton_2 = uibutton(app.PhaseUnwrappingPanel, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.UnwrappingAlgorithmButtonGroup = uibuttongroup(app.PhaseUnwrappingPanel);
            app.UnwrappingAlgorithmButtonGroup.Title = 'Unwrapping Algorithm';
            app.ConstantinButton = uitogglebutton(app.UnwrappingAlgorithmButtonGroup);
            app.ConstantinButton.Tooltip = {'References:'; '%   - http://earth.esa.int/workshops/ers97/papers/costantini/'; '%   - Costantini'; ' M. (1998) A novel phase unwrapping method based on network'; '%   programming. IEEE Tran. on Geoscience and Remote Sensing'; ' 36'; ' 813-821.'; '% Author: Bruno Luong <brunoluong@yahoo.com>'};
            app.ConstantinButton.Text = 'Constantin';
            app.ConstantinButton.Value = true;
            app.GhigliaandPrittButton = uitogglebutton(app.UnwrappingAlgorithmButtonGroup);
            app.GhigliaandPrittButton.Tooltip = {'% D. C. Ghiglia and M. D. Pritt'; ' Two-Dimensional Phase Unwrapping:'; '% Theory'; ' Algorithms and Software. New York: Wiley-Interscience'; ' 1998.'};
            app.GhigliaandPrittButton.Text = 'Ghiglia and Pritt';
            app.CustomizedMATLABFunctionButton_5 = uitogglebutton(app.UnwrappingAlgorithmButtonGroup);
            app.CustomizedMATLABFunctionButton_5.Text = 'Customized MATLAB Function';
            app.UseMasksCheckBox = uicheckbox(app.UnwrappingAlgorithmButtonGroup);
            app.UseMasksCheckBox.Tooltip = {'Needs step 1 be processed beforehand '};
            app.UseMasksCheckBox.Text = 'Use Mask/s';
            app.GhigliaandRomeroButton = uitogglebutton(app.UnwrappingAlgorithmButtonGroup);
            app.GhigliaandRomeroButton.Tooltip = {'Unwrapping phase based on Ghiglia and Romero (1994) based on weighted and unweighted least-square method'; '% URL: https://doi.org/10.1364/JOSAA.11.000107. Author: Muhammad F. Kasim (University of Oxford'; ' 2016)'; ''};
            app.GhigliaandRomeroButton.Text = 'Ghiglia and Romero';
            app.GoldsteinButton = uitogglebutton(app.UnwrappingAlgorithmButtonGroup);
            app.GoldsteinButton.Tooltip = {'GoldsteinUnwrap2D implements 2D Goldstein branch cut phase unwrapping algorithm'};
            app.GoldsteinButton.Text = 'Goldstein';
            app.VolkovButton = uitogglebutton(app.UnwrappingAlgorithmButtonGroup);
            app.VolkovButton.Tooltip = {'Unwrapping using the 2D_SRNCP algorithm:'; 'http://www.ljmu.ac.uk/GERI/90225.htm.'; ''};
            app.VolkovButton.Text = 'Volkov';
            app.FrameSpinner_9Label = uilabel(app.PhaseUnwrappingPanel);
            app.FrameSpinner_9Label.Text = 'Frame';
            app.FrameSpinner_9 = uispinner(app.PhaseUnwrappingPanel);
            app.FrameSpinner_9.Limits = [1 Inf];
            app.FrameSpinner_9.Value = 1;
            app.UIAxes2_11 = uiaxes(app.PhaseUnwrappingPanel);
            title(app.UIAxes2_11, '')
            app.UIAxes2_11.XTick = [];
            app.UIAxes2_11.YTick = [];
            app.Switch_6 = uiswitch(app.PhaseUnwrappingPanel, 'toggle');
            app.Switch_6.Items = {'Main', 'Reference'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Enable = 'off';
            app.Switch_6.Value = 'Main';
            app.PhaseMapLabel = uilabel(app.PostProcessingTab);
            app.PhaseMapLabel.Text = 'Phase Map:';
            app.DataOptionsDropDown = uidropdown(app.PostProcessingTab);
            app.DataOptionsDropDown.Items = {'Wrapped Phase', 'Carrier Removed Wrapped Phase'};
            app.DataOptionsDropDown.Value = 'Carrier Removed Wrapped Phase';
            app.MaskingPanel = uipanel(app.PostProcessingTab);
            app.MaskingPanel.Title = '2-Masking';
            app.ProcessButton_3 = uibutton(app.MaskingPanel, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_3Pushed, true);
            app.ProcessButton_3.Text = 'Process';
            app.UIAxes2_10 = uiaxes(app.MaskingPanel);
            title(app.UIAxes2_10, '')
            app.UIAxes2_10.XTick = [];
            app.UIAxes2_10.YTick = [];
            app.FrameSpinner_7Label = uilabel(app.MaskingPanel);
            app.FrameSpinner_7Label.Text = 'Frame';
            app.FrameSpinner_7 = uispinner(app.MaskingPanel);
            app.FrameSpinner_7.Limits = [1 Inf];
            app.FrameSpinner_7.Value = 1;
            app.Switch_4 = uiswitch(app.MaskingPanel, 'toggle');
            app.Switch_4.Items = {'Main', 'Reference'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Enable = 'off';
            app.Switch_4.Value = 'Main';
            app.MethodButtonGroup_2 = uibuttongroup(app.MaskingPanel);
            app.MethodButtonGroup_2.Title = 'Method';
            app.ManualButton = uitogglebutton(app.MethodButtonGroup_2);
            app.ManualButton.Tooltip = {'M. F. Kasim'; ' "Fast 2D phase unwrapping implementation in MATLAB"'; '          %'; '  https://github.com/mfkasim91/unwrap_phase/ (2017).'};
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.CustomizedMATLABFunctionButton_4 = uitogglebutton(app.MethodButtonGroup_2);
            app.CustomizedMATLABFunctionButton_4.Text = 'Customized MATLAB Function';
            app.Switch_7 = uiswitch(app.MethodButtonGroup_2, 'toggle');
            app.Switch_7.Items = {'a mask per frame', 'one mask for all frames'};
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            app.Switch_7.Value = 'a mask per frame';
            app.BackgroundRemovalPanel = uipanel(app.PostProcessingTab);
            app.BackgroundRemovalPanel.Title = '4-Background Removal';
            app.ProcessButton_4 = uibutton(app.BackgroundRemovalPanel, 'push');
            app.ProcessButton_4.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_4Pushed, true);
            app.ProcessButton_4.Text = 'Process';
            app.MethodButtonGroup = uibuttongroup(app.BackgroundRemovalPanel);
            app.MethodButtonGroup.Title = 'Method';
            app.ManualBackgroungEstimationButton = uitogglebutton(app.MethodButtonGroup);
            app.ManualBackgroungEstimationButton.Tooltip = {'Select multiple samples on the backgroung and then press enter'; ''};
            app.ManualBackgroungEstimationButton.Text = 'Manual Backgroung Estimation';
            app.AutomaticBackgroundEstimationButton = uitogglebutton(app.MethodButtonGroup);
            app.AutomaticBackgroundEstimationButton.Tooltip = {'Automatically fit a polynomial'; ''};
            app.AutomaticBackgroundEstimationButton.Text = 'Automatic Background Estimation';
            app.AutomaticBackgroundEstimationButton.Value = true;
            app.UsingReferenceButton = uitogglebutton(app.MethodButtonGroup);
            app.UsingReferenceButton.Tooltip = {'Only works when the reference in "Import Data" is been activated'};
            app.UsingReferenceButton.Text = 'Using Reference';
            app.CustomizedMATLABFunctionButton_6 = uitogglebutton(app.MethodButtonGroup);
            app.CustomizedMATLABFunctionButton_6.Text = 'Customized MATLAB Function';
            app.FrameSpinner_10Label = uilabel(app.BackgroundRemovalPanel);
            app.FrameSpinner_10Label.Text = 'Frame';
            app.FrameSpinner_10 = uispinner(app.BackgroundRemovalPanel);
            app.FrameSpinner_10.Limits = [1 Inf];
            app.FrameSpinner_10.Value = 1;
            app.UIAxes2_12 = uiaxes(app.BackgroundRemovalPanel);
            title(app.UIAxes2_12, '')
            app.UIAxes2_12.XTick = [];
            app.UIAxes2_12.YTick = [];
            app.ImageEnhancementPanel = uipanel(app.PostProcessingTab);
            app.ImageEnhancementPanel.Title = '5-Image Enhancement';
            app.ProcessButton_5 = uibutton(app.ImageEnhancementPanel, 'push');
            app.ProcessButton_5.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_5Pushed, true);
            app.ProcessButton_5.Text = 'Process';
            app.FilteringPanel = uipanel(app.ImageEnhancementPanel);
            app.FilteringPanel.Title = 'Filtering';
            app.AveragefilterCheckBox = uicheckbox(app.FilteringPanel);
            app.AveragefilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @AveragefilterCheckBoxValueChanged, true);
            app.AveragefilterCheckBox.Text = 'Average filter';
            app.MedianfilterCheckBox = uicheckbox(app.FilteringPanel);
            app.MedianfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @MedianfilterCheckBoxValueChanged, true);
            app.MedianfilterCheckBox.Text = 'Median filter';
            app.AdaptiveLowpassWienerFilterCheckBox_4 = uicheckbox(app.FilteringPanel);
            app.AdaptiveLowpassWienerFilterCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @AdaptiveLowpassWienerFilterCheckBox_4ValueChanged, true);
            app.AdaptiveLowpassWienerFilterCheckBox_4.Text = {'Adaptive Low-pass Wiener'; ' Filter'};
            app.DeepneuralnetworkCheckBox_3 = uicheckbox(app.FilteringPanel);
            app.DeepneuralnetworkCheckBox_3.Tooltip = {'Function: denoiseImage'; ''; 'This function uses:'; 'Image Processing Toolbox'; 'Deep Learning Toolbox'};
            app.DeepneuralnetworkCheckBox_3.Text = 'Deep neural network';
            app.sizemnSpinner_4Label = uilabel(app.FilteringPanel);
            app.sizemnSpinner_4Label.Enable = 'off';
            app.sizemnSpinner_4Label.Text = 'size [m n]:';
            app.sizemnSpinner_4 = uispinner(app.FilteringPanel);
            app.sizemnSpinner_4.Enable = 'off';
            app.sizemnSpinner_4.Value = 3;
            app.filtersizemnSpinner_12 = uispinner(app.FilteringPanel);
            app.filtersizemnSpinner_12.Enable = 'off';
            app.filtersizemnSpinner_12.Value = 3;
            app.filtersizemnSpinner_13 = uispinner(app.FilteringPanel);
            app.filtersizemnSpinner_13.Enable = 'off';
            app.filtersizemnSpinner_13.Value = 3;
            app.sizemnSpinner_5Label = uilabel(app.FilteringPanel);
            app.sizemnSpinner_5Label.Enable = 'off';
            app.sizemnSpinner_5Label.Text = 'size [m n]:';
            app.sizemnSpinner_5 = uispinner(app.FilteringPanel);
            app.sizemnSpinner_5.Enable = 'off';
            app.sizemnSpinner_5.Value = 3;
            app.filtersizemnSpinner_14 = uispinner(app.FilteringPanel);
            app.filtersizemnSpinner_14.Enable = 'off';
            app.filtersizemnSpinner_14.Value = 3;
            app.sizemnSpinner_6Label = uilabel(app.FilteringPanel);
            app.sizemnSpinner_6Label.Enable = 'off';
            app.sizemnSpinner_6Label.Text = 'size [m n]:';
            app.sizemnSpinner_6 = uispinner(app.FilteringPanel);
            app.sizemnSpinner_6.Enable = 'off';
            app.sizemnSpinner_6.Value = 3;
            app.CustomizedMATLABfunctionCheckBox_3 = uicheckbox(app.FilteringPanel);
            app.CustomizedMATLABfunctionCheckBox_3.Tooltip = {'Function: denoiseImage'; ''; 'This function uses:'; 'Image Processing Toolbox'; 'Deep Learning Toolbox'};
            app.CustomizedMATLABfunctionCheckBox_3.Text = {'Customized MATLAB '; 'function'};
            app.FrameSpinner_11Label = uilabel(app.ImageEnhancementPanel);
            app.FrameSpinner_11Label.Text = 'Frame';
            app.FrameSpinner_11 = uispinner(app.ImageEnhancementPanel);
            app.FrameSpinner_11.Limits = [1 Inf];
            app.FrameSpinner_11.Value = 1;
            app.UIAxes2_13 = uiaxes(app.ImageEnhancementPanel);
            title(app.UIAxes2_13, '')
            app.UIAxes2_13.XTick = [];
            app.UIAxes2_13.YTick = [];
            app.DisplaySaveTab = uitab(app.TabGroup);
            app.DisplaySaveTab.Title = 'Display & Save';
            app.LineProfile = uibutton(app.DisplaySaveTab, 'push');
            app.LineProfile.ButtonPushedFcn = createCallbackFcn(app, @LineProfileButtonPushed, true);
            app.LineProfile.Icon = 'line profile.jpg.png';
            app.LineProfile.Tooltip = {'Line profile in x/y directions'};
            app.LineProfile.Text = {''; ''};
            app.Surfplot = uibutton(app.DisplaySaveTab, 'push');
            app.Surfplot.ButtonPushedFcn = createCallbackFcn(app, @SurfplotButtonPushed, true);
            app.Surfplot.Icon = 'surf (2).png';
            app.Surfplot.Tooltip = {'3D surface display'};
            app.Surfplot.Text = {''; ''};
            app.ImageDropDown_2Label = uilabel(app.DisplaySaveTab);
            app.ImageDropDown_2Label.Text = 'Image:';
            app.ImageOptionsDropDown = uidropdown(app.DisplaySaveTab);
            app.ImageOptionsDropDown.Items = {'Select ...', 'Original Frame', 'Reconstructed (Amplitude)', 'Reconstructed (Phase)', 'Wrapped Phase', 'Carrier Removed Wrapped Phase', 'Unwrapped Phase', 'Background', 'Background Removed Unwrapped Phase', 'Background Removed Unwrapped Phase (Filtered)'};
            app.ImageOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageOptionsDropDownValueChanged, true);
            app.ImageOptionsDropDown.Value = 'Select ...';
            app.Histogram = uibutton(app.DisplaySaveTab, 'push');
            app.Histogram.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.Histogram.Icon = 'Histogram.png';
            app.Histogram.Tooltip = {'Histogram'};
            app.Histogram.Text = {''; ''};
            app.FrameNumberSpinner_2Label = uilabel(app.DisplaySaveTab);
            app.FrameNumberSpinner_2Label.Text = 'Frame Number:';
            app.FrameNumberselection = uispinner(app.DisplaySaveTab);
            app.FrameNumberselection.Limits = [1 Inf];
            app.FrameNumberselection.Editable = 'off';
            app.FrameNumberselection.Tooltip = {'valid for video inputs'};
            app.FrameNumberselection.Value = 1;
            app.Play = uibutton(app.DisplaySaveTab, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed2, true);
            app.Play.Icon = 'download.png';
            app.Play.Enable = 'off';
            app.Play.Tooltip = {'play and save in video'};
            app.Play.Text = {''; ''};
            app.lowerlimitEditFieldLabel = uilabel(app.DisplaySaveTab);
            app.lowerlimitEditFieldLabel.Enable = 'off';
            app.lowerlimitEditFieldLabel.Text = 'lower limit:';
            app.lowerlimitEditField = uieditfield(app.DisplaySaveTab, 'numeric');
            app.lowerlimitEditField.Limits = [-Inf -1];
            app.lowerlimitEditField.Enable = 'off';
            app.lowerlimitEditField.Tooltip = {'Down limit for intensity shown in video display/save function'};
            app.lowerlimitEditField.Value = -1000;
            app.upperlimitEditFieldLabel = uilabel(app.DisplaySaveTab);
            app.upperlimitEditFieldLabel.Enable = 'off';
            app.upperlimitEditFieldLabel.Text = 'upper limit:';
            app.upperlimitEditField = uieditfield(app.DisplaySaveTab, 'numeric');
            app.upperlimitEditField.Limits = [1 Inf];
            app.upperlimitEditField.Enable = 'off';
            app.upperlimitEditField.Tooltip = {'Uper-limit for intensity shown in video display/save function'};
            app.upperlimitEditField.Value = 1000;
            app.timeplot = uibutton(app.DisplaySaveTab, 'push');
            app.timeplot.ButtonPushedFcn = createCallbackFcn(app, @timeplotButtonPushed, true);
            app.timeplot.Icon = 'timeplot.png';
            app.timeplot.Enable = 'off';
            app.timeplot.Tooltip = {'Change over time for an area'};
            app.timeplot.Text = {''; ''};
            app.ysizeLabel = uilabel(app.DisplaySaveTab);
            app.ysizeLabel.Enable = 'off';
            app.ysizeLabel.Text = 'y size:';
            app.ysizeEditField = uieditfield(app.DisplaySaveTab, 'numeric');
            app.ysizeEditField.Enable = 'off';
            app.ysizeEditField.Value = 300;
            app.xsizeEditFieldLabel = uilabel(app.DisplaySaveTab);
            app.xsizeEditFieldLabel.Enable = 'off';
            app.xsizeEditFieldLabel.Text = 'x size:';
            app.xsizeEditField = uieditfield(app.DisplaySaveTab, 'numeric');
            app.xsizeEditField.Enable = 'off';
            app.xsizeEditField.Value = 300;
            app.ColormapSelection = uidropdown(app.DisplaySaveTab);
            app.ColormapSelection.Items = {'gray', 'jet', 'parula', 'autumn', 'bone', 'colorcube', 'cool', 'copper', 'flag', 'hot', 'hsv', 'lines', 'pink', 'prism', 'spring', 'summer', 'white', 'winter'};
            app.ColormapSelection.Tooltip = {'Valid for 3D surface dispaly'};
            app.ColormapSelection.Value = 'gray';
            app.ColormapDropDown_2Label = uilabel(app.DisplaySaveTab);
            app.ColormapDropDown_2Label.Text = 'Colormap:';
            app.UIAxes4_2 = uiaxes(app.DisplaySaveTab);
            title(app.UIAxes4_2, '')
            xlabel(app.UIAxes4_2, '')
            ylabel(app.UIAxes4_2, '')
            app.UIAxes4_2.XTick = [];
            app.UIAxes4_2.YTick = [];
            app.xSlider_2Label = uilabel(app.DisplaySaveTab);
            app.xSlider_2Label.Text = {'x'; ''};
            app.xSlider_2 = uislider(app.DisplaySaveTab);
            app.xSlider_2.Limits = [0 1];
            app.xSlider_2.MajorTicks = [];
            app.xSlider_2.MajorTickLabels = {''};
            app.xSlider_2.ValueChangedFcn = createCallbackFcn(app, @xSlider_2ValueChanged, true);
            app.xSlider_2.MinorTicks = [0 0.0125 0.025 0.0375 0.05 0.0625 0.075 0.0875 0.1 0.1125 0.125 0.1375 0.15 0.1625 0.175 0.1875 0.2 0.2125 0.225 0.2375 0.25 0.2625 0.275 0.2875 0.3 0.3125 0.325 0.3375 0.35 0.3625 0.375 0.3875 0.4 0.4125 0.425 0.4375 0.45 0.4625 0.475 0.4875 0.5 0.5125 0.525 0.5375 0.55 0.5625 0.575 0.5875 0.6 0.6125 0.625 0.6375 0.65 0.6625 0.675 0.6875 0.7 0.7125 0.725 0.7375 0.75 0.7625 0.775 0.7875 0.8 0.8125 0.825 0.8375 0.85 0.8625 0.875 0.8875 0.9 0.9125 0.925 0.9375 0.95 0.9625 0.975 0.9875 1];
            app.xSlider_2.Value = 0.5;
            app.ySlider_2 = uislider(app.DisplaySaveTab);
            app.ySlider_2.Limits = [0 1];
            app.ySlider_2.MajorTicks = [];
            app.ySlider_2.ValueChangedFcn = createCallbackFcn(app, @ySlider_2ValueChanged, true);
            app.ySlider_2.MinorTicks = [0 0.025 0.05 0.075 0.1 0.125 0.15 0.175 0.2 0.225 0.25 0.275 0.3 0.325 0.35 0.375 0.4 0.425 0.45 0.475 0.5 0.525 0.55 0.575 0.6 0.625 0.65 0.675 0.7 0.725 0.75 0.775 0.8 0.825 0.85 0.875 0.9 0.925 0.95 0.975 1];
            app.ySlider_2.Value = 0.5;
            app.VerticalUIAxes_2 = uiaxes(app.DisplaySaveTab);
            title(app.VerticalUIAxes_2, 'Vertical Line-Profile')
            xlabel(app.VerticalUIAxes_2, 'Pixels ')
            ylabel(app.VerticalUIAxes_2, 'Intensity (a.u.)')
            app.HorizontalUIAxes_2 = uiaxes(app.DisplaySaveTab);
            title(app.HorizontalUIAxes_2, 'Horizontal Line-Profile')
            xlabel(app.HorizontalUIAxes_2, 'Pixels ')
            ylabel(app.HorizontalUIAxes_2, 'Intensity (a.u.)')
            app.HistogramUi_2 = uiaxes(app.DisplaySaveTab);
            title(app.HistogramUi_2, 'Histogram')
            xlabel(app.HistogramUi_2, 'Intensity Value')
            ylabel(app.HistogramUi_2, 'Number of Pixels')
            app.ImageDisplay = uibutton(app.DisplaySaveTab, 'push');
            app.ImageDisplay.ButtonPushedFcn = createCallbackFcn(app, @ImageDisplayButtonPushed, true);
            app.ImageDisplay.Icon = 'icon2d2.png';
            app.ImageDisplay.Tooltip = {'2D display'};
            app.ImageDisplay.Text = {''; ''};
            app.ContrastSpinnerLabel = uilabel(app.DisplaySaveTab);
            app.ContrastSpinnerLabel.Text = 'Contrast:';
            app.ContrastSpinner = uispinner(app.DisplaySaveTab);
            app.ContrastSpinner.Step = 128;
            app.ContrastSpinner.Tooltip = {'Valid for 2D display'};
            app.ContrastSpinner.Value = 256;
            app.UpdateButton = uibutton(app.DisplaySaveTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.yLabel = uilabel(app.DisplaySaveTab);
            app.yLabel.Text = 'y';
            app.SaveButton_2 = uibutton(app.DisplaySaveTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.BrowseFolderDirectoryButton = uibutton(app.DisplaySaveTab, 'push');
            app.BrowseFolderDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFolderDirectoryButtonPushed, true);
            app.BrowseFolderDirectoryButton.Text = 'Browse Folder Directory';
            app.PropertiesTextArea_4 = uitextarea(app.DisplaySaveTab);
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.Panel_6 = uipanel(app.ReportTab);
            app.Image = uiimage(app.Panel_6);
            app.Image.ImageSource = 'ToC.png';
            app.SampleofthetableofcontentsforthegeneratedreportLabel = uilabel(app.Panel_6);
            app.SampleofthetableofcontentsforthegeneratedreportLabel.Text = 'Sample of the table of contents for the generated report';
            app.GenerateReportButton = uibutton(app.ReportTab, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.AcknowledgmentsLabel = uilabel(app.HelpTab);
            app.AcknowledgmentsLabel.Text = 'Acknowledgments:';
            app.AngularPhaseAmpReconstructionLabel = uilabel(app.HelpTab);
            app.AngularPhaseAmpReconstructionLabel.Text = 'AngularPhaseAmpReconstruction';
            app.FunctionLabel = uilabel(app.HelpTab);
            app.FunctionLabel.Text = 'Function';
            app.AuthorLabel = uilabel(app.HelpTab);
            app.AuthorLabel.Text = 'Author';
            app.GeorgesTNehmetallahLabel = uilabel(app.HelpTab);
            app.GeorgesTNehmetallahLabel.Text = 'Georges T. Nehmetallah';
            app.BackgroundRemovalLabel = uilabel(app.HelpTab);
            app.BackgroundRemovalLabel.Text = 'BackgroundRemoval';
            app.ParsaOmidiLabel = uilabel(app.HelpTab);
            app.ParsaOmidiLabel.Text = 'Parsa Omidi';
            app.branch_cutsLabel = uilabel(app.HelpTab);
            app.branch_cutsLabel.Text = 'branch_cuts';
            app.BruceSpottiswoodeLabel_2 = uilabel(app.HelpTab);
            app.BruceSpottiswoodeLabel_2.Text = 'Bruce Spottiswoode';
            app.CarrierRemovalLabel = uilabel(app.HelpTab);
            app.CarrierRemovalLabel.Text = 'CarrierRemoval';
            app.ParsaOmidiLabel_3 = uilabel(app.HelpTab);
            app.ParsaOmidiLabel_3.Text = 'Parsa Omidi';
            app.ConvolutionPhaseAmpReconstructionLabel = uilabel(app.HelpTab);
            app.ConvolutionPhaseAmpReconstructionLabel.Text = 'ConvolutionPhaseAmpReconstruction';
            app.GeorgesTNehmetallahmodifiedbyParsaOmidiLabel = uilabel(app.HelpTab);
            app.GeorgesTNehmetallahmodifiedbyParsaOmidiLabel.Text = 'Georges T. Nehmetallah, (modified by Parsa Omidi)';
            app.CROIEditorLabel = uilabel(app.HelpTab);
            app.CROIEditorLabel.Text = 'CROIEditor ';
            app.JonasReberLabel = uilabel(app.HelpTab);
            app.JonasReberLabel.Text = 'Jonas Reber';
            app.FFTWrappingLabel = uilabel(app.HelpTab);
            app.FFTWrappingLabel.Text = 'FFTWrapping';
            app.ParsaOmidiLabel_4 = uilabel(app.HelpTab);
            app.ParsaOmidiLabel_4.Text = 'Parsa Omidi';
            app.FloodFillLabel = uilabel(app.HelpTab);
            app.FloodFillLabel.Text = 'FloodFill';
            app.BruceSpottiswoodeLabel_3 = uilabel(app.HelpTab);
            app.BruceSpottiswoodeLabel_3.Text = 'Bruce Spottiswoode';
            app.FramePreProcessingLabel = uilabel(app.HelpTab);
            app.FramePreProcessingLabel.Text = 'FramePreProcessing';
            app.ParsaOmidiLabel_5 = uilabel(app.HelpTab);
            app.ParsaOmidiLabel_5.Text = 'Parsa Omidi';
            app.FreqFilterDFTLabel = uilabel(app.HelpTab);
            app.FreqFilterDFTLabel.Text = 'FreqFilterDFT';
            app.unknownLabel = uilabel(app.HelpTab);
            app.unknownLabel.Text = 'unknown';
            app.FresnelPhaseAmpReconstructionLabel = uilabel(app.HelpTab);
            app.FresnelPhaseAmpReconstructionLabel.Text = 'FresnelPhaseAmpReconstruction';
            app.GeorgesTNehmetallahmodifiedbyParsaOmidiLabel_2 = uilabel(app.HelpTab);
            app.GeorgesTNehmetallahmodifiedbyParsaOmidiLabel_2.Text = 'Georges T. Nehmetallah, (modified by Parsa Omidi)';
            app.FT2DcIFT2DcLabel = uilabel(app.HelpTab);
            app.FT2DcIFT2DcLabel.Text = 'FT2Dc,IFT2Dc';
            app.TatianaLatychevskaiaLabel_2 = uilabel(app.HelpTab);
            app.TatianaLatychevskaiaLabel_2.Text = 'Tatiana Latychevskaia';
            app.GoldsteinUnwrap2DLabel = uilabel(app.HelpTab);
            app.GoldsteinUnwrap2DLabel.Text = 'GoldsteinUnwrap2D';
            app.BruceSpottiswoodeLabel = uilabel(app.HelpTab);
            app.BruceSpottiswoodeLabel.Text = 'Bruce Spottiswoode';
            app.HTWrappingLabel = uilabel(app.HelpTab);
            app.HTWrappingLabel.Text = 'HTWrapping';
            app.ParsaOmidiLabel_6 = uilabel(app.HelpTab);
            app.ParsaOmidiLabel_6.Text = 'Parsa Omidi';
            app.polyfitnpolyvalnLabel = uilabel(app.HelpTab);
            app.polyfitnpolyvalnLabel.Text = 'polyfitn,polyvaln';
            app.JohnDErricoLabel = uilabel(app.HelpTab);
            app.JohnDErricoLabel.Text = 'John D''Errico';
            app.UnwrappingConstantinLabel = uilabel(app.HelpTab);
            app.UnwrappingConstantinLabel.Text = 'UnwrappingConstantin';
            app.BrunoLuongLabel = uilabel(app.HelpTab);
            app.BrunoLuongLabel.Text = 'Bruno Luong';
            app.UnwrappingGhigliaPrittLabel = uilabel(app.HelpTab);
            app.UnwrappingGhigliaPrittLabel.Text = 'UnwrappingGhigliaPritt';
            app.unknownLabel_3 = uilabel(app.HelpTab);
            app.unknownLabel_3.Text = 'unknown';
            app.UnwrappingGhigliaRomeroLabel = uilabel(app.HelpTab);
            app.UnwrappingGhigliaRomeroLabel.Text = 'UnwrappingGhigliaRomero';
            app.MuhammadFKasimLabel = uilabel(app.HelpTab);
            app.MuhammadFKasimLabel.Text = 'Muhammad F. Kasim';
            app.UnwrappingGoldsteinLabel = uilabel(app.HelpTab);
            app.UnwrappingGoldsteinLabel.Text = 'UnwrappingGoldstein';
            app.unknownLabel_4 = uilabel(app.HelpTab);
            app.unknownLabel_4.Text = 'unknown';
            app.UnwrappingVolkovLabel = uilabel(app.HelpTab);
            app.UnwrappingVolkovLabel.Text = 'UnwrappingVolkov';
            app.unknownLabel_5 = uilabel(app.HelpTab);
            app.unknownLabel_5.Text = 'unknown';
            app.VirtualReal_ImageRec_selectionLabel = uilabel(app.HelpTab);
            app.VirtualReal_ImageRec_selectionLabel.Text = 'VirtualReal_ImageRec_selection';
            app.ParsaOmidiLabel_9 = uilabel(app.HelpTab);
            app.ParsaOmidiLabel_9.Text = 'Parsa Omidi';
            app.WindowedNormalizationLabel = uilabel(app.HelpTab);
            app.WindowedNormalizationLabel.Text = 'WindowedNormalization';
            app.ParsaOmidiLabel_12 = uilabel(app.HelpTab);
            app.ParsaOmidiLabel_12.Text = 'Parsa Omidi';
            app.WTWrappingLabel = uilabel(app.HelpTab);
            app.WTWrappingLabel.Text = 'WTWrapping';
            app.ParsaOmidiLabel_10 = uilabel(app.HelpTab);
            app.ParsaOmidiLabel_10.Text = 'Parsa Omidi';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Value = {'Latest versions can be found on Github:'; '                 https://github.com/Lawson-Optics-Lab/PhaseWare '; 'For instructions, consult the instruction manual: '; '                 https://github.com/Lawson-Optics-Lab/PhaseWare/blob/master/docs/PhaseWare_Instruction_Manual.pdf'};
            % Show the figure after all components are created
            app.PhaseWare.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseWare_V01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhaseWare)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhaseWare)
        end
    end
end